classdef app1firstversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NRCSSimulationTab               matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        ExportButton                    matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        OverlayButton                   matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        ResultsPanel                    matlab.ui.container.Panel
        Label_9                         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        GridLayout4                     matlab.ui.container.GridLayout
        ValidRangesNoteLabel            matlab.ui.control.Label
        FrequencyGHzEditField           matlab.ui.control.NumericEditField
        FrequencyGHzLabel               matlab.ui.control.Label
        k0kcEditField                   matlab.ui.control.NumericEditField
        k0kcLabel                       matlab.ui.control.Label
        FetchmEditField                 matlab.ui.control.NumericEditField
        FetchmLabel_4                   matlab.ui.control.Label
        Anglerelativetowinddirection0360degEditField  matlab.ui.control.NumericEditField
        Anglerelativetowinddirection0360degLabel  matlab.ui.control.Label
        Windspeedu10msEditField_5       matlab.ui.control.NumericEditField
        Windspeedu10msLabel_4           matlab.ui.control.Label
        PolarizationDropDown            matlab.ui.control.DropDown
        PolarizationLabel_2             matlab.ui.control.Label
        EMmodelDropDown                 matlab.ui.control.DropDown
        EMmodelDropDownLabel            matlab.ui.control.Label
        FrequencybandDropDown           matlab.ui.control.DropDown
        FrequencybandLabel              matlab.ui.control.Label
        IncidenceangledegEditField      matlab.ui.control.NumericEditField
        IncidenceangledegLabel_2        matlab.ui.control.Label
        InputparametersLabel            matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        NRCSSimulationLabel             matlab.ui.control.Label
        GenerationofSeaSurfaceTab       matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        GridLayout3_2                   matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        ExportSurfaceButton             matlab.ui.control.Button
        ClearSurfaceButton              matlab.ui.control.Button
        GenerateSurfaceButton           matlab.ui.control.Button
        GridLayout8                     matlab.ui.container.GridLayout
        Roundtothenearestpowerof2CheckBox  matlab.ui.control.CheckBox
        UsesingleprecisionCheckBox      matlab.ui.control.CheckBox
        AngularspectrumDropDown         matlab.ui.control.DropDown
        AngularspectrumLabel            matlab.ui.control.Label
        IsotropicspectrumDropDown       matlab.ui.control.DropDown
        IsotropicspectrumLabel          matlab.ui.control.Label
        RoundlengthLabel                matlab.ui.control.Label
        PrecisionLabel                  matlab.ui.control.Label
        NumberofsamplesEditField_2      matlab.ui.control.NumericEditField
        NumberofsamplesLabel            matlab.ui.control.Label
        SamplesperwavelengthEditField   matlab.ui.control.NumericEditField
        SamplesperwavelengthEditFieldLabel  matlab.ui.control.Label
        RadarfrequencyGHz130GHz2308cmEditField  matlab.ui.control.NumericEditField
        RadarfrequencyGHz130GHz2308cmLabel  matlab.ui.control.Label
        LengthYmEditField               matlab.ui.control.NumericEditField
        LengthYmLabel                   matlab.ui.control.Label
        LengthXmEditField               matlab.ui.control.NumericEditField
        LengthXmLabel                   matlab.ui.control.Label
        FetchmEditField_2               matlab.ui.control.NumericEditField
        FetchmLabel                     matlab.ui.control.Label
        WinddirectiondegEditField       matlab.ui.control.NumericEditField
        WinddirectiondegLabel           matlab.ui.control.Label
        Windspeedu10msEditField         matlab.ui.control.NumericEditField
        Windspeedu10msLabel             matlab.ui.control.Label
        SeasurfaceparametersLabel       matlab.ui.control.Label
        GridLayout5_2                   matlab.ui.container.GridLayout
        SurfacestatisticsPanel          matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        Label_8                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ZstdLabel                       matlab.ui.control.Label
        ZmaxLabel                       matlab.ui.control.Label
        ZmeanLabel                      matlab.ui.control.Label
        ZminLabel                       matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        GridLayout2_2                   matlab.ui.container.GridLayout
        Image2_2                        matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        GenerationofSeaSurfaceLabel     matlab.ui.control.Label
        GenerationofSeaProfilesTab      matlab.ui.container.Tab
        GridLayout_3                    matlab.ui.container.GridLayout
        GridLayout3_3                   matlab.ui.container.GridLayout
        GridLayout7_2                   matlab.ui.container.GridLayout
        GridLayout9_2                   matlab.ui.container.GridLayout
        ExportSurfaceButton_2           matlab.ui.control.Button
        ClearSurfaceButton_2            matlab.ui.control.Button
        GenerateProfilesButton          matlab.ui.control.Button
        GridLayout8_2                   matlab.ui.container.GridLayout
        YesCheckBox                     matlab.ui.control.CheckBox
        ParallelisationLabel            matlab.ui.control.Label
        Roundtothenearestpowerof2CheckBox_2  matlab.ui.control.CheckBox
        UsesingleprecisionCheckBox_2    matlab.ui.control.CheckBox
        IsotropicspectrumDropDown_2     matlab.ui.control.DropDown
        IsotropicspectrumLabel_2        matlab.ui.control.Label
        RoundlengthLabel_2              matlab.ui.control.Label
        PrecisionLabel_2                matlab.ui.control.Label
        NumberofsamplesEditField_3      matlab.ui.control.NumericEditField
        NumberofsamplesLabel_2          matlab.ui.control.Label
        SamplesperwavelengthEditField_2  matlab.ui.control.NumericEditField
        SamplesperwavelengthLabel       matlab.ui.control.Label
        RadarfrequencyGHz130GHz2308cmEditField_2  matlab.ui.control.NumericEditField
        RadarfrequencyGHz130GHz2308cmLabel_2  matlab.ui.control.Label
        NprofilesEditField              matlab.ui.control.NumericEditField
        NprofilesLabel                  matlab.ui.control.Label
        LengthmEditField                matlab.ui.control.NumericEditField
        LengthmLabel                    matlab.ui.control.Label
        FetchmEditField_3               matlab.ui.control.NumericEditField
        FetchmLabel_2                   matlab.ui.control.Label
        Windspeedu10msEditField_2       matlab.ui.control.NumericEditField
        Windspeedu10msLabel_2           matlab.ui.control.Label
        SeaProfilesparametersLabel      matlab.ui.control.Label
        GridLayout5_3                   matlab.ui.container.GridLayout
        SelectedProfileStatisticsPanel  matlab.ui.container.Panel
        GridLayout10_2                  matlab.ui.container.GridLayout
        Label_5                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ZstdLabel_2                     matlab.ui.control.Label
        ZmaxLabel_2                     matlab.ui.control.Label
        ZmeanLabel_2                    matlab.ui.control.Label
        ZminLabel_2                     matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        GridLayout2_3                   matlab.ui.container.GridLayout
        Image2_3                        matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        GenerationofSeaProfilesLabel    matlab.ui.control.Label
        BistaticNRCSTab                 matlab.ui.container.Tab
        GridLayout_4                    matlab.ui.container.GridLayout
        GridLayout3_4                   matlab.ui.container.GridLayout
        GridLayout7_3                   matlab.ui.container.GridLayout
        GridLayout9_3                   matlab.ui.container.GridLayout
        ExportButton_2                  matlab.ui.control.Button
        ClearButton_2                   matlab.ui.control.Button
        CalculateButton_2               matlab.ui.control.Button
        GridLayout8_3                   matlab.ui.container.GridLayout
        ParallelisationLabel_2          matlab.ui.control.Label
        RoundlengthLabel_3              matlab.ui.control.Label
        PrecisionLabel_3                matlab.ui.control.Label
        IsotropicspectrumDropDown_3     matlab.ui.control.DropDown
        IsotropicspectrumLabel_3        matlab.ui.control.Label
        UsesingleprecisionCheckBox_3    matlab.ui.control.CheckBox
        Roundtothenearestpowerof2CheckBox_3  matlab.ui.control.CheckBox
        NprofilesEditField_2            matlab.ui.control.NumericEditField
        NprofilesLabel_2                matlab.ui.control.Label
        YesCheckBox_2                   matlab.ui.control.CheckBox
        PolarizationDropDown_2          matlab.ui.control.DropDown
        PolarizationLabel               matlab.ui.control.Label
        SelectprofileDropDown           matlab.ui.control.DropDown
        SelectprofileLabel              matlab.ui.control.Label
        NumberofsamplesEditField        matlab.ui.control.NumericEditField
        NumberofsamplesLabel_3          matlab.ui.control.Label
        SamplesperwavelengthEditField_3  matlab.ui.control.NumericEditField
        SamplesperwavelengthLabel_2     matlab.ui.control.Label
        RadarfrequencyGHz130GHz2308cmEditField_3  matlab.ui.control.NumericEditField
        RadarfrequencyGHz130GHz2308cmLabel_3  matlab.ui.control.Label
        IncidenceangledegEditField_2    matlab.ui.control.NumericEditField
        IncidenceangledegLabel          matlab.ui.control.Label
        LengthmEditField_2              matlab.ui.control.NumericEditField
        LengthmLabel_2                  matlab.ui.control.Label
        FetchmEditField_4               matlab.ui.control.NumericEditField
        FetchmLabel_3                   matlab.ui.control.Label
        Windspeedu10msEditField_3       matlab.ui.control.NumericEditField
        Windspeedu10msLabel_3           matlab.ui.control.Label
        InputparametersLabel_2          matlab.ui.control.Label
        GridLayout5_4                   matlab.ui.container.GridLayout
        UIAxes_4                        matlab.ui.control.UIAxes
        GridLayout2_4                   matlab.ui.container.GridLayout
        Image2_4                        matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        BistaticNRCSofSpecificSeaProfilesMOMLabel  matlab.ui.control.Label
        MonostaticNRCSTab               matlab.ui.container.Tab
        GridLayout_5                    matlab.ui.container.GridLayout
        GridLayout3_5                   matlab.ui.container.GridLayout
        GridLayout7_4                   matlab.ui.container.GridLayout
        GridLayout9_4                   matlab.ui.container.GridLayout
        ExportButton_3                  matlab.ui.control.Button
        ClearButton_3                   matlab.ui.control.Button
        CalculateButton_3               matlab.ui.control.Button
        GridLayout8_4                   matlab.ui.container.GridLayout
        SelectprofileDropDown_2         matlab.ui.control.DropDown
        SelectprofileDropDown_2Label    matlab.ui.control.Label
        PolarizationDropDown_3          matlab.ui.control.DropDown
        PolarizationDropDown_3Label     matlab.ui.control.Label
        PrecisionLabel_4                matlab.ui.control.Label
        RoundlengthLabel_4              matlab.ui.control.Label
        ParallelisationLabel_3          matlab.ui.control.Label
        UsesingleprecisionCheckBox_4    matlab.ui.control.CheckBox
        Roundtothenearestpowerof2CheckBox_4  matlab.ui.control.CheckBox
        YesCheckBox_3                   matlab.ui.control.CheckBox
        NprofilesEditField_3            matlab.ui.control.NumericEditField
        NprofilesLabel_3                matlab.ui.control.Label
        NumberofsamplesEditField_4      matlab.ui.control.NumericEditField
        NumberofsamplesLabel_4          matlab.ui.control.Label
        IsotropicspectrumDropDown_4     matlab.ui.control.DropDown
        IsotropicspectrumLabel_4        matlab.ui.control.Label
        N_calcsNEditField               matlab.ui.control.NumericEditField
        NumberofcalculationsNLabel      matlab.ui.control.Label
        MaximumincidenceangledegEditField  matlab.ui.control.NumericEditField
        MaximumincidenceangledegLabel   matlab.ui.control.Label
        SamplesperwavelengthEditField_4  matlab.ui.control.NumericEditField
        SamplesperwavelengthLabel_3     matlab.ui.control.Label
        LengthmEditField_3              matlab.ui.control.NumericEditField
        LengthmLabel_3                  matlab.ui.control.Label
        RadarfrequencyGHz130GHz2308cmEditField_4  matlab.ui.control.NumericEditField
        RadarfrequencyGHz130GHz2308cmLabel_4  matlab.ui.control.Label
        FetchmEditField_5               matlab.ui.control.NumericEditField
        FetchmLabel_5                   matlab.ui.control.Label
        Windspeedu10msEditField_4       matlab.ui.control.NumericEditField
        Windspeedu10msLabel_5           matlab.ui.control.Label
        InputparametersLabel_3          matlab.ui.control.Label
        GridLayout5_5                   matlab.ui.container.GridLayout
        UIAxes_5                        matlab.ui.control.UIAxes
        GridLayout2_5                   matlab.ui.container.GridLayout
        Image2_5                        matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        MonostaticNRCSofSpecificSeaProfilesMOMLabel  matlab.ui.control.Label
    end

    
   properties (Access = private)

    SessionData = {}
    LastSurface = struct()
    ProfilesData cell = {}
    ProfileDropDown      matlab.ui.control.DropDown
    ProfileCountLabel    matlab.ui.control.Label
    RangeStartDropDown   matlab.ui.control.DropDown
    RangeEndDropDown     matlab.ui.control.DropDown
    UIAxes5              matlab.ui.control.UIAxes
    ProfileForNRCS       double = []
    ProfileXForNRCS      double = []
    ProfileSelectorDropDown  matlab.ui.control.DropDown
    IncidenceAngleTab4EditField  matlab.ui.control.NumericEditField
    PolarizationTab4DropDown     matlab.ui.control.DropDown
    NRCSAxes             matlab.ui.control.UIAxes
    isUIInitialized = false
    ValidationTimers = struct()  % Pour gérer la réinitialisation des couleurs
    MonoNRCSAxes    matlab.ui.control.UIAxes

    BistaticRangeStart   matlab.ui.control.DropDown
    BistaticRangeEnd     matlab.ui.control.DropDown
    MonoRangeStart       matlab.ui.control.DropDown
    MonoRangeEnd         matlab.ui.control.DropDown

   end


    methods (Access = private)

function initializeUIDropdowns(app)
    % Vérifier si déjà initialisé
    if app.isUIInitialized
        return;
    end
    
    % Configurer GridLayout5_3
    app.GridLayout5_3.RowHeight = {'1x', 40, 130};
    app.UIAxes_3.HitTest = 'on';
    app.UIAxes_3.PickableParts = 'all';
    app.SelectedProfileStatisticsPanel.Layout.Row = 3;
    
    % Créer la grille de la ligne 2
    dropGrid = uigridlayout(app.GridLayout5_3);
    dropGrid.ColumnWidth = {'1x', 80, 25, 80, 30, '1x', 160};
    dropGrid.RowHeight = {'1x'};
    dropGrid.Padding = [8 4 8 4];
    dropGrid.ColumnSpacing = 4;
    dropGrid.Layout.Row = 2;
    dropGrid.Layout.Column = 1;
    dropGrid.BackgroundColor = [0.94 0.94 0.94];
    
    % Col 1 : Label "Profile range:"
    rangeLabel = uilabel(dropGrid);
    rangeLabel.Text = 'Profile range:';
    rangeLabel.FontWeight = 'bold';
    rangeLabel.FontSize = 11;
    rangeLabel.HorizontalAlignment = 'right';
    rangeLabel.Layout.Row = 1;
    rangeLabel.Layout.Column = 1;
    
    % Col 2 : Dropdown borne début
    app.RangeStartDropDown = uidropdown(dropGrid);
    app.RangeStartDropDown.Items = {'—'};
    app.RangeStartDropDown.Value = '—';
    app.RangeStartDropDown.Layout.Row = 1;
    app.RangeStartDropDown.Layout.Column = 2;
    app.RangeStartDropDown.ValueChangedFcn = @(~,~) app.onRangeDropDownChanged();
    
    % Col 3 : Tiret séparateur
    dashLabel = uilabel(dropGrid);
    dashLabel.Text = '—';
    dashLabel.HorizontalAlignment = 'center';
    dashLabel.FontSize = 14;
    dashLabel.FontWeight = 'bold';
    dashLabel.Layout.Row = 1;
    dashLabel.Layout.Column = 3;
    
    % Col 4 : Dropdown borne fin
    app.RangeEndDropDown = uidropdown(dropGrid);
    app.RangeEndDropDown.Items = {'—'};
    app.RangeEndDropDown.Value = '—';
    app.RangeEndDropDown.Layout.Row = 1;
    app.RangeEndDropDown.Layout.Column = 4;
    app.RangeEndDropDown.ValueChangedFcn = @(~,~) app.onRangeDropDownChanged();
    
    % Col 5 : espace
    sp = uilabel(dropGrid);
    sp.Text = '';
    sp.Layout.Row = 1;
    sp.Layout.Column = 5;
    
    % Col 6 : Label "Selected Profile:"
    selLabel = uilabel(dropGrid);
    selLabel.Text = 'Selected Profile:';
    selLabel.FontWeight = 'bold';
    selLabel.FontSize = 11;
    selLabel.HorizontalAlignment = 'right';
    selLabel.Layout.Row = 1;
    selLabel.Layout.Column = 6;
    
    % Col 7 : Dropdown Selected Profile
    app.ProfileDropDown = uidropdown(dropGrid);
    app.ProfileDropDown.Items = {'— Generate first —'};
    app.ProfileDropDown.Value = '— Generate first —';
    app.ProfileDropDown.Layout.Row = 1;
    app.ProfileDropDown.Layout.Column = 7;
    app.ProfileDropDown.ValueChangedFcn = @(~,~) app.onProfileDropDownChanged();
    
    % Compteur (invisible)
    app.ProfileCountLabel = uilabel(dropGrid);
    app.ProfileCountLabel.Text = '';
    app.ProfileCountLabel.Visible = 'off';
    app.ProfileCountLabel.Layout.Row = 1;
    app.ProfileCountLabel.Layout.Column = 5;
    
    % Titre et layout du panel stats
    app.SelectedProfileStatisticsPanel.Title = 'Profile Statistics';
    app.GridLayout10_2.ColumnWidth = {70, '1x', 70, '1x'};
    app.GridLayout10_2.RowHeight = {'1x', '1x'};
    app.GridLayout10_2.Padding = [10 8 10 8];
    
    % Connecter les champs n_s
    app.LengthmEditField.ValueChangedFcn = @(~,~) app.updateNs();
    app.SamplesperwavelengthEditField_2.ValueChangedFcn = @(~,~) app.updateNs();
    app.RadarfrequencyGHz130GHz2308cmEditField_2.ValueChangedFcn = @(~,~) app.updateFreqLabelProfile();
    app.Roundtothenearestpowerof2CheckBox_2.ValueChangedFcn = @(~,~) app.updateNs();
    app.updateFreqLabelProfile();
    
    % Marquer comme initialisé
    app.isUIInitialized = true;
    
    disp('UI des profils initialisée avec succès');
end


function setupValidation(app)
    % ── Onglet 1 ──
    app.FrequencyGHzEditField.ValueChangedFcn = ...
        @(~,~) app.validateField(app.FrequencyGHzEditField, 'Frequency (GHz)');
    app.Windspeedu10msEditField_5.ValueChangedFcn = ...
        @(~,~) app.validateField(app.Windspeedu10msEditField_5, 'Wind speed u10');
    app.FetchmEditField.ValueChangedFcn = ...
        @(~,~) app.validateField(app.FetchmEditField, 'Fetch');
    app.k0kcEditField.ValueChangedFcn = ...
        @(~,~) app.validateField(app.k0kcEditField, 'K0/Kc');
    app.IncidenceangledegEditField.ValueChangedFcn = ...
        @(~,~) app.validateField(app.IncidenceangledegEditField, 'Incidence angle');

    % ── Onglet 2 ──
    app.Windspeedu10msEditField.ValueChangedFcn = ...
        @(~,~) app.validateField(app.Windspeedu10msEditField, 'Wind speed u10');
    app.FetchmEditField_2.ValueChangedFcn = ...
        @(~,~) app.validateField(app.FetchmEditField_2, 'Fetch');
    app.LengthXmEditField.ValueChangedFcn = ...
        @(~,~) app.validateField(app.LengthXmEditField, 'Length X');
    app.LengthYmEditField.ValueChangedFcn = ...
        @(~,~) app.validateField(app.LengthYmEditField, 'Length Y');
    app.RadarfrequencyGHz130GHz2308cmEditField.ValueChangedFcn = ...
        @(~,~) app.validateFieldAndUpdateSurface(app.RadarfrequencyGHz130GHz2308cmEditField, 'Radar frequency');
    app.SamplesperwavelengthEditField.ValueChangedFcn = ...
        @(~,~) app.validateFieldAndUpdateSurface(app.SamplesperwavelengthEditField, 'Samples/wavelength');

    % ── Onglet 3 ──
    app.Windspeedu10msEditField_2.ValueChangedFcn = ...
        @(~,~) app.validateField(app.Windspeedu10msEditField_2, 'Wind speed u10');
    app.FetchmEditField_3.ValueChangedFcn = ...
        @(~,~) app.validateField(app.FetchmEditField_3, 'Fetch');
    app.LengthmEditField.ValueChangedFcn = ...
        @(~,~) app.validateFieldAndUpdateNs(app.LengthmEditField, 'Length');
    app.RadarfrequencyGHz130GHz2308cmEditField_2.ValueChangedFcn = ...
        @(~,~) app.validateFieldAndUpdateFreqProfile(app.RadarfrequencyGHz130GHz2308cmEditField_2, 'Radar frequency');
    app.SamplesperwavelengthEditField_2.ValueChangedFcn = ...
        @(~,~) app.validateFieldAndUpdateNs(app.SamplesperwavelengthEditField_2, 'Samples/wavelength');
    app.NprofilesEditField.ValueChangedFcn = ...
        @(~,~) app.validateField(app.NprofilesEditField, 'Number of profiles');

    % ── Onglet 4 (lecture seule, pas de validation directe) ──
    app.IncidenceangledegEditField_2.ValueChangedFcn = ...
        @(~,~) app.validateField(app.IncidenceangledegEditField_2, 'Incidence angle');
end

function ok = validateField(app, field, fieldName)
    % Retourne true si valide, false sinon + clignote rouge + message
    ok = true;
    if field.Value < 0
        ok = false;
        app.flashFieldRed(field, fieldName);
    end
end

function ok = validateFieldAndUpdateSurface(app, field, fieldName)
    ok = app.validateField(field, fieldName);
    if ok
        app.updateNsSurface();
        app.updateFreqLabelSurface();
    end
end

function ok = validateFieldAndUpdateNs(app, field, fieldName)
    ok = app.validateField(field, fieldName);
    if ok
        app.updateNs();
    end
end

function ok = validateFieldAndUpdateFreqProfile(app, field, fieldName)
    ok = app.validateField(field, fieldName);
    if ok
        app.updateFreqLabelProfile();
    end
end

function flashFieldRed(app, field, fieldName)
    % Clignote rouge 3 fois + message d'erreur
    uialert(app.UIFigure, ...
        sprintf('"%s" cannot be negative.\nEntered value: %.4g', fieldName, field.Value), ...
        'Invalid value', 'Icon', 'error');
    
    % Remettre à 0 (valeur minimale sûre)
    field.Value = 0;
    
    % Clignotement rouge (3 fois)
    for i = 1:3
        field.BackgroundColor = [1 0.6 0.6];  % rouge
        drawnow;
        pause(0.15);
        field.BackgroundColor = [1 1 1];       % blanc
        drawnow;
        pause(0.10);
    end
end


function resetFieldColor(app, field)
    if isvalid(field)
        field.BackgroundColor = [1 1 1];  % Blanc
    end
end

function checkAllFieldsBeforeCalculation(app, fieldNames, fields)
    % Vérifier tous les champs avant calcul
    allValid = true;
    invalidFields = {};
    
    for i = 1:length(fields)
        if fields{i}.Value < 0
            allValid = false;
            invalidFields{end+1} = fieldNames{i};
            app.validateAndHighlight(fields{i}, fieldNames{i});
        end
    end
    
    if ~allValid
        errorMsg = sprintf('Valeurs invalides (négatives):\n• %s', strjoin(invalidFields, '\n• '));
        uialert(app.UIFigure, errorMsg, 'Paramètres invalides', 'Icon', 'error');
        return;
    end
end


       function onCurveClick(app, src, evt)
    % Récupérer l'index de la courbe cliquée
    idx = str2double(src.Tag);
    if isnan(idx) || idx < 1 || idx > length(app.SessionData)
        return;
    end

    entry = app.SessionData{idx};

    % Position du clic
    clickPos = evt.IntersectionPoint;
    x_click  = clickPos(1);

    % Valeur NRCS interpolée au point cliqué
    valid    = ~isnan(entry.Nrcs_dB);
    y_interp = interp1(entry.Th_i_d(valid), entry.Nrcs_dB(valid), ...
                       x_click, 'linear', 'extrap');

    % Supprimer ancien tooltip
    delete(findobj(app.UIAxes, 'Tag', 'tooltip_box'));

    % Texte tooltip avec les 7 infos
    tipText = sprintf(['Incidence Angle (deg): %.1f\n' ...
                       'NRCS (dB): %.4f\n' ...
                       'Model: %s\n' ...
                       'Pol: %s\n' ...
                       'Freq (GHz): %.2f\n' ...
                       'Wind (m/s): %.1f\n' ...
                       'Wind Angle (deg): %.0f'], ...
        x_click, y_interp, entry.model, entry.pol, ...
        entry.freq, entry.u10, entry.windDir);

    % Affichage tooltip sur le graphe
    text(app.UIAxes, x_click, y_interp, tipText, ...
        'Tag', 'tooltip_box', ...
        'BackgroundColor', [1 1 0.9], ...
        'EdgeColor', [0.3 0.3 0.3], ...
        'Margin', 6, ...
        'FontSize', 9, ...
        'VerticalAlignment', 'bottom', ...
        'HorizontalAlignment', 'left', ...
        'Interpreter', 'none');

    % Mettre à jour Results panel
    app.Label_9.Text = ...
        sprintf('NRCS: %.2f dB  (for %.1f° — %s %s)', ...
        y_interp, x_click, entry.model, entry.pol);
end


     function clearTooltip(app, ~, ~)
        oldTip = findobj(app.UIAxes, 'Tag', 'tooltip_box');
        delete(oldTip);
     end


     function drawProfiles(app, selectedIdx)
        cla(app.UIAxes_3);
        hold(app.UIAxes_3, 'on');
        grid(app.UIAxes_3, 'on');
        xlabel(app.UIAxes_3, 'X (m)');
    ylabel(app.UIAxes_3, 'Z (m)');
    
    Ntotal = numel(app.ProfilesData);
    if Ntotal == 0
        title(app.UIAxes_3, ''); 
        hold(app.UIAxes_3, 'off'); 
        return;
    end

    % ── Plage à afficher ──
    if isfield(app.LastSurface, 'ProfileRange') && ...
       numel(app.LastSurface.ProfileRange) == 2
        iStart = app.LastSurface.ProfileRange(1);
        iEnd   = app.LastSurface.ProfileRange(2);
    else
        iStart = 1;
        iEnd   = min(5, Ntotal);
    end
    iStart = max(1, iStart);
    iEnd   = min(Ntotal, iEnd);
    indices = iStart:iEnd;
    Ndisplay = numel(indices);

    colors = lines(Ndisplay);
    for ki = 1:Ndisplay
        k = indices(ki);
        p = app.ProfilesData{k};

        if k == selectedIdx
            ls = '-';
            lw = 2.5;
        else
            ls = '--';
            lw = 0.8;
        end

        plot(app.UIAxes_3, p.X, p.H, ...
            'Color',         colors(ki,:), ...
            'LineStyle',     ls, ...
            'LineWidth',     lw, ...
            'DisplayName',   sprintf('P%d', k), ...
            'Tag',           num2str(k), ...
            'ButtonDownFcn', @(~,~) app.onProfileClick(k));
    end

    legend(app.UIAxes_3, 'Location', 'northeast');
    title(app.UIAxes_3, sprintf('Profiles P%d – P%d  |  %d total', ...
        iStart, iEnd, Ntotal));
    hold(app.UIAxes_3, 'off');
    
    % Ajuster les limites
    allX = []; allH = [];
    for ki = 1:Ndisplay
        k = indices(ki);
        allX = [allX; app.ProfilesData{k}.X(:)];
        allH = [allH; app.ProfilesData{k}.H(:)];
    end
    if ~isempty(allX)
        xlim(app.UIAxes_3, [min(allX), max(allX)]);
        mg = (max(allH)-min(allH))*0.02;
        if mg == 0; mg = 0.01; end
        ylim(app.UIAxes_3, [min(allH)-mg, max(allH)+mg]);
    end
end

   function onProfileClick(app, idx)
    if idx < 1 || idx > numel(app.ProfilesData)
        return;
    end

    H = app.ProfilesData{idx}.H;
    
    % Récupérer les paramètres actuels
    u10 = app.Windspeedu10msEditField_2.Value;
    fetch_m = app.FetchmEditField_3.Value;

    % Stats
    app.Label_2.Text = sprintf('%.2f m', min(H));
    app.Label_3.Text = sprintf('%.2f m', max(H));
    app.Label_4.Text = sprintf('%.2f m', mean(H));
    
    % Utiliser la fonction existante pour Zstd (isSurface = false pour l'onglet 3)
    app.updateZstdWithTheory(H, u10, fetch_m, false);

    % Synchroniser dropdown
    targetItem = sprintf('P%d', idx);
    if ismember(targetItem, app.ProfileDropDown.Items)
        app.ProfileDropDown.Value = targetItem;
    end

    % ── Mettre à jour le titre du panel avec le profil sélectionné ──
    app.SelectedProfileStatisticsPanel.Title = sprintf('Selected Profile Statistics  [ P%d ]', idx);

    % Vérifier plage
    if isfield(app.LastSurface, 'ProfileRange') && numel(app.LastSurface.ProfileRange) == 2
        iStart = app.LastSurface.ProfileRange(1);
        iEnd   = app.LastSurface.ProfileRange(2);
    else
        iStart = 1;
        iEnd   = min(5, numel(app.ProfilesData));
    end

    % Recentrer si hors plage
    if idx < iStart || idx > iEnd
        rangeSize = iEnd - iStart;
        newStart  = max(1, idx - floor(rangeSize/2));
        newEnd    = min(numel(app.ProfilesData), newStart + rangeSize);
        app.LastSurface.ProfileRange = [newStart, newEnd];
        app.fillRangeDropDowns(numel(app.ProfilesData), newStart, newEnd);
    end

    app.drawProfiles(idx);
end


   function onProfileDropDownChanged(app)
    val = app.ProfileDropDown.Value;

    if contains(val, '—') || isempty(app.ProfilesData)
        return;
    end

    idx = str2double(val(2:end));
    if isnan(idx) || idx < 1 || idx > numel(app.ProfilesData)
        return;
    end

    app.SelectedProfileStatisticsPanel.Title = sprintf('Selected Profile Statistics  [ P%d ]', idx);

    H = app.ProfilesData{idx}.H;
    
    % Récupérer les paramètres actuels pour le calcul théorique
    u10 = app.Windspeedu10msEditField_2.Value;
    fetch_m = app.FetchmEditField_3.Value;

    % Stats
    app.Label_2.Text = sprintf('%.2f m', min(H));
    app.Label_3.Text = sprintf('%.2f m', max(H));
    app.Label_4.Text = sprintf('%.2f m', mean(H));
    
    % Utiliser la fonction existante pour Zstd (isSurface = false pour l'onglet 3)
    app.updateZstdWithTheory(H, u10, fetch_m, false);

    % Lire plage actuelle
    if isfield(app.LastSurface, 'ProfileRange') && numel(app.LastSurface.ProfileRange) == 2
        iStart = app.LastSurface.ProfileRange(1);
        iEnd   = app.LastSurface.ProfileRange(2);
    else
        iStart = 1;
        iEnd   = min(5, numel(app.ProfilesData));
        app.LastSurface.ProfileRange = [iStart, iEnd];
    end

    % Ajuster si hors plage
    if idx < iStart || idx > iEnd
        rangeSize = iEnd - iStart;
        newStart  = max(1, idx - floor(rangeSize/2));
        newEnd    = newStart + rangeSize;
        if newEnd > numel(app.ProfilesData)
            newEnd   = numel(app.ProfilesData);
            newStart = max(1, newEnd - rangeSize);
        end
        app.LastSurface.ProfileRange = [newStart, newEnd];
        app.fillRangeDropDowns(numel(app.ProfilesData), newStart, newEnd);
    end

    app.drawProfiles(idx);
end

  function updateNs(app)
     l_s  = app.LengthmEditField.Value;
    freq = app.RadarfrequencyGHz130GHz2308cmEditField_2.Value;
    nlb  = app.SamplesperwavelengthEditField_2.Value;

    if l_s <= 0 || freq <= 0 || nlb < 1
        app.NumberofsamplesEditField_3.Value = 0;
        return;
    end

    c_ms     = 3e8;
    lambda_m = c_ms / (freq * 1e9);
    n_s_raw  = round(l_s / lambda_m) * nlb;

    % ⭐ CORRECTION : utiliser la bonne checkbox ⭐
    if app.Roundtothenearestpowerof2CheckBox_2.Value  % ← _2 au lieu de rien
        n_s = 2^round(log2(max(1, n_s_raw)));
        l_s_new = n_s * lambda_m / nlb;
        app.LengthmEditField.Value = l_s_new;
    else
        n_s = n_s_raw;
    end

    app.NumberofsamplesEditField_3.Value = n_s;
end

   function updateNsSurface(app)
    lx   = app.LengthXmEditField.Value;
    ly   = app.LengthYmEditField.Value;
    freq = app.RadarfrequencyGHz130GHz2308cmEditField.Value;
    nlb  = app.SamplesperwavelengthEditField.Value;

    if lx <= 0 || ly <= 0 || freq <= 0 || nlb < 1
        app.NumberofsamplesEditField_2.Value = 0;
        return;
    end

    c_ms     = 3e8;
    lambda_m = c_ms / (freq * 1e9);
    dxy_m    = lambda_m / nlb;
    n_x      = round(lx / dxy_m / 2) * 2;
    n_y      = round(ly / dxy_m / 2) * 2;

    if app.Roundtothenearestpowerof2CheckBox.Value  % ← Correction ici : utiliser la bonne propriété
        n_x = 2^round(log2(max(1, n_x)));
        n_y = 2^round(log2(max(1, n_y)));
        app.LengthXmEditField.Value = n_x * dxy_m;
        app.LengthYmEditField.Value = n_y * dxy_m;
    end

    app.NumberofsamplesEditField_2.Value = n_x * n_y;
end
        function onRangeDropDownChanged(app)
    if isempty(app.ProfilesData); return; end

    vs = app.RangeStartDropDown.Value;
    ve = app.RangeEndDropDown.Value;

    if contains(vs,'—') || contains(ve,'—'); return; end

    iStart = str2double(vs);
    iEnd   = str2double(ve);

    if isnan(iStart) || isnan(iEnd); return; end
    
    if iEnd < iStart
        iEnd = iStart;
        app.RangeEndDropDown.ValueChangedFcn = [];
        app.RangeEndDropDown.Value = num2str(iEnd);
        app.RangeEndDropDown.ValueChangedFcn = @(~,~) app.onRangeDropDownChanged();
    end

    % Limiter à 5 profils
    if (iEnd - iStart + 1) > 5
        iEnd = iStart + 4;
        app.RangeEndDropDown.ValueChangedFcn = [];
        if ismember(num2str(iEnd), app.RangeEndDropDown.Items)
            app.RangeEndDropDown.Value = num2str(iEnd);
        end
        app.RangeEndDropDown.ValueChangedFcn = @(~,~) app.onRangeDropDownChanged();
    end

    % Stocker et redessiner
    app.LastSurface.ProfileRange = [iStart, iEnd];

    app.Label_2.Text = '—';
    app.Label_4.Text = '—';
    app.Label_3.Text = '—';
    app.Label_5.Text = '—';

    if ismember('— Select a profile —', app.ProfileDropDown.Items)
        app.ProfileDropDown.Value = '— Select a profile —';
    end

    app.drawProfiles(-1);
end

       function fillRangeDropDowns(app, Nprof, iStart, iEnd)
    items = arrayfun(@(k) num2str(k), 1:Nprof, 'UniformOutput', false);

    app.RangeStartDropDown.ValueChangedFcn = [];
    app.RangeEndDropDown.ValueChangedFcn   = [];

    app.RangeStartDropDown.Items = items;
    app.RangeEndDropDown.Items   = items;

    app.RangeStartDropDown.Value = num2str(iStart);
    app.RangeEndDropDown.Value   = num2str(iEnd);

    app.RangeStartDropDown.ValueChangedFcn = @(~,~) app.onRangeDropDownChanged();
    app.RangeEndDropDown.ValueChangedFcn   = @(~,~) app.onRangeDropDownChanged();
end

       function updateFreqLabelSurface(app)
    freq = app.RadarfrequencyGHz130GHz2308cmEditField.Value;
    if freq <= 0; return; end
    lambda_cm = (3e8 / (freq * 1e9)) * 100;
    app.RadarfrequencyGHz130GHz2308cmLabel.Text = ...
        sprintf('Radar frequency (GHz):\n%.2f GHz → λ = %.2f cm', freq, lambda_cm);
end

       function updateFreqLabelProfile(app)
    freq = app.RadarfrequencyGHz130GHz2308cmEditField_2.Value;
    if freq <= 0; return; end
    lambda_cm = (3e8 / (freq * 1e9)) * 100;
    app.RadarfrequencyGHz130GHz2308cmLabel_2.Text = ...
        sprintf('Radar frequency (GHz):\n%.2f GHz → λ = %.2f cm', freq, lambda_cm);
    app.updateNs();
end

       function updateFreqLabelTab4(app)
    freq = app.RadarfrequencyGHz130GHz2308cmEditField_3.Value;
    if freq <= 0; return; end
    lambda_cm = (3e8 / (freq * 1e9)) * 100;
    app.RadarfrequencyGHz130GHz2308cmLabel_3.Text = ...
        sprintf('Radar frequency (GHz):\n%.2f GHz → λ = %.2f cm', freq, lambda_cm);
end

     function onSurfaceClick(app, src, evt)
    if ~isfield(app.LastSurface, 'HH_XY'); return; end
    
    clickPos = evt.IntersectionPoint;
    if isempty(clickPos) || numel(clickPos) < 2; return; end

    HH_XY = app.LastSurface.HH_XY;
    X     = app.LastSurface.X;
    Y     = app.LastSurface.Y;

    % Récupérer les coordonnées du clic
    % Dans UIAxes_2, l'axe X représente Y (m) et l'axe Y représente X (m)
    y_click = clickPos(1);  % Position Y sur la carte
    x_click = clickPos(2);  % Position X sur la carte
    
    [~, iy] = min(abs(Y - y_click));
    [~, ix] = min(abs(X - x_click));
    
    % Profil à Y constant (coupe selon X)
    profil_Z = HH_XY(:, iy);

    % Rendre visible UIAxes5 et ajuster le layout
    app.UIAxes5.Visible = 'on';
    app.GridLayout5_2.RowHeight = {'1x', 150, 130};

    % Tracer le profil
    cla(app.UIAxes5);
    plot(app.UIAxes5, X, profil_Z, 'b-', 'LineWidth', 1.5);
    hold(app.UIAxes5, 'on');
    
    % Marquer le point cliqué
    plot(app.UIAxes5, X(ix), profil_Z(ix), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
    
    % Afficher les coordonnées du point
    text(app.UIAxes5, X(ix) + 0.02*diff(xlim(app.UIAxes5)), profil_Z(ix), ...
        sprintf('  Y = %.2f m\n  Z = %.3f m', Y(iy), profil_Z(ix)), ...
        'FontSize', 9, 'Color', 'k', 'VerticalAlignment', 'bottom', ...
        'BackgroundColor', [1 1 0.9], 'EdgeColor', [0.5 0.5 0.5]);
    
    hold(app.UIAxes5, 'off');
    
    % Configurer les axes du profil
    xlabel(app.UIAxes5, 'X (m)');
    ylabel(app.UIAxes5, 'Z (m)');
    title(app.UIAxes5, sprintf('Profile at Y = %.2f m', Y(iy)));
    grid(app.UIAxes5, 'on');
    app.UIAxes5.FontSize = 10;
    
    % Ajuster les limites Y pour bien voir
    zMin = min(profil_Z);
    zMax = max(profil_Z);
    margin = max(0.1, (zMax - zMin) * 0.1);
    ylim(app.UIAxes5, [zMin - margin, zMax + margin]);

    % Ligne rouge sur la surface 3D pour indiquer la coupe
    hold(app.UIAxes_2, 'on');
    delete(findobj(app.UIAxes_2, 'Tag', 'profile_line'));
    
    % La ligne est à X constant (x_click) sur toute l'étendue de Y
    zTop = max(HH_XY(:));
    lineZ = repmat(zTop * 1.05, size(Y));
    lineX = repmat(x_click, size(Y));
    
    plot3(app.UIAxes_2, Y, lineX, lineZ, ...
        'r-', 'LineWidth', 2, 'Tag', 'profile_line');
    hold(app.UIAxes_2, 'off');

    % Reconnecter le callback de clic sur la surface
    hSurf = findobj(app.UIAxes_2, 'Type', 'surface');
    if ~isempty(hSurf)
        hSurf(1).ButtonDownFcn = @(s, e) app.onSurfaceClick(s, e);
        hSurf(1).HitTest = 'on';
        hSurf(1).PickableParts = 'all';
    end
end

    function onProfileSelectorChanged(app)
    val = app.SelectprofileDropDown.Value;
    if isempty(app.ProfilesData) || contains(val, '__') || contains(val, '—')
        return;
    end

    idx = str2double(val(2:end));
    if isnan(idx) || idx < 1 || idx > numel(app.ProfilesData); return; end

    % ── Mettre la plage sur ce seul profil (Start = End = idx) ──
    idxStr = num2str(idx);
    if ismember(idxStr, app.BistaticRangeStart.Items)
        app.BistaticRangeStart.ValueChangedFcn = [];
        app.BistaticRangeEnd.ValueChangedFcn   = [];
        app.BistaticRangeStart.Value = idxStr;
        app.BistaticRangeEnd.Value   = idxStr;
        app.BistaticRangeStart.ValueChangedFcn = @(~,~) app.onBistaticRangeChanged();
        app.BistaticRangeEnd.ValueChangedFcn   = @(~,~) app.onBistaticRangeChanged();
    end

    % Afficher uniquement ce profil dans UIAxes_4
    app.ProfileForNRCS  = app.ProfilesData{idx}.H;
    app.ProfileXForNRCS = app.ProfilesData{idx}.X;

    cla(app.UIAxes_4); hold(app.UIAxes_4,'on'); grid(app.UIAxes_4,'on');
    plot(app.UIAxes_4, app.ProfileXForNRCS, app.ProfileForNRCS, ...
        'Color', [0.1 0.3 0.8], 'LineWidth', 2.0, ...
        'DisplayName', sprintf('P%d', idx));
    legend(app.UIAxes_4, 'Location','northeast');
    xlabel(app.UIAxes_4, 'X (m)'); ylabel(app.UIAxes_4, 'Z (m)');
    title(app.UIAxes_4, sprintf('Profile P%d  |  click Calculate', idx));
    hold(app.UIAxes_4,'off');

    % Effacer NRCS précédent
    cla(app.NRCSAxes);
    title(app.NRCSAxes, sprintf('Bistatic NRCS — P%d — click Calculate', idx));
end


    function autoRegenerateSurface(app)
    if ~isfield(app.LastSurface, 'HH_XY')
        return;
    end

    isoMdl  = strtrim(app.IsotropicspectrumDropDown.Value);
    angMdl  = strtrim(app.AngularspectrumDropDown.Value);
    u10     = app.LastSurface.windSpeed;
    fetch_m = app.LastSurface.fetch_m;
    freq    = app.LastSurface.freq_GHz;
    n_x     = app.LastSurface.nx;
    n_y     = app.LastSurface.ny;
    lx      = app.LengthXmEditField.Value;
    ly      = app.LengthYmEditField.Value;
    phiw_d  = app.WinddirectiondegEditField.Value;
    prec    = 2 - app.UsesingleprecisionCheckBox.Value;

    d = uiprogressdlg(app.UIFigure, ...
        'Title',         'Mise à jour de la surface', ...
        'Message',       sprintf('Regenerating surface — Iso: %s | Ang: %s ...', isoMdl, angMdl), ...
        'Indeterminate', 'on');
    drawnow;

    try
        tic;
        % Convertir le nom du spectre isotrope en index numérique
        switch isoMdl
            case 'Apel';                isoIdx = 1;
            case 'Elfouhaily';          isoIdx = 2;
            case {'Donelan','Donelan-Pierson'}; isoIdx = 3;
            otherwise;                  isoIdx = 1;
        end
        % Convertir le nom du spectre angulaire en index numérique
        switch angMdl
            case 'Elfouhaily';   angIdx = 1;
            case 'Du';           angIdx = 2;
            case 'Donelan';      angIdx = 3;
            case 'Banner';       angIdx = 4;
            case 'McDaniel';     angIdx = 5;
            otherwise;           angIdx = 1;
        end

        % ── APPEL avec les spectres choisis ──
        % Adapter selon la vraie signature de ta fonction :
        % f_GeneSurfMer3D_Fetch_Elfo_v3(u10, phiw_d, lx, ly, n_x, n_y, isoIdx, prec, fetch_m, angIdx)
        [HH_XY, X, Y] = f_GeneSurfMer3D_Fetch_Elfo_v3( ...
            u10, phiw_d, lx, ly, n_x, n_y, isoIdx, prec, fetch_m);
        temps = toc;
        close(d);
    catch ME
        close(d);
        uialert(app.UIFigure, ['Regeneration error : ' ME.message], ...
            'Erreur', 'Icon', 'error');
        return;
    end

    app.LastSurface.HH_XY = HH_XY;
    app.LastSurface.X     = X;
    app.LastSurface.Y     = Y;

    app.Label.Text   = sprintf('%.4f m', min(HH_XY(:)));
    app.Label_6.Text = sprintf('%.4f m', max(HH_XY(:)));
    app.Label_7.Text = sprintf('%.4f m', mean(HH_XY(:)));
    app.updateZstdWithTheory(HH_XY, u10, fetch_m, true);

    cla(app.UIAxes_2);
    h = surf(app.UIAxes_2, Y, X, HH_XY);
    shading(app.UIAxes_2, 'interp');
    colormap(app.UIAxes_2, 'jet');
    rotate3d(app.UIAxes_2, 'on');
    view(app.UIAxes_2, -45, 30);
    axis(app.UIAxes_2, 'tight');
    app.UIAxes_2.DataAspectRatio = [1 1 0.2];
    xlabel(app.UIAxes_2, 'Y (m)');
    ylabel(app.UIAxes_2, 'X (m)');
    zlabel(app.UIAxes_2, 'Z (m)');
    app.UIAxes_2.Box   = 'on';
    app.UIAxes_2.Color = [0.95 0.95 0.95];
    cb = colorbar(app.UIAxes_2);
    cb.Label.String = 'Hauteur (m)';
    h.HitTest       = 'on';
    h.PickableParts = 'all';
    h.ButtonDownFcn = @(s,e) app.onSurfaceClick(s, e);
    app.UIAxes_2.HitTest = 'on';

    title(app.UIAxes_2, sprintf('Surface — Iso: %s | Ang: %s  (%.2f s)', ...
        isoMdl, angMdl, temps));
end


function autoRegenerateProfiles(app)
    if isempty(app.ProfilesData)
        return;
    end

    isoMdl  = strtrim(app.IsotropicspectrumDropDown_2.Value);
    switch isoMdl
        case 'Apel';                    isoIdx = 1;
        case 'Elfouhaily';              isoIdx = 2;
        case {'Donelan','Donelan-Pierson'}; isoIdx = 3;
        otherwise;                      isoIdx = 1;
    end

    Nprof   = numel(app.ProfilesData);
    u10     = app.Windspeedu10msEditField_2.Value;
    fetch_m = app.FetchmEditField_3.Value;
    l_s     = app.LengthmEditField.Value;
    freq    = app.RadarfrequencyGHz130GHz2308cmEditField_2.Value;
    nlb     = app.SamplesperwavelengthEditField_2.Value;
    prec    = 2;

    c_ms     = 3e8;
    lambda_m = c_ms / (freq * 1e9);
    n_s      = round(l_s / lambda_m) * nlb;
    n_s      = 2^nextpow2(n_s);

    useParallel = app.YesCheckBox.Value;

    d = uiprogressdlg(app.UIFigure, ...
        'Title',   'Mise à jour des profils', ...
        'Message', sprintf('Regenerating %d profils — Spectre: %s | Mode: %s ...', ...
                           Nprof, isoMdl, app.ternaireModeStr(useParallel)), ...
        'Indeterminate', 'on');
    drawnow;

    H_all = cell(1, Nprof);
    X_all = cell(1, Nprof);

    try
        tic;
        if useParallel
            parfor k = 1:Nprof
                [H_k, X_k, ~, ~, ~, ~, ~] = f_GeneSurfMer2D_ElfoGauss_Fetch_v2b( ...
                    u10, l_s, n_s, double(k), fetch_m, prec);
                H_all{k} = H_k(:);
                X_all{k} = X_k(:);
            end
        else
            for k = 1:Nprof
                [H_k, X_k, ~, ~, ~, ~, ~] = f_GeneSurfMer2D_ElfoGauss_Fetch_v2b( ...
                    u10, l_s, n_s, double(k), fetch_m, prec);
                H_all{k} = H_k(:);
                X_all{k} = X_k(:);
            end
        end
        temps = toc;
        close(d);
    catch ME
        close(d);
        uialert(app.UIFigure, ['Error : ' ME.message], 'Error', 'Icon', 'error');
        return;
    end

    for k = 1:Nprof
        app.ProfilesData{k}.H = H_all{k};
        app.ProfilesData{k}.X = X_all{k};
    end

   % Réinitialiser les stats (le user devra recliquer sur un profil)
    app.Label_2.Text = '—';
    app.Label_3.Text = '—';
    app.Label_4.Text = '—';
    app.Label_5.Text = '—';

    currentVal = app.ProfileDropDown.Value;
    if contains(currentVal, '—') || ~contains(currentVal, 'P')
        selIdx = -1;
    else
        selIdx = str2double(currentVal(2:end));
        if isnan(selIdx); selIdx = -1; end
    end
    app.drawProfiles(selIdx);

    title(app.UIAxes_3, sprintf('Profils (%d) — %s  (%.2f s)', Nprof, isoMdl, temps));
end

% function onBistaticRangeChanged(app)
%     if isempty(app.ProfilesData); return; end
%     vs = app.BistaticRangeStart.Value;
%     ve = app.BistaticRangeEnd.Value;
%     if contains(vs,'—') || contains(ve,'—'); return; end
%     iS = str2double(vs);
%     iE = str2double(ve);
%     if isnan(iS) || isnan(iE); return; end
% 
%     % Corriger si inversé
%     if iE < iS
%         iE = iS;
%         app.BistaticRangeEnd.ValueChangedFcn = [];
%         if ismember(num2str(iE), app.BistaticRangeEnd.Items)
%             app.BistaticRangeEnd.Value = num2str(iE);
%         end
%         app.BistaticRangeEnd.ValueChangedFcn = @(~,~) app.onBistaticRangeChanged();
%     end
% 
%     % Limiter à 5
%     if (iE - iS + 1) > 5
%         iE = iS + 4;
%         app.BistaticRangeEnd.ValueChangedFcn = [];
%         if ismember(num2str(iE), app.BistaticRangeEnd.Items)
%             app.BistaticRangeEnd.Value = num2str(iE);
%         end
%         app.BistaticRangeEnd.ValueChangedFcn = @(~,~) app.onBistaticRangeChanged();
%     end
% 
%     % ── Synchroniser SelectprofileDropDown avec la borne Start ──
%     targetVal = sprintf('P%d', iS);
%     if ismember(targetVal, app.SelectprofileDropDown.Items)
%         oldCb = app.SelectprofileDropDown.ValueChangedFcn;
%         app.SelectprofileDropDown.ValueChangedFcn = [];
%         app.SelectprofileDropDown.Value = targetVal;
%         app.SelectprofileDropDown.ValueChangedFcn = oldCb;
%     end
% 
%     % Afficher tous les profils de la plage dans UIAxes_4
%     cla(app.UIAxes_4); hold(app.UIAxes_4,'on'); grid(app.UIAxes_4,'on');
%     N = iE - iS + 1;
%     colors = lines(N);
%     for ki = 1:N
%         k = iS + ki - 1;
%         lw = 2.5; ls = '-';
%         if k ~= iS; lw = 0.9; ls = '--'; end  % profil de référence (Start) en gras
%         plot(app.UIAxes_4, app.ProfilesData{k}.X, app.ProfilesData{k}.H, ...
%             'Color', colors(ki,:), 'LineWidth', lw, 'LineStyle', ls, ...
%             'DisplayName', sprintf('P%d', k));
%     end
%     legend(app.UIAxes_4, 'Location','northeast');
%     xlabel(app.UIAxes_4,'X (m)'); ylabel(app.UIAxes_4,'Z (m)');
%     if N == 1
%         title(app.UIAxes_4, sprintf('Profile P%d  |  click Calculate', iS));
%     else
%         title(app.UIAxes_4, sprintf('Profiles P%d – P%d  (%d profiles)  |  click Calculate', iS, iE, N));
%     end
%     hold(app.UIAxes_4,'off');
% 
%     % Effacer le NRCS précédent
%     cla(app.NRCSAxes);
%     title(app.NRCSAxes, sprintf('Bistatic NRCS — P%d to P%d — click Calculate', iS, iE));
% end


    %% Fonction pour mettre à jour la note des plages valides pour les modèles
    function updateValidRangesNote(app)
        switch app.EMmodelDropDown.Value
            case 'NRL'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 30°-89.9°, Frequency 0.5-35 GHz, Wind Speed u10 0-13.2 m/s';
            case 'TSC'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 0°-89.9°, Frequency 0.5-35 GHz, Wind Speed u10 0-11.4 m/s';
            case 'SITTROP'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 80°-89.8°, Frequency 9.375 GHz, Wind Speed u10 0-14.9 m/s';
            case 'GIT'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 80°-89.9°, Frequency 1-100 GHz, Wind Speed u10 3.2-13.2 m/s';
            case 'RRE'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 80°-89.9°, Frequency 9-10 GHz, Wind Speed u10 3.2-13.2 m/s';
            case 'HYBRID'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 60°-90°, Frequency 0.1-100 GHz, Wind Speed u10 0-11.4 m/s';
            case 'Quilfen'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 18°-60°, Frequency 5.3 GHz, Wind Speed u10 < 20 m/s, Wind Angle 0°-360°';
            case 'CMOD2-I3'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 18°-57°, Frequency 5.3 GHz, Wind Speed u10 0-25 m/s, Wind Angle 0°-360°';
            case 'CMOD5'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 18°-57°, Frequency 5.3 GHz, Wind Speed u10 0-25 m/s, Wind Angle 0°-360°';
            case 'CMOD5N'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 18°-57°, Frequency 5.3 GHz, Wind Speed u10 0-25 m/s, Wind Angle 0°-360°';
            case 'Isoguchi'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 17°-43°, Frequency 1.3 GHz, Wind Speed u10 0-20 m/s, Wind Angle 0°-360°';
            case 'XMOD1R'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 20°-60°, Frequency 10 GHz, Wind Speed u10 0-20 m/s, Wind Angle 0°-360°';
            case 'XMOD2N'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 18°-46°, Frequency 10 GHz, Wind Speed u10 2-25 m/s, Wind Angle 0°-360°';
            case 'Meissner'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 24.36°-49.29°, Frequency 1.26 GHz, Wind Speed u10 0.2-22 m/s, Wind Angle 0°-360°';
            case 'Wentz84'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 0°-60°, Frequency 14.6 GHz, Wind Speed u10 0-20 m/s, Wind Angle 0°-360°';
            case 'Wentz99'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 15°-65°, Frequency 14.6 GHz, Wind Speed u10 0-20 m/s, Wind Angle 0°-360°';
            case 'Masuko X'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 30°-60°, Frequency 10 GHz, Wind Speed u19 3.2-17.2 m/s, Wind Angle 0°-360°';
            case 'Masuko Ka'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 30°-60°, Frequency 34.43 GHz, Wind Speed u19 3.2-17.2 m/s, Wind Angle 0°-360°';
            case 'NSCAT1'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 15°-60°, Frequency 13.6 GHz, Wind Speed u10 1-30 m/s, Wind Angle 0°-360°';  
            case 'TSM'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 0°–80°, Frequency 0.1–100 GHz, Wind Speed u10 0–25 m/s, Wind Angle 0°-360°';
            case 'SSA1'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 0°–80°, Frequency 0.1–100 GHz, Wind Speed u10 3–25 m/s, Wind Angle 0°-360°';
            case 'GO1sh'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 0°–50°, Frequency 0.1–100 GHz, Wind Speed u10 0–25 m/s, Wind Angle 0°–360°';
            case 'SPM1'
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 0°–80°, Frequency 0.1–100 GHz, Wind Speed u10 0–25 m/s, Wind Angle 0°–360°';
            otherwise
                app.ValidRangesNoteLabel.Text = 'Valid ranges: Not specified for this model.';
        end
    end

function AngleFieldValueChanged(app, field, fieldName, minVal, maxVal)
    % Gérer modulo pour les angles
    value = field.Value;
    
    if value < minVal || value > maxVal
        newValue = mod(value, maxVal);
        if newValue < minVal
            newValue = newValue + minVal;
        end
        
        field.Value = newValue;
        uialert(app.UIFigure, ...
            sprintf('%s ajusté: %.1f° → %.1f° (modulo %d°)', fieldName, value, newValue, maxVal), ...
            'Angle ajusté', 'Icon', 'info');
        
        % Mettre en surbrillance temporaire
        originalColor = field.BackgroundColor;
        field.BackgroundColor = [1 1 0.7];  % Jaune clair
        pause(0.5);
        field.BackgroundColor = originalColor;
    end
end


function setupAngleValidation(app)
    % Modulo 360° UNIQUEMENT sur :
    %   - Onglet 1 : Angle relative to wind direction
    %   - Onglet 2 : Wind direction
    % PAS sur l'angle d'incidence

    app.Anglerelativetowinddirection0360degEditField.ValueChangedFcn = ...
        @(~,~) app.applyModulo360(app.Anglerelativetowinddirection0360degEditField, ...
                                  'Angle rel. au vent (Onglet 1)');

    app.WinddirectiondegEditField.ValueChangedFcn = ...
        @(~,~) app.applyModulo360(app.WinddirectiondegEditField, ...
                                  'Wind direction (Onglet 2)');
end

function applyModulo360(app, field, fieldName)
    val = field.Value;
    % Appliquer modulo si hors [0, 360[
    if val < 0 || val >= 360
        newVal = mod(val, 360);
        field.Value = newVal;
        % Clignotement jaune pour signaler l'ajustement
        for i = 1:2
            field.BackgroundColor = [1 1 0.5];  % jaune
            drawnow;
            pause(0.15);
            field.BackgroundColor = [1 1 1];    % blanc
            drawnow;
            pause(0.10);
        end
        uialert(app.UIFigure, ...
            sprintf('"%s" automatically adjusted:\n%.1f° → %.1f°  (mod 360°)', ...
                fieldName, val, newVal), ...
            'Angle adjusted', 'Icon', 'info');
    end
end


function updateZstdWithTheory(app, surfaceHeight, u10, fetch_m, isSurface)
    % ── Zstd mesuré (expérimental) ──
    zstd_exp = std(surfaceHeight(:));

    % ── Zstd théorique : sqrt( trapz(K, Sh_iso) ) ──
    % (formule du professeur)
    K = logspace(log10(0.01), log10(1000), 2000);
    g   = 9.81;
    km  = 370;
    x0  = 2.2e4;
    k0  = g / u10^2;
    xmaj = k0 * fetch_m;

    try
        % Spectre isotrope Elfouhaily (utilisé pour la génération)
        omgc = 0.84 * tanh((xmaj/x0)^0.4)^(-0.75);
        kp   = k0 * omgc^2;
        alphp = 6e-3 * omgc^0.55;
        if omgc <= 1; gm = 1.7; else; gm = 1.7 + 6*log10(omgc); end
        sgm  = 0.08 * (1 + 4*omgc^(-3));
        L_PM = exp(-5/4*(kp./K).^2);
        J_p  = gm .^ exp(-(sqrt(K/kp)-1).^2 / (2*sgm^2));
        B_l  = 0.5*alphp * L_PM .* J_p .* sqrt(K/kp);
        alphhf = 1.8e-2;
        B_h  = alphhf * (1 + 2.5*(K/km).^(5/2)) .* exp(-0.25*(K/km-1).^2);
        Sh_iso = (B_l + B_h.*(K > 10*kp)) ./ K.^3;
        zstd_theo = sqrt(trapz(K, Sh_iso));
    catch
        zstd_theo = NaN;
    end

    % ── Zstd Cox & Munk (1954) ──
    mss_cox  = 0.003 + 0.00512 * u10;
    zstd_cox = 0.5 * sqrt(mss_cox);

    % ── Formatage : valeur (théorique = X ; Cox&Munk = Y) ──
    if isnan(zstd_theo)
        txtVal = sprintf('%.2f m  (théo = N/A ; Cox&Munk = %.2f m)', zstd_exp, zstd_cox);
    else
        txtVal = sprintf('%.2f m  (théo = %.2f m ; Cox&Munk = %.2f m)', zstd_exp, zstd_theo, zstd_cox);
    end

    if isSurface
        app.Label_8.Text  = txtVal;
        app.ZstdLabel.Text = 'Zstd:';
    else
        app.Label_5.Text   = txtVal;
        app.ZstdLabel_2.Text = 'Zstd:';
    end
end


function s = ternaireModeStr(app, useParallel)
    if useParallel
        s = 'Parallel (parfor)';
    else
        s = 'Sequential (for)';
    end
end

function onProfileSelectorTab5Changed(app)
    val = app.SelectprofileDropDown_2.Value;
    if isempty(app.ProfilesData) || contains(val, '__') || contains(val, '—')
        return;
    end
    idx = str2double(val(2:end));
    if isnan(idx) || idx < 1 || idx > numel(app.ProfilesData); return; end

    % ── Mettre la plage sur ce seul profil ──
    idxStr = num2str(idx);
    if ismember(idxStr, app.MonoRangeStart.Items)
        app.MonoRangeStart.ValueChangedFcn = [];
        app.MonoRangeEnd.ValueChangedFcn   = [];
        app.MonoRangeStart.Value = idxStr;
        app.MonoRangeEnd.Value   = idxStr;
        app.MonoRangeStart.ValueChangedFcn = @(~,~) app.onMonoRangeChanged();
        app.MonoRangeEnd.ValueChangedFcn   = @(~,~) app.onMonoRangeChanged();
    end

    % Afficher uniquement ce profil dans UIAxes_5
    cla(app.UIAxes_5); hold(app.UIAxes_5,'on'); grid(app.UIAxes_5,'on');
    plot(app.UIAxes_5, app.ProfilesData{idx}.X, app.ProfilesData{idx}.H, ...
        'Color', [0.1 0.3 0.8], 'LineWidth', 2.0, ...
        'DisplayName', sprintf('P%d', idx));
    legend(app.UIAxes_5, 'Location','northeast');
    xlabel(app.UIAxes_5, 'X (m)'); ylabel(app.UIAxes_5, 'Z (m)');
    title(app.UIAxes_5, sprintf('Profile P%d  |  click Calculate', idx));
    hold(app.UIAxes_5,'off');

    cla(app.MonoNRCSAxes);
    title(app.MonoNRCSAxes, sprintf('Monostatic NRCS — P%d — click Calculate', idx));
end

function updateFreqLabelTab5(app)
    freq = app.RadarfrequencyGHz130GHz2308cmEditField_4.Value;
    if freq <= 0; return; end
    lambda_cm = (3e8 / (freq * 1e9)) * 100;
    app.RadarfrequencyGHz130GHz2308cmLabel_4.Text = ...
        sprintf('Radar frequency (GHz):\n%.2f GHz → λ = %.2f cm', freq, lambda_cm);
end


function onBistaticRangeChanged(app)
    if isempty(app.ProfilesData); return; end
    vs = app.BistaticRangeStart.Value;
    ve = app.BistaticRangeEnd.Value;
    if contains(vs,'—') || contains(ve,'—'); return; end
    iS = str2double(vs);
    iE = str2double(ve);
    if isnan(iS) || isnan(iE); return; end
    % Corriger si inversé
    if iE < iS; iE = iS;
        app.BistaticRangeEnd.ValueChangedFcn = [];
        if ismember(num2str(iE), app.BistaticRangeEnd.Items)
            app.BistaticRangeEnd.Value = num2str(iE);
        end
        app.BistaticRangeEnd.ValueChangedFcn = @(~,~) app.onBistaticRangeChanged();
    end
    % Limiter à 5
    if (iE - iS + 1) > 5; iE = iS + 4;
        app.BistaticRangeEnd.ValueChangedFcn = [];
        if ismember(num2str(iE), app.BistaticRangeEnd.Items)
            app.BistaticRangeEnd.Value = num2str(iE);
        end
        app.BistaticRangeEnd.ValueChangedFcn = @(~,~) app.onBistaticRangeChanged();
    end
    % Afficher les profils dans UIAxes_4
    cla(app.UIAxes_4); hold(app.UIAxes_4,'on'); grid(app.UIAxes_4,'on');
    N = iE - iS + 1;
    colors = lines(N);
    for ki = 1:N
        k = iS + ki - 1;
        plot(app.UIAxes_4, app.ProfilesData{k}.X, app.ProfilesData{k}.H, ...
            'Color', colors(ki,:), 'LineWidth', 1.2, ...
            'DisplayName', sprintf('P%d', k));
    end
    legend(app.UIAxes_4, 'Location','northeast');
    xlabel(app.UIAxes_4,'X (m)'); ylabel(app.UIAxes_4,'Z (m)');
    title(app.UIAxes_4, sprintf('Profiles P%d – P%d  |  click Calculate', iS, iE));
    hold(app.UIAxes_4,'off');
    % Effacer le NRCS précédent
    cla(app.NRCSAxes);
    title(app.NRCSAxes, sprintf('Bistatic NRCS — P%d to P%d — click Calculate', iS, iE));
end

function onMonoRangeChanged(app)
    if isempty(app.ProfilesData); return; end
    vs = app.MonoRangeStart.Value;
    ve = app.MonoRangeEnd.Value;
    if contains(vs,'—') || contains(ve,'—'); return; end
    iS = str2double(vs);
    iE = str2double(ve);
    if isnan(iS) || isnan(iE); return; end

    if iE < iS
        iE = iS;
        app.MonoRangeEnd.ValueChangedFcn = [];
        if ismember(num2str(iE), app.MonoRangeEnd.Items)
            app.MonoRangeEnd.Value = num2str(iE);
        end
        app.MonoRangeEnd.ValueChangedFcn = @(~,~) app.onMonoRangeChanged();
    end
    if (iE - iS + 1) > 5
        iE = iS + 4;
        app.MonoRangeEnd.ValueChangedFcn = [];
        if ismember(num2str(iE), app.MonoRangeEnd.Items)
            app.MonoRangeEnd.Value = num2str(iE);
        end
        app.MonoRangeEnd.ValueChangedFcn = @(~,~) app.onMonoRangeChanged();
    end

    % ── Synchroniser SelectprofileDropDown_2 avec la borne Start ──
    targetVal = sprintf('P%d', iS);
    if ismember(targetVal, app.SelectprofileDropDown_2.Items)
        oldCb = app.SelectprofileDropDown_2.ValueChangedFcn;
        app.SelectprofileDropDown_2.ValueChangedFcn = [];
        app.SelectprofileDropDown_2.Value = targetVal;
        app.SelectprofileDropDown_2.ValueChangedFcn = oldCb;
    end

    cla(app.UIAxes_5); hold(app.UIAxes_5,'on'); grid(app.UIAxes_5,'on');
    N = iE - iS + 1;
    colors = lines(N);
    for ki = 1:N
        k = iS + ki - 1;
        lw = 2.5; ls = '-';
        if k ~= iS; lw = 0.9; ls = '--'; end
        plot(app.UIAxes_5, app.ProfilesData{k}.X, app.ProfilesData{k}.H, ...
            'Color', colors(ki,:), 'LineWidth', lw, 'LineStyle', ls, ...
            'DisplayName', sprintf('P%d', k));
    end
    legend(app.UIAxes_5, 'Location','northeast');
    xlabel(app.UIAxes_5,'X (m)'); ylabel(app.UIAxes_5,'Z (m)');
    if N == 1
        title(app.UIAxes_5, sprintf('Profile P%d  |  click Calculate', iS));
    else
        title(app.UIAxes_5, sprintf('Profiles P%d – P%d  (%d profiles)  |  click Calculate', iS, iE, N));
    end
    hold(app.UIAxes_5,'off');

    cla(app.MonoNRCSAxes);
    title(app.MonoNRCSAxes, sprintf('Monostatic NRCS — P%d to P%d — click Calculate', iS, iE));
end


end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ── Démarrer le pool parallèle si disponible ──
    try
        if isempty(gcp('nocreate'))
            parpool('local');
        end
    catch
        % Parallel Computing Toolbox non disponible — mode séquentiel
    end

    app.SessionData = {};
    app.UIAxes_2.Box = 'off';
    app.UIAxes_2.Color = 'none';
    app.UIAxes_2.ZColor = 'none';

    app.ClearButton_3.ButtonPushedFcn = @(~,~) app.ClearButton_3Pushed([]);
    app.ExportButton_3.ButtonPushedFcn = @(~,~) app.ExportButton_3Pushed([]);
    
    % ── Label NRCS ──
    app.Label_9.Position = [10 5 500 60];

    FrequencybandDropDownValueChanged(app, []);

    % ═══════════════════════════════════════════════════════════════
    %  ONGLET 3 : Generation of sea Profiles (adapté pour nouvelle app)
    % ═══════════════════════════════════════════════════════════════
    
    % ── Valeurs par défaut onglet 3 ──
    app.Windspeedu10msEditField_2.Value = 10;
    app.FetchmEditField_3.Value = 500000;
    app.LengthmEditField.Value = 200;
    app.RadarfrequencyGHz130GHz2308cmEditField_2.Value = 1.30;
    app.SamplesperwavelengthEditField_2.Value = 4;
    app.NprofilesEditField.Value = 5;
    app.UsesingleprecisionCheckBox_2.Value = false;

    % ── Connecter les boutons onglet 3 (noms adaptés) ──
    app.GenerateProfilesButton.ButtonPushedFcn = @(~,~) GenerateProfilesButtonPushed(app, []);
    app.ClearSurfaceButton_2.ButtonPushedFcn = @(~,~) ClearSurfaceButton_2Pushed(app, []);
    app.ExportSurfaceButton_2.ButtonPushedFcn = @(~,~) ExportSurfaceButton_2Pushed(app, []);

    % ═══════════════════════════════════════════════════════════════
    %  CONFIGURATION GRIDLAYOUT5_3 (remplace GridLayout30)
    % ═══════════════════════════════════════════════════════════════
    app.GridLayout5_3.RowHeight = {'1x', 40, 130};
    app.UIAxes_3.HitTest = 'on';
    app.UIAxes_3.PickableParts = 'all';
    app.SelectedProfileStatisticsPanel.Layout.Row = 3;

    % ── Créer la grille de la ligne 2 ──
    dropGrid = uigridlayout(app.GridLayout5_3);
    dropGrid.ColumnWidth = {'1x', 80, 25, 80, 30, '1x', 160};
    dropGrid.RowHeight = {'1x'};
    dropGrid.Padding = [8 4 8 4];
    dropGrid.ColumnSpacing = 4;
    dropGrid.Layout.Row = 2;
    dropGrid.Layout.Column = 1;
    dropGrid.BackgroundColor = [0.94 0.94 0.94];

    % ── Col 1 : Label "Profile range:" ──
    rangeLabel = uilabel(dropGrid);
    rangeLabel.Text = 'Profile range:';
    rangeLabel.FontWeight = 'bold';
    rangeLabel.FontSize = 11;
    rangeLabel.HorizontalAlignment = 'right';
    rangeLabel.Layout.Row = 1;
    rangeLabel.Layout.Column = 1;

    % ── Col 2 : Dropdown borne début ──
    app.RangeStartDropDown = uidropdown(dropGrid);
    app.RangeStartDropDown.Items = {'—'};
    app.RangeStartDropDown.Value = '—';
    app.RangeStartDropDown.Layout.Row = 1;
    app.RangeStartDropDown.Layout.Column = 2;
    app.RangeStartDropDown.ValueChangedFcn = @(~,~) app.onRangeDropDownChanged();

    % ── Col 3 : Tiret séparateur ──
    dashLabel = uilabel(dropGrid);
    dashLabel.Text = '—';
    dashLabel.HorizontalAlignment = 'center';
    dashLabel.FontSize = 14;
    dashLabel.FontWeight = 'bold';
    dashLabel.Layout.Row = 1;
    dashLabel.Layout.Column = 3;

    % ── Col 4 : Dropdown borne fin ──
    app.RangeEndDropDown = uidropdown(dropGrid);
    app.RangeEndDropDown.Items = {'—'};
    app.RangeEndDropDown.Value = '—';
    app.RangeEndDropDown.Layout.Row = 1;
    app.RangeEndDropDown.Layout.Column = 4;
    app.RangeEndDropDown.ValueChangedFcn = @(~,~) app.onRangeDropDownChanged();

    % ── Col 5 : espace ──
    sp = uilabel(dropGrid);
    sp.Text = '';
    sp.Layout.Row = 1;
    sp.Layout.Column = 5;

    % ── Col 6 : Label "Selected Profile:" ──
    selLabel = uilabel(dropGrid);
    selLabel.Text = 'Selected Profile:';
    selLabel.FontWeight = 'bold';
    selLabel.FontSize = 11;
    selLabel.HorizontalAlignment = 'right';
    selLabel.Layout.Row = 1;
    selLabel.Layout.Column = 6;

    % ── Col 7 : Dropdown Selected Profile ──
    app.ProfileDropDown = uidropdown(dropGrid);
    app.ProfileDropDown.Items = {'— Generate first —'};
    app.ProfileDropDown.Value = '— Generate first —';
    app.ProfileDropDown.Layout.Row = 1;
    app.ProfileDropDown.Layout.Column = 7;
    app.ProfileDropDown.ValueChangedFcn = @(~,~) app.onProfileDropDownChanged();

    % ── Compteur (invisible) ──
    app.ProfileCountLabel = uilabel(dropGrid);
    app.ProfileCountLabel.Text = '';
    app.ProfileCountLabel.Visible = 'off';
    app.ProfileCountLabel.Layout.Row = 1;
    app.ProfileCountLabel.Layout.Column = 5;

    % ── Titre et layout du panel stats ──
    app.SelectedProfileStatisticsPanel.Title = 'Profile Statistics';
    app.GridLayout10_2.ColumnWidth = {70, '1x', 70, '1x'};
    app.GridLayout10_2.RowHeight = {'1x', '1x'};
    app.GridLayout10_2.Padding = [10 8 10 8];

    % ── Connecter les champs n_s ──
    app.LengthmEditField.ValueChangedFcn = @(~,~) app.updateNs();
    app.SamplesperwavelengthEditField_2.ValueChangedFcn = @(~,~) app.updateNs();
    
    % ── Onglet 3 : fréquence → label + n_s ──
    app.RadarfrequencyGHz130GHz2308cmEditField_2.ValueChangedFcn = @(~,~) app.updateFreqLabelProfile();
    app.updateFreqLabelProfile();
    
    app.Roundtothenearestpowerof2CheckBox_2.ValueChangedFcn = @(~,~) app.updateNs();
    app.updateNs();

    % ═══════════════════════════════════════════════════════════════
    %  ONGLET 2 : Generation of sea surface (adapté)
    % ═══════════════════════════════════════════════════════════════
    
    app.RadarfrequencyGHz130GHz2308cmEditField.ValueChangedFcn = @(~,~) app.updateFreqLabelSurface();
    app.updateFreqLabelSurface();
    
    app.Roundtothenearestpowerof2CheckBox.ValueChangedFcn = @(~,~) app.updateNsSurface();
    app.updateNsSurface();

    % ── Connecter les champs onglet 2 pour mise à jour automatique de n_s ──
    app.LengthXmEditField.ValueChangedFcn = @(~,~) app.updateNsSurface();
    app.LengthYmEditField.ValueChangedFcn = @(~,~) app.updateNsSurface();
    app.RadarfrequencyGHz130GHz2308cmEditField.ValueChangedFcn = @(~,~) app.updateNsSurface();
    app.SamplesperwavelengthEditField.ValueChangedFcn = @(~,~) app.updateNsSurface();
    app.Roundtothenearestpowerof2CheckBox.ValueChangedFcn = @(~,~) app.updateNsSurface();


    % ── Valeurs par défaut onglet 2 ──
    app.Windspeedu10msEditField.Value   = 8;
    app.WinddirectiondegEditField.Value = 0;
    app.FetchmEditField_2.Value         = 500000;
    app.LengthXmEditField.Value         = 40;
    app.LengthYmEditField.Value         = 30;
    app.RadarfrequencyGHz130GHz2308cmEditField.Value = 1.30;
    app.SamplesperwavelengthEditField.Value           = 8;

    % ── Calculer n_s initial ──
    app.updateNsSurface();

    % ── Number of samples — lecture seule ──
    app.NumberofsamplesEditField_3.Editable = 'off';
    app.NumberofsamplesEditField_2.Editable = 'off';

    % ═══════════════════════════════════════════════════════════════
    %  ONGLET 2 : Clic sur surface et profil
    % ═══════════════════════════════════════════════════════════════
    app.GridLayout5_2.RowHeight = {'1x', 0, 130};
    app.SurfacestatisticsPanel.Layout.Row = 3;

    app.UIAxes5 = uiaxes(app.GridLayout5_2);
    app.UIAxes5.Layout.Row = 2;
    app.UIAxes5.Layout.Column = 1;
    app.UIAxes5.Visible = 'off';
    xlabel(app.UIAxes5, 'X (m)');
    ylabel(app.UIAxes5, 'Z (m)');
    app.UIAxes5.XGrid = 'on';
    app.UIAxes5.YGrid = 'on';
    app.UIAxes5.FontSize = 9;

    app.UIAxes_2.ButtonDownFcn = @(src,evt) app.onSurfaceClick(src, evt);
    app.UIAxes_2.HitTest = 'on';
    app.UIAxes_2.PickableParts = 'all';

   % ═══════════════════════════════════════════════════════════════
    %  ONGLET 4 : Sea profile radar simulator — champs non éditables
    % ═══════════════════════════════════════════════════════════════
    disabledBg = [0.94 0.94 0.94];  % gris très léger, quasi identique au fond par défaut  % bleu clair = "vient de l'onglet 3"

    % Champs numériques non éditables
    app.Windspeedu10msEditField_3.Editable         = 'off';
    app.FetchmEditField_4.Editable                  = 'off';
    app.LengthmEditField_2.Editable                 = 'off';
    app.RadarfrequencyGHz130GHz2308cmEditField_3.Editable = 'off';
    app.SamplesperwavelengthEditField_3.Editable    = 'off';
    app.NumberofsamplesEditField.Editable           = 'off';
    app.NprofilesEditField_2.Editable               = 'off';

    % Dropdowns non éditables
    app.IsotropicspectrumDropDown_3.Enable          = 'off';
    
    % Checkboxes non éditables
    app.UsesingleprecisionCheckBox_3.Enable         = 'off';
    app.Roundtothenearestpowerof2CheckBox_3.Enable  = 'off';
    app.YesCheckBox_2.Enable                        = 'off';

% Couleur fond bleu clair pour tous les champs bloqués (onglet 4)
app.Windspeedu10msEditField_3.BackgroundColor         = disabledBg;
app.FetchmEditField_4.BackgroundColor                  = disabledBg;
app.LengthmEditField_2.BackgroundColor                 = disabledBg;
app.RadarfrequencyGHz130GHz2308cmEditField_3.BackgroundColor = disabledBg;
app.SamplesperwavelengthEditField_3.BackgroundColor    = disabledBg;
app.NumberofsamplesEditField.BackgroundColor           = disabledBg;
app.NprofilesEditField_2.BackgroundColor               = disabledBg;
app.IsotropicspectrumDropDown_3.BackgroundColor        = disabledBg;

% Valeurs initiales à 0 (avant génération des profils)
app.Windspeedu10msEditField_3.Value                    = 0;
app.FetchmEditField_4.Value                            = 0;
app.LengthmEditField_2.Value                           = 0;
app.RadarfrequencyGHz130GHz2308cmEditField_3.Value     = 0;
app.SamplesperwavelengthEditField_3.Value              = 0;
app.NumberofsamplesEditField.Value                     = 0;
app.NprofilesEditField_2.Value                         = 0;

% Initialiser le dropdown spectre avec les mêmes items que l'onglet 3
app.IsotropicspectrumDropDown_3.Items = {'Apel', 'Elfouhaily', 'Donelan'};
app.IsotropicspectrumDropDown_3.Value = app.IsotropicspectrumDropDown_2.Value;

% Label fréquence dynamique onglet 4
app.RadarfrequencyGHz130GHz2308cmEditField_3.ValueChangedFcn = @(~,~) app.updateFreqLabelTab4();

% Boutons onglet 4
app.CalculateButton_2.ButtonPushedFcn  = @(~,~) app.CalculateButton_2Pushed([]);
app.ClearButton_2.ButtonPushedFcn      = @(~,~) app.ClearButton_2Pushed([]);
app.SelectprofileDropDown.ValueChangedFcn = @(~,~) app.onProfileSelectorChanged();

% % Diviser UIAxes_4 en 2 zones : profil (haut) + NRCS (bas)
% app.GridLayout5_4.RowHeight = {'1x', '1x'};
% app.UIAxes_4.Layout.Row = 1;
% 
% if isempty(app.NRCSAxes) || ~isvalid(app.NRCSAxes)
%     app.NRCSAxes = uiaxes(app.GridLayout5_4);
%     app.NRCSAxes.Layout.Row    = 2;
%     app.NRCSAxes.Layout.Column = 1;
%     xlabel(app.NRCSAxes, 'Scattering angle θ_{sca} (deg)');
%     ylabel(app.NRCSAxes, 'NRCS (dB)');
%     app.NRCSAxes.XGrid = 'on';
%     app.NRCSAxes.YGrid = 'on';
% end

% ── Onglet 4 : 3 lignes — barre de plage / profil(s) / NRCS ──
app.GridLayout5_4.RowHeight = {40, '1x', '1x'};
app.UIAxes_4.Layout.Row = 2;

% Barre de sélection de plage (ligne 1)
rangeBar4 = uigridlayout(app.GridLayout5_4);
rangeBar4.ColumnWidth = {'1x', 80, 25, 80, 30, '1x'};
rangeBar4.RowHeight = {'1x'};
rangeBar4.Padding = [8 4 8 4];
rangeBar4.ColumnSpacing = 4;
rangeBar4.Layout.Row = 1;
rangeBar4.Layout.Column = 1;
rangeBar4.BackgroundColor = [0.94 0.94 0.94];

lbl4a = uilabel(rangeBar4);
lbl4a.Text = 'Profile range :';
lbl4a.FontWeight = 'bold'; lbl4a.FontSize = 11;
lbl4a.HorizontalAlignment = 'right';
lbl4a.Layout.Row = 1; lbl4a.Layout.Column = 1;

app.BistaticRangeStart = uidropdown(rangeBar4);
app.BistaticRangeStart.Items = {'—'};
app.BistaticRangeStart.Value = '—';
app.BistaticRangeStart.Layout.Row = 1;
app.BistaticRangeStart.Layout.Column = 2;
app.BistaticRangeStart.ValueChangedFcn = @(~,~) app.onBistaticRangeChanged();

dash4 = uilabel(rangeBar4);
dash4.Text = '—'; dash4.HorizontalAlignment = 'center';
dash4.FontSize = 14; dash4.FontWeight = 'bold';
dash4.Layout.Row = 1; dash4.Layout.Column = 3;

app.BistaticRangeEnd = uidropdown(rangeBar4);
app.BistaticRangeEnd.Items = {'—'};
app.BistaticRangeEnd.Value = '—';
app.BistaticRangeEnd.Layout.Row = 1;
app.BistaticRangeEnd.Layout.Column = 4;
app.BistaticRangeEnd.ValueChangedFcn = @(~,~) app.onBistaticRangeChanged();

sp4b = uilabel(rangeBar4); sp4b.Text = '';
sp4b.Layout.Row = 1; sp4b.Layout.Column = 5;

info4 = uilabel(rangeBar4);
info4.Text = '← set range then click Calculate  (max 5 profiles)';
info4.FontAngle = 'italic'; info4.FontSize = 10;
info4.Layout.Row = 1; info4.Layout.Column = 6;

% Axes NRCS bistatique (ligne 3)
if isempty(app.NRCSAxes) || ~isvalid(app.NRCSAxes)
    app.NRCSAxes = uiaxes(app.GridLayout5_4);
    app.NRCSAxes.Layout.Row    = 3;
    app.NRCSAxes.Layout.Column = 1;
    xlabel(app.NRCSAxes, 'θ_{sca} (deg)');
    ylabel(app.NRCSAxes, 'NRCS (dB)');
    app.NRCSAxes.XGrid = 'on';
    app.NRCSAxes.YGrid = 'on';
    title(app.NRCSAxes, 'Bistatic NRCS — set range then click Calculate');
end

% ═══════════════════════════════════════════════════════════════
%  ONGLET 5 : Monostatic NRCS — champs non éditables
% ═══════════════════════════════════════════════════════════════

% Champs numériques non éditables
app.Windspeedu10msEditField_4.Editable         = 'off';
app.FetchmEditField_5.Editable                  = 'off';
app.LengthmEditField_3.Editable                 = 'off';
app.RadarfrequencyGHz130GHz2308cmEditField_4.Editable = 'off';
app.SamplesperwavelengthEditField_4.Editable    = 'off';
app.NumberofsamplesEditField_4.Editable         = 'off';
app.NprofilesEditField_3.Editable               = 'off';

% Dropdowns non éditables
app.IsotropicspectrumDropDown_4.Enable          = 'off';

% Checkboxes non éditables
app.UsesingleprecisionCheckBox_4.Enable         = 'off';
app.Roundtothenearestpowerof2CheckBox_4.Enable  = 'off';
app.YesCheckBox_3.Enable                        = 'off';

% Couleur fond bleu clair pour tous les champs bloqués (onglet 5)
app.Windspeedu10msEditField_4.BackgroundColor         = disabledBg;
app.FetchmEditField_5.BackgroundColor                  = disabledBg;
app.LengthmEditField_3.BackgroundColor                 = disabledBg;
app.RadarfrequencyGHz130GHz2308cmEditField_4.BackgroundColor = disabledBg;
app.SamplesperwavelengthEditField_4.BackgroundColor    = disabledBg;
app.NumberofsamplesEditField_4.BackgroundColor         = disabledBg;
app.NprofilesEditField_3.BackgroundColor               = disabledBg;
app.IsotropicspectrumDropDown_4.BackgroundColor        = disabledBg;

% Valeurs initiales à 0 (avant génération des profils)
app.Windspeedu10msEditField_4.Value                    = 0;
app.FetchmEditField_5.Value                            = 0;
app.LengthmEditField_3.Value                           = 0;
app.RadarfrequencyGHz130GHz2308cmEditField_4.Value     = 0;
app.SamplesperwavelengthEditField_4.Value              = 0;
app.NumberofsamplesEditField_4.Value                   = 0;
app.NprofilesEditField_3.Value                         = 0;

% Initialiser le dropdown spectre avec les mêmes items que l'onglet 3
app.IsotropicspectrumDropDown_4.Items = {'Apel', 'Elfouhaily', 'Donelan'};
app.IsotropicspectrumDropDown_4.Value = app.IsotropicspectrumDropDown_2.Value;

% Seuls champs éditables par l'utilisateur sur l'onglet 5
app.N_calcsNEditField.Editable = 'on';
app.MaximumincidenceangledegEditField.Editable = 'on';
app.N_calcsNEditField.Value    = 5;
app.MaximumincidenceangledegEditField.Value = 60;

% Boutons onglet 5
app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
app.ClearButton_3.ButtonPushedFcn     = @(~,~) cla(app.UIAxes_5);


% ═══════════════════════════════════════════════════════════════
%  ONGLET 5 : Diviser UIAxes_5 en 2 zones : profil (haut) + NRCS mono (bas)
% ═══════════════════════════════════════════════════════════════
% app.GridLayout5_5.RowHeight = {'1x', '1x'};
% app.UIAxes_5.Layout.Row = 1;
% 
% if isempty(app.MonoNRCSAxes) || ~isvalid(app.MonoNRCSAxes)
%     app.MonoNRCSAxes = uiaxes(app.GridLayout5_5);
%     app.MonoNRCSAxes.Layout.Row    = 2;
%     app.MonoNRCSAxes.Layout.Column = 1;
%     xlabel(app.MonoNRCSAxes, 'θ_{inc} (deg)');
%     ylabel(app.MonoNRCSAxes, 'NRCS_{mono} (dB)');
%     app.MonoNRCSAxes.XGrid = 'on';
%     app.MonoNRCSAxes.YGrid = 'on';
%     title(app.MonoNRCSAxes, 'Monostatic NRCS — click Calculate');
% end
% 
% app.SelectprofileDropDown_2.ValueChangedFcn = @(~,~) app.onProfileSelectorTab5Changed();


% ── Onglet 5 : 3 lignes — barre de plage / profil(s) / NRCS mono ──
app.GridLayout5_5.RowHeight = {40, '1x', '1x'};
app.UIAxes_5.Layout.Row = 2;

rangeBar5 = uigridlayout(app.GridLayout5_5);
rangeBar5.ColumnWidth = {'1x', 80, 25, 80, 30, '1x'};
rangeBar5.RowHeight = {'1x'};
rangeBar5.Padding = [8 4 8 4];
rangeBar5.ColumnSpacing = 4;
rangeBar5.Layout.Row = 1;
rangeBar5.Layout.Column = 1;
rangeBar5.BackgroundColor = [0.94 0.94 0.94];

lbl5a = uilabel(rangeBar5);
lbl5a.Text = 'Profile range :';
lbl5a.FontWeight = 'bold'; lbl5a.FontSize = 11;
lbl5a.HorizontalAlignment = 'right';
lbl5a.Layout.Row = 1; lbl5a.Layout.Column = 1;

app.MonoRangeStart = uidropdown(rangeBar5);
app.MonoRangeStart.Items = {'—'};
app.MonoRangeStart.Value = '—';
app.MonoRangeStart.Layout.Row = 1;
app.MonoRangeStart.Layout.Column = 2;
app.MonoRangeStart.ValueChangedFcn = @(~,~) app.onMonoRangeChanged();

dash5 = uilabel(rangeBar5);
dash5.Text = '—'; dash5.HorizontalAlignment = 'center';
dash5.FontSize = 14; dash5.FontWeight = 'bold';
dash5.Layout.Row = 1; dash5.Layout.Column = 3;

app.MonoRangeEnd = uidropdown(rangeBar5);
app.MonoRangeEnd.Items = {'—'};
app.MonoRangeEnd.Value = '—';
app.MonoRangeEnd.Layout.Row = 1;
app.MonoRangeEnd.Layout.Column = 4;
app.MonoRangeEnd.ValueChangedFcn = @(~,~) app.onMonoRangeChanged();

sp5b = uilabel(rangeBar5); sp5b.Text = '';
sp5b.Layout.Row = 1; sp5b.Layout.Column = 5;

info5 = uilabel(rangeBar5);
info5.Text = '← set range then click Calculate  (max 5 profiles)';
info5.FontAngle = 'italic'; info5.FontSize = 10;
info5.Layout.Row = 1; info5.Layout.Column = 6;

if isempty(app.MonoNRCSAxes) || ~isvalid(app.MonoNRCSAxes)
    app.MonoNRCSAxes = uiaxes(app.GridLayout5_5);
    app.MonoNRCSAxes.Layout.Row    = 3;
    app.MonoNRCSAxes.Layout.Column = 1;
    xlabel(app.MonoNRCSAxes, 'θ_{inc} (deg)');
    ylabel(app.MonoNRCSAxes, 'σ⁰_{mono} (dB)');
    app.MonoNRCSAxes.XGrid = 'on';
    app.MonoNRCSAxes.YGrid = 'on';
    title(app.MonoNRCSAxes, 'Monostatic NRCS — set range then click Calculate');
end

app.SelectprofileDropDown_2.ValueChangedFcn = @(~,~) app.onProfileSelectorTab5Changed();
  
    % % ═══════════════════════════════════════════════════════════════
    % %  SPECTRES
    % % ═══════════════════════════════════════════════════════════════
    % app.IsotropicspectrumDropDown.ValueChangedFcn = @(~,~) app.autoRegenerateSurface();
    % app.AngularspectrumDropDown.ValueChangedFcn   = @(~,~) app.autoRegenerateSurface();
    % app.IsotropicspectrumDropDown_2.ValueChangedFcn = @(~,~) app.autoRegenerateProfiles();
    % 

      % ═══════════════════════════════════════════════════════════════
    %  SPECTRES - MISE À JOUR AUTOMATIQUE (ONGLE 2)
    % ═══════════════════════════════════════════════════════════════
    
    % Quand l'utilisateur change le spectre isotrope → recalcul automatique
    app.IsotropicspectrumDropDown.ValueChangedFcn  = @(~,~) app.autoRegenerateSurface();
    
    % Quand l'utilisateur change le spectre angulaire → recalcul automatique
    app.AngularspectrumDropDown.ValueChangedFcn    = @(~,~) app.autoRegenerateSurface();
    
    % Quand l'utilisateur change le spectre isotrope (onglet 3) → recalcul
    app.IsotropicspectrumDropDown_2.ValueChangedFcn = @(~,~) app.autoRegenerateProfiles();
    
    % Quand l'utilisateur change la fréquence (onglet 2) → mettre à jour l'affichage
    app.RadarfrequencyGHz130GHz2308cmEditField.ValueChangedFcn = @(~,~) app.updateFreqLabelSurface();
    
    % Quand l'utilisateur change la fréquence (onglet 3) → mettre à jour l'affichage ET n_s
    app.RadarfrequencyGHz130GHz2308cmEditField_2.ValueChangedFcn = @(~,~) app.updateFreqLabelProfile();
    
    % Quand l'utilisateur change la fréquence (onglet 4) → mettre à jour l'affichage
    app.RadarfrequencyGHz130GHz2308cmEditField_3.ValueChangedFcn = @(~,~) app.updateFreqLabelTab4();


    % Mettre à jour les labels au démarrage
    app.updateFreqLabelSurface();
    app.updateFreqLabelProfile();
    app.updateFreqLabelTab4();
    app.RadarfrequencyGHz130GHz2308cmEditField_4.ValueChangedFcn = @(~,~) app.updateFreqLabelTab5();
    app.updateFreqLabelTab5();
    
    % ═══════════════════════════════════════════════════════════════
    %  MISE À JOUR AUTOMATIQUE DE n_s (ONGLET 3)
    % ═══════════════════════════════════════════════════════════════
    app.LengthmEditField.ValueChangedFcn = @(~,~) app.updateNs();
    app.SamplesperwavelengthEditField_2.ValueChangedFcn = @(~,~) app.updateNs();
    app.Roundtothenearestpowerof2CheckBox.ValueChangedFcn = @(~,~) app.updateNsSurface();
    
    % ═══════════════════════════════════════════════════════════════
    %  MISE À JOUR AUTOMATIQUE DE n_s (ONGLET 2)
    % ═══════════════════════════════════════════════════════════════
    app.LengthXmEditField.ValueChangedFcn = @(~,~) app.updateNsSurface();
    app.LengthYmEditField.ValueChangedFcn = @(~,~) app.updateNsSurface();
    app.SamplesperwavelengthEditField.ValueChangedFcn = @(~,~) app.updateNsSurface();
    app.Roundtothenearestpowerof2CheckBox.ValueChangedFcn = @(~,~) app.updateNsSurface();
    
    % Calcul initial
    app.updateNs();
    app.updateNsSurface();
    app.updateValidRangesNote();
     % Configurer validation des champs
    app.setupValidation();
    
    % Configurer validation des angles (modulo)
    app.setupAngleValidation();
    
    % ═══════════════════════════════════════════════════════════════
    %  AFFICHAGE DU SPECTRE AU DÉMARRAGE (comme dans l'ancienne app)
    % ═══════════════════════════════════════════════════════════════
    %app.plotSpectra();
    %app.plotSpectraProfile();

    disp('=== Application initialisée avec succès ===');
        
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            u10=app.Windspeedu10msEditField_5.Value; windDir=app.Anglerelativetowinddirection0360degEditField.Value;
            pol=app.PolarizationDropDown.Value; model=app.EMmodelDropDown.Value;
            freq=app.FrequencyGHzEditField.Value; th_user=app.IncidenceangledegEditField.Value;
            Nrcs_VV=[]; Nrcs_HH=[]; Nrcs_VH=[]; Th_i_d=[];


            % ── Validation : pas de valeurs négatives ──
fields = {app.FrequencyGHzEditField, app.Windspeedu10msEditField_5, ...
          app.IncidenceangledegEditField};
names  = {'Frequency (GHz)', 'Wind speed u10', 'Incidence angle'};
if ismember(model, {'TSM','GO1sh','SPM1'})
    fields{end+1} = app.FetchmEditField;
    names{end+1}  = 'Fetch';
    fields{end+1} = app.k0kcEditField;
    names{end+1}  = 'K0/Kc';
end
for vi = 1:numel(fields)
    if ~app.validateField(fields{vi}, names{vi}); return; end
end


            try
                switch model
                    case 'Isoguchi'; Th_i_d=(17:0.5:43)'; [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_EmpExpSea_Lband_Isoguchi(Th_i_d,u10,windDir); Nrcs_VH=NaN(size(Th_i_d));
                    case 'Meissner'; Th_i_d=(24.36:0.5:49.29)'; [Nrcs_VV,Nrcs_HH,Nrcs_VH]=f_Nrcs_3dMono_EmpExpSea_Lband_Meissner_v2(Th_i_d,u10,windDir,'linear');
                    case 'Quilfen'
                        if u10>19.9; uialert(app.UIFigure,'Quilfen : u10 doit être < 20 m/s','Paramètre invalide','Icon','warning'); return; end
                        Th_i_d=(18:0.5:60); [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_EmpExpSea_Cband_Quilfen(Th_i_d,u10,windDir); Nrcs_VV=Nrcs_VV(:); Nrcs_HH=Nrcs_HH(:); Th_i_d=Th_i_d(:); Nrcs_VH=NaN(size(Th_i_d));
                    case 'CMOD2-I3'; Th_i_d=(18:0.5:57); [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_EmpExpSea_Cband_Cmod2I3(Th_i_d,u10,windDir); Nrcs_VV=Nrcs_VV(:); Nrcs_HH=Nrcs_HH(:); Th_i_d=Th_i_d(:); Nrcs_VH=NaN(size(Th_i_d));
                    case 'CMOD5';  Th_i_d=(18:0.5:57)'; [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_EmpExpSea_Cband_Cmod5(Th_i_d,u10,windDir);  Nrcs_VV=Nrcs_VV(:); Nrcs_HH=Nrcs_HH(:); Nrcs_VH=NaN(size(Th_i_d));
                    case 'CMOD5N'; Th_i_d=(18:0.5:57)'; [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_EmpExpSea_Cband_Cmod5N(Th_i_d,u10,windDir); Nrcs_VV=Nrcs_VV(:); Nrcs_HH=Nrcs_HH(:); Nrcs_VH=NaN(size(Th_i_d));
                    case 'XMOD1R'; Th_i_d=(20:0.5:60)'; [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_EmpExpSea_Xband_Xmod1R(Th_i_d,u10,windDir); Nrcs_VV=Nrcs_VV(:); Nrcs_HH=Nrcs_HH(:); Nrcs_VH=NaN(size(Th_i_d));
                    case 'XMOD2N'; Th_i_d=(18:0.5:46)'; [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_EmpExpSea_Xband_Xmod2N(Th_i_d,u10,windDir); Nrcs_VV=Nrcs_VV(:); Nrcs_HH=Nrcs_HH(:); Nrcs_VH=NaN(size(Th_i_d));
                    case {'NRL','TSC','GIT','HYBRID','RRE','SITTROP'}
                        c_ms=3e8; lambda_m=c_ms/(freq*1e9); seaState=max(0,min(7,round((u10/3.16)^1.25)));
                        switch model
                            case 'NRL';     if u10>13.2;u10=13.2;seaState=6;end; Chi_d=(0.1:0.5:60)';
                            case 'TSC';     if u10>11.4;u10=11.4;seaState=5;end; Chi_d=(0.1:0.5:89.9)';
                            case 'GIT';     u10=max(3.2,min(13.2,u10));seaState=max(1,min(6,round((u10/3.16)^1.25)));Chi_d=(0.1:0.5:9.9)';
                            case 'HYBRID';  if u10>11.4;u10=11.4;seaState=5;end;Chi_d=(0:0.5:30)';
                            case 'RRE';     u10=max(3.2,min(13.2,u10));seaState=max(1,min(6,round((u10/3.16)^1.25)));Chi_d=(0.1:0.5:9.9)';
                            case 'SITTROP'; if u10>14.9;u10=14.9;end;Chi_d=(0.2:0.1:9.8)';
                        end
                        Th_i_d=90-Chi_d; if strcmp(pol,'VV');polModel='V';else;polModel='H';end
                        switch model
                            case 'NRL';     Nrcs_dB_raw=f_Nrcs_3dMonoGrazing_NRL_2012(Chi_d,lambda_m,seaState,windDir,polModel,c_ms);
                            case 'TSC';     Nrcs_dB_raw=f_Nrcs_3dMonoGrazing_TSC(Chi_d,lambda_m,seaState,windDir,polModel);
                            case 'GIT';     Nrcs_dB_raw=f_Nrcs_3dMonoGrazing_GIT(Chi_d,lambda_m,seaState,windDir,polModel);
                            case 'HYBRID';  Nrcs_dB_raw=f_Nrcs_3dMonoGrazing_HYB2(Chi_d,lambda_m,seaState,windDir,polModel);
                            case 'RRE';     Nrcs_dB_raw=f_Nrcs_3dMonoGrazing_RRE(Chi_d,lambda_m,seaState,windDir,polModel);
                            case 'SITTROP'; Nrcs_dB_raw=f_Nrcs_3dMonoGrazing_SIT(Chi_d,lambda_m,seaState,windDir,polModel);
                        end
                        Nrcs_dB_raw=Nrcs_dB_raw(:); Nrcs_VV=10.^(Nrcs_dB_raw/10); Nrcs_HH=Nrcs_VV; Nrcs_VH=NaN(size(Th_i_d));
                    case {'TSM','GO1sh','SPM1'}
                        sst_C=15;sss_ppt=35;F_Hz=freq*1e9;fetch_m=app.FetchmEditField.Value;if fetch_m<=0;fetch_m=500000;end
                        k0SurKc=app.k0kcEditField.Value;if k0SurKc<=0;k0SurKc=4;end
                        [er2,~,K_EM,Kc]=f_Sea_ErOmg(F_Hz,sst_C,sss_ppt,u10,fetch_m,k0SurKc);
                        switch model
                            case 'TSM'; Th_i_d=(0.1:0.5:80)'; [Nrcs_VV_raw,Nrcs_HH_raw,Nrcs_VH_raw,~,~,AddVV,AddHH]=f_Nrcs_3dMono_StatisticSea_TSM_Elfo_Gauss_Fetch_v2(Th_i_d,windDir,er2,u10,fetch_m,K_EM,Kc); Nrcs_VV=Nrcs_VV_raw(:)+AddVV(:);Nrcs_HH=Nrcs_HH_raw(:)+AddHH(:);Nrcs_VH=Nrcs_VH_raw(:);
                            case 'GO1sh'; Th_i_d=(0.1:0.5:50)'; [Nrcs_VV,Nrcs_HH,~,~,~]=f_Nrcs_3dMono_StatisticSea_GO1sh_Elfo_Gauss_Fetch_v1(Th_i_d,windDir,er2,u10,fetch_m,Kc); Nrcs_VV=Nrcs_VV(:);Nrcs_HH=Nrcs_HH(:);Nrcs_VH=NaN(size(Th_i_d));
                            case 'SPM1';  Th_i_d=(2:0.5:80)';   [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_StatisticSea_SPM1_Elfo_Gauss_Fetch_v1(Th_i_d,windDir,er2,u10,fetch_m,K_EM); Nrcs_VV=Nrcs_VV(:);Nrcs_HH=Nrcs_HH(:);Nrcs_VH=NaN(size(Th_i_d));
                        end
                    case 'Wentz84'; Th_i_d=(0:1:60)';   [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_EmpExpSea_Kuband_Wentz84(Th_i_d,u10,windDir); Nrcs_VH=NaN(size(Th_i_d));
                    case 'Wentz99'; Th_i_d=(15:0.5:65)'; [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_EmpExpSea_Kuband_Wentz99(Th_i_d,u10,windDir); Nrcs_VH=NaN(size(Th_i_d));
                    case 'Masuko Ka'; Th_i_d=(30:0.5:60)'; [u19,~,~,~]=f_Sea_WindSpeedHeightConversion_v2(u10,19.5,500000); [Nrcs_VV,Nrcs_HH]=f_Nrcs_3dMono_EmpExpSea_Kaband_Masuko(Th_i_d,u19,windDir); Nrcs_VH=NaN(size(Th_i_d));
                end
            catch ME; uialert(app.UIFigure,['Erreur: ' ME.message],'Erreur calcul','Icon','error'); return; end
            switch pol; case 'VV'; Nrcs_lin=Nrcs_VV; case 'HH'; Nrcs_lin=Nrcs_HH; case 'VH'; Nrcs_lin=Nrcs_VH; otherwise; Nrcs_lin=Nrcs_VV; end
            Nrcs_lin(Nrcs_lin<=0)=NaN; Nrcs_dB=10*log10(Nrcs_lin);
            entry.Th_i_d=Th_i_d; entry.Nrcs_dB=Nrcs_dB; entry.Nrcs_lin=Nrcs_lin; entry.model=model; entry.pol=pol; entry.u10=u10; entry.windDir=windDir; entry.freq=freq;
            entry.label=sprintf('%s | %s | u10=%.1f | φ=%.0f°',model,pol,u10,windDir);
            app.SessionData{end+1}=entry;
            if length(app.SessionData)>1; cla(app.UIAxes); end
            hold(app.UIAxes,'on');
            plot(app.UIAxes,Th_i_d,Nrcs_dB,'LineWidth',2,'DisplayName',entry.label);
            xlabel(app.UIAxes,'Angle d''incidence (deg)');
            ylabel(app.UIAxes,'NRCS (dB)');
            title(app.UIAxes,sprintf('NRCS — %s — %s — %.2f GHz',model,pol,freq));
            grid(app.UIAxes,'on');
            legend(app.UIAxes,'show','Location','northeast');
            hold(app.UIAxes,'off');

           % À la fin de CalculateButtonPushed, après avoir calculé Nrcs_dB et Th_i_d
    valid = ~isnan(Nrcs_dB) & isfinite(Nrcs_dB);
   if sum(valid) < 2
    app.Label_9.Text = 'Pas assez de points valides.';
    return;
end

theta_valid = Th_i_d(valid);
nrcs_valid = Nrcs_dB(valid);

% Vérifier si l'angle demandé est dans la plage valide
if th_user >= min(theta_valid) && th_user <= max(theta_valid)
    % Trouver l'index le plus proche (comme dans votre exemple)
    [~, idx] = min(abs(theta_valid - th_user));
    val_dB = nrcs_valid(idx);
    app.Label_9.Text = sprintf('NRCS: %.2f dB (for %.1f° incidence angle)', val_dB, th_user);
else
    app.Label_9.Text = sprintf('Angle %.1f° hors plage [%.1f°–%.1f°]', th_user, min(theta_valid), max(theta_valid));
end
        end

        % Button pushed function: OverlayButton
        function OverlayButtonPushed(app, event)
             if isempty(app.SessionData); uialert(app.UIFigure,'Aucun calcul en session.','Overlay','Icon','warning'); return; end
            lineStyles={'-','--',':','-.'}; lineWidths=[2,2,2.5,2.5]; markers={'none','none','none','o'};
            colors=lines(length(app.SessionData));
            cla(app.UIAxes); hold(app.UIAxes,'on'); app.UIAxes.ButtonDownFcn=@(s,e)app.clearTooltip(s,e);
            all_x_min=Inf;all_x_max=-Inf;all_y_min=Inf;all_y_max=-Inf;
            for k=1:length(app.SessionData)
                entry=app.SessionData{k}; ls=lineStyles{mod(k-1,4)+1}; lw=lineWidths(mod(k-1,4)+1); mrk=markers{mod(k-1,4)+1};
                plot(app.UIAxes,entry.Th_i_d,entry.Nrcs_dB,'LineStyle',ls,'LineWidth',lw,'Marker',mrk,'MarkerSize',4,'Color',colors(k,:),'DisplayName',entry.label,'Tag',num2str(k),'ButtonDownFcn',@(s,e)app.onCurveClick(s,e));
                valid=~isnan(entry.Nrcs_dB);
                if any(valid); all_x_min=min(all_x_min,min(entry.Th_i_d(valid)));all_x_max=max(all_x_max,max(entry.Th_i_d(valid)));all_y_min=min(all_y_min,min(entry.Nrcs_dB(valid)));all_y_max=max(all_y_max,max(entry.Nrcs_dB(valid))); end
            end
            if ~isinf(all_x_min); mx=(all_x_max-all_x_min)*0.05;my=(all_y_max-all_y_min)*0.1;xlim(app.UIAxes,[all_x_min-mx,all_x_max+mx]);ylim(app.UIAxes,[all_y_min-my,all_y_max+my]); end
            xlabel(app.UIAxes,'Incidence Angle (deg)'); ylabel(app.UIAxes,'NRCS (dB)');
            title(app.UIAxes,sprintf('NRCS Overlay (%d courbes)',length(app.SessionData)));
            grid(app.UIAxes,'on'); legend(app.UIAxes,'show','Location','northeast'); hold(app.UIAxes,'off');
      
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes); legend(app.UIAxes,'off');
            app.Label_9.Text=''; app.SessionData={};
      
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

    if ~isfield(app.LastSurface, 'HH_XY')
        uialert(app.UIFigure, 'No surface to export.', 'Export', 'Icon', 'warning');
        return;
    end
    [file, path] = uiputfile({ ...
        '*.png', 'PNG image (*.png)'; ...
        '*.csv', 'CSV data (*.csv)'; ...
        '*.mat', 'MATLAB data (*.mat)'}, 'Export Sea Surface');
    if isequal(file, 0); return; end
    [~, ~, ext] = fileparts(file);
    fullpath = fullfile(path, file);

    HH_XY   = app.LastSurface.HH_XY;
    X        = app.LastSurface.X;
    Y        = app.LastSurface.Y;
    u10      = app.LastSurface.windSpeed;
    fetch_m  = app.LastSurface.fetch_m;
    freq_GHz = app.LastSurface.freq_GHz;
    [nx, ny] = size(HH_XY);

    switch lower(ext)

        case '.png'
            exportgraphics(app.UIAxes_2, fullpath, 'Resolution', 150);
            uialert(app.UIFigure, 'Surface image exported as PNG.', 'Export OK', 'Icon', 'success');

        case '.csv'
            fid = fopen(fullpath, 'w');
            % Métadonnées
            fprintf(fid, 'Sea Surface Export\n');
            fprintf(fid, 'Wind speed u10 (m/s),%.4f\n', u10);
            fprintf(fid, 'Fetch (m),%.1f\n',            fetch_m);
            fprintf(fid, 'Radar frequency (GHz),%.4f\n', freq_GHz);
            fprintf(fid, 'Grid size,%dx%d\n',            nx, ny);
            fprintf(fid, 'X range (m),%.4f,%.4f\n',     min(X), max(X));
            fprintf(fid, 'Y range (m),%.4f,%.4f\n',     min(Y), max(Y));
            fprintf(fid, '\n');
            % Matrice HH_XY avec en-têtes X et Y
            % Première ligne : valeurs de Y
            fprintf(fid, 'X\\Y');
            fprintf(fid, ',%.4f', Y);
            fprintf(fid, '\n');
            % Lignes : valeur de X puis les hauteurs Z
            for i = 1:nx
                fprintf(fid, '%.4f', X(i));
                fprintf(fid, ',%.6f', HH_XY(i, :));
                fprintf(fid, '\n');
            end
            fclose(fid);
            uialert(app.UIFigure, ...
                sprintf('Surface %dx%d exported to CSV.', nx, ny), ...
                'Export OK', 'Icon', 'success');

        case '.mat'
            % Structure avec métadonnées
            Surface.HH_XY          = HH_XY;
            Surface.X_m            = X;
            Surface.Y_m            = Y;
            Surface.wind_speed_ms  = u10;
            Surface.fetch_m        = fetch_m;
            Surface.frequency_GHz  = freq_GHz;
            Surface.grid_size      = [nx, ny];
            Surface.Zmin_m         = min(HH_XY(:));
            Surface.Zmax_m         = max(HH_XY(:));
            Surface.Zmean_m        = mean(HH_XY(:));
            Surface.Zstd_m         = std(HH_XY(:));
            save(fullpath, 'Surface');
            uialert(app.UIFigure, ...
                'Surface exported to .mat with metadata.', ...
                'Export OK', 'Icon', 'success');
    end
        end

        % Button pushed function: GenerateSurfaceButton
        function GenerateSurfaceButtonPushed(app, event)
    u10 = app.Windspeedu10msEditField.Value; 
    phiw_d = app.WinddirectiondegEditField.Value;
    fetch_m = app.FetchmEditField_2.Value; 
    lx = app.LengthXmEditField.Value; 
    ly = app.LengthYmEditField.Value;
    freq = app.RadarfrequencyGHz130GHz2308cmEditField.Value; 
    nlb = app.SamplesperwavelengthEditField.Value;
    prec = 2 - app.UsesingleprecisionCheckBox.Value;


    % ── Validation : pas de valeurs négatives ──
checkFields = {app.Windspeedu10msEditField, app.FetchmEditField_2, ...
               app.LengthXmEditField, app.LengthYmEditField, ...
               app.RadarfrequencyGHz130GHz2308cmEditField, ...
               app.SamplesperwavelengthEditField};
checkNames  = {'Wind speed u10', 'Fetch', 'Length X', 'Length Y', ...
               'Radar frequency', 'Samples/wavelength'};
for vi = 1:numel(checkFields)
    if ~app.validateField(checkFields{vi}, checkNames{vi}); return; end
end
    
    if u10 < 1 || u10 > 10
        uialert(app.UIFigure, 'Wind speed u10 must be between 1 and 10 m/s.', ...
    'Invalid parameter', 'Icon', 'warning');
        return; 
    end
    if fetch_m <= 0; fetch_m = 500e3; end
    
    c_ms = 3e8; 
    lb0_m = c_ms / (freq * 1e9); 
    dxy_m = lb0_m / nlb;
    n_x_raw = round(lx / dxy_m / 2) * 2; 
    n_y_raw = round(ly / dxy_m / 2) * 2;
    
    if app.Roundtothenearestpowerof2CheckBox.Value
        n_x = 2^round(log2(max(1, n_x_raw)));
        n_y = 2^round(log2(max(1, n_y_raw)));
    else
        n_x = 2^nextpow2(n_x_raw);
        n_y = 2^nextpow2(n_y_raw);
    end
    
    d = uiprogressdlg(app.UIFigure, 'Title', 'Génération', ...
        'Message', 'Generation in progress...', 'Indeterminate', 'on'); 
    drawnow;
    
    vars_avant = whos; 
    ram_avant_W = sum([vars_avant.bytes]) / 1024^2;
    
    try
        tic; 
        % Lire le spectre choisi dans l'interface
isoMdl = strtrim(app.IsotropicspectrumDropDown.Value);
switch isoMdl
    case 'Apel';       isoIdx = 1;
    case 'Elfouhaily'; isoIdx = 2;
    case 'Donelan';    isoIdx = 3;
    otherwise;         isoIdx = 1;
end
[HH_XY, X, Y] = f_GeneSurfMer3D_Fetch_Elfo_v3(u10, phiw_d, lx, ly, n_x, n_y, isoIdx, prec, fetch_m); 
        temps_gen = toc; 
        close(d);
    catch ME
        close(d); 
        uialert(app.UIFigure, ['Error: ' ME.message], 'Generation error', 'Icon', 'error'); 

        return; 
    end
    
    vars_apres = whos; 
    ram_apres_W = sum([vars_apres.bytes]) / 1024^2; 
    delta_RAM_MB = ram_apres_W - ram_avant_W;
    bytes_per_el = 8 - 4 * isa(HH_XY, 'single'); 
    mem_Mo = (numel(HH_XY) + numel(X) + numel(Y)) * bytes_per_el / 1e6;
    
    % Stats
    app.ZminLabel.Text = 'Zmin:';
    app.ZmaxLabel.Text = 'Zmax:';
    app.ZmeanLabel.Text = 'Zmean:';
    app.ZstdLabel.Text = 'Zstd:';
    app.Label.Text = sprintf('%.2f m', min(HH_XY(:)));
    app.Label_7.Text = sprintf('%.2f m', mean(HH_XY(:)));
    app.Label_6.Text = sprintf('%.2f m', max(HH_XY(:)));

% Afficher Zstd avec valeurs théoriques
app.updateZstdWithTheory(HH_XY, u10, fetch_m, true);
    
    % ── Tracé de la surface ──
    cla(app.UIAxes_2);
    h = surf(app.UIAxes_2, Y, X, HH_XY);
    shading(app.UIAxes_2, 'interp');
    colormap(app.UIAxes_2, 'jet');
    view(app.UIAxes_2, -45, 30);
    axis(app.UIAxes_2, 'tight');
    app.UIAxes_2.DataAspectRatio = [1 1 0.2];
    xlabel(app.UIAxes_2, 'Y (m)');
    ylabel(app.UIAxes_2, 'X (m)');
    zlabel(app.UIAxes_2, 'Z (m)');
    app.UIAxes_2.Box   = 'on';
    app.UIAxes_2.Color = [0.95 0.95 0.95];
    app.UIAxes_2.ZAxis.Visible = 'on';
    cb = colorbar(app.UIAxes_2);
    cb.Label.String = 'Height (m)';
    cb.FontSize = 9;

    % ── Activer le clic AVANT rotate3d ──
    h.HitTest       = 'on';
    h.PickableParts = 'all';
    h.ButtonDownFcn = @(s,e) app.onSurfaceClick(s, e);

    % ── Activer la rotation APRÈS avoir connecté le clic ──
    app.UIAxes_2.HitTest = 'on';
    app.UIAxes_2.PickableParts = 'all';
    app.UIAxes_2.ButtonDownFcn = [];  % Pas de callback sur l'axe, seulement sur la surface

    % Stocker les données
    app.LastSurface.HH_XY    = HH_XY;
    app.LastSurface.X        = X;
    app.LastSurface.Y        = Y;
    app.LastSurface.windSpeed = u10;
    app.LastSurface.fetch_m  = fetch_m;
    app.LastSurface.freq_GHz = freq;
    app.LastSurface.nx       = n_x;
    app.LastSurface.ny       = n_y;
    app.LastSurface.isoMdl = isoMdl;

    % ── Afficher le résultat dans le titre (pas de popup qui efface la surface) ──
    title(app.UIAxes_2, sprintf('Surface %dx%d | %.2f s | %.1f Mo', ...
        n_x, n_y, temps_gen, mem_Mo));
    drawnow;
   
        end

        % Button pushed function: ClearSurfaceButton
        function ClearSurfaceButtonPushed(app, event)
           cla(app.UIAxes_2);
    if ~isempty(app.UIAxes5) && isvalid(app.UIAxes5)
        app.UIAxes5.Visible = 'off';
        cla(app.UIAxes5);
    end
    app.GridLayout5_2.RowHeight = {'1x', 0, 130};
    app.Label.Text = '—';
    app.Label_7.Text = '—';
    app.Label_6.Text = '—';
    app.Label_8.Text = '—';
    app.ZminLabel.Text = 'Zmin';
    app.ZmaxLabel.Text = 'Zmax';
    app.ZmeanLabel.Text = 'Zmean';
    app.ZstdLabel.Text = 'Zstd';
    app.LastSurface = struct();
    title(app.UIAxes_2, '');
        end

        % Button pushed function: ExportSurfaceButton
        function ExportSurfaceButtonPushed(app, event)
         if ~isfield(app.LastSurface,'HH_XY')
        uialert(app.UIFigure,'No surface to export.','Export','Icon','warning');
        return;
    end
    [file,path] = uiputfile({ ...
        '*.png','PNG image (*.png)'; ...
        '*.mat','MATLAB file (*.mat)'; ...
        '*.csv','CSV file (*.csv)'; ...
        '*.*','Export ALL (enter a base name)'}, ...
        'Export Sea Surface');
    if isequal(file,0); return; end
    [~,baseName,ext] = fileparts(file);
    folder = path;
    HH_XY = app.LastSurface.HH_XY;
    X = app.LastSurface.X;
    Y = app.LastSurface.Y;

    switch lower(ext)
        case '.png'
            exportgraphics(app.UIAxes_2, fullfile(folder,[baseName,'.png']), 'Resolution',150);
            uialert(app.UIFigure,'PNG exported.','Export OK','Icon','success');
        case '.mat'
            save(fullfile(folder,[baseName,'.mat']),'HH_XY','X','Y');
            uialert(app.UIFigure,'MAT file exported.','Export OK','Icon','success');
        case '.csv'
            writematrix(HH_XY, fullfile(folder,[baseName,'.csv']));
            uialert(app.UIFigure,'CSV exported.','Export OK','Icon','success');
        otherwise
            % Export ALL : .mat + .csv + .png
            save(fullfile(folder,[baseName,'.mat']),'HH_XY','X','Y');
            writematrix(HH_XY, fullfile(folder,[baseName,'.csv']));
            exportgraphics(app.UIAxes_2, fullfile(folder,[baseName,'.png']), 'Resolution',150);
            uialert(app.UIFigure, ...
                sprintf('3 files exported:\n%s.mat\n%s.csv\n%s.png', baseName,baseName,baseName), ...
                'Export ALL OK','Icon','success');
    end
        end

        % Button pushed function: GenerateProfilesButton
        function GenerateProfilesButtonPushed(app, event)

    % ⭐ INITIALISER L'UI SI NÉCESSAIRE ⭐
    if ~app.isUIInitialized
        app.initializeUIDropdowns();
    end
    
    u10     = app.Windspeedu10msEditField_2.Value;
    fetch_m = app.FetchmEditField_3.Value;
    l_s     = app.LengthmEditField.Value;
    freq    = app.RadarfrequencyGHz130GHz2308cmEditField_2.Value;
    nlb     = app.SamplesperwavelengthEditField_2.Value;
    Nprof   = app.NprofilesEditField.Value;
    prec    = 2;

    % ── Validation : pas de valeurs négatives ──
    checkFields = {app.Windspeedu10msEditField_2, app.FetchmEditField_3, ...
                   app.LengthmEditField, app.RadarfrequencyGHz130GHz2308cmEditField_2, ...
                   app.SamplesperwavelengthEditField_2, app.NprofilesEditField};
    checkNames  = {'Wind speed u10', 'Fetch', 'Length', ...
                   'Radar frequency', 'Samples/wavelength', 'Nprofiles'};
    for vi = 1:numel(checkFields)
        if ~app.validateField(checkFields{vi}, checkNames{vi}); return; end
    end

    if u10 <= 0 || l_s <= 0 || fetch_m <= 0 || freq <= 0 || nlb < 1 || Nprof < 1
        uialert(app.UIFigure, 'Invalid parameters.', 'Invalid parameter', 'Icon', 'warning');
        return;
    end

    c_ms     = 3e8;
    lambda_m = c_ms / (freq * 1e9);
    n_s      = round(l_s / lambda_m) * nlb;
    n_s      = 2^nextpow2(n_s);

    d = uiprogressdlg(app.UIFigure, ...
        'Title',         'Génération des profils', ...
        'Message',       sprintf('Generating %d profiles (n_s = %d)...', Nprof, n_s), ...
        'Indeterminate', 'on');
    drawnow;

    % RAM avant calcul
    vars_avant  = whos;
    ram_avant_W = sum([vars_avant.bytes]) / 1024^2;

    % ⭐⭐⭐ OPTIMISATION PARFOR - Extraire toutes les variables ⭐⭐⭐
    % Ceci est CRITIQUE : évite de sérialiser l'objet 'app' tout entier
    u10_loc     = u10;
    l_s_loc     = l_s;
    n_s_loc     = n_s;
    fetch_loc   = fetch_m;
    prec_loc    = prec;
    Nprof_loc   = Nprof;
    
    % Vérifier si le pool parallèle existe déjà
    poolExists = ~isempty(gcp('nocreate'));
    
    % ⭐ SEUIL ADAPTATIF : parfor utile seulement pour Nprof >= 5 ET n_s >= 2000
    useParallel = app.YesCheckBox.Value && (Nprof_loc >= 5) && (n_s_loc >= 2000) && poolExists;
    
    % Pré-allocation
    H_all = cell(1, Nprof_loc);
    X_all = cell(1, Nprof_loc);

    try
        tic;
        
        if useParallel
            % ── Mode PARALLÈLE OPTIMISÉ ──
            parfor k = 1:Nprof_loc
                [H_k, X_h_k, ~, ~, ~, ~, ~] = f_GeneSurfMer2D_ElfoGauss_Fetch_v2b( ...
                    u10_loc, l_s_loc, n_s_loc, double(k), fetch_loc, prec_loc);
                H_all{k} = H_k(:);
                X_all{k} = X_h_k(:);
            end
        else
            % ── Mode SÉQUENTIEL ──
            for k = 1:Nprof_loc
                [H_k, X_h_k, ~, ~, ~, ~, ~] = f_GeneSurfMer2D_ElfoGauss_Fetch_v2b( ...
                    u10_loc, l_s_loc, n_s_loc, double(k), fetch_loc, prec_loc);
                H_all{k} = H_k(:);
                X_all{k} = X_h_k(:);
            end
        end
        temps_gen = toc;
        
    catch ME
        close(d);
        uialert(app.UIFigure, ['Error: ' ME.message], 'Generation error', 'Icon', 'error');
        return;
    end
    close(d);

    % Stocker dans app.ProfilesData
    app.ProfilesData = cell(1, Nprof_loc);
    for k = 1:Nprof_loc
        app.ProfilesData{k}.H = H_all{k};
        app.ProfilesData{k}.X = X_all{k};
    end

    % RAM après calcul
    vars_apres   = whos;
    ram_apres_W  = sum([vars_apres.bytes]) / 1024^2;
    delta_RAM_MB = ram_apres_W - ram_avant_W;

    % Taille mémoire des profils
    mem_Mo = 0;
    for k = 1:Nprof_loc
        mem_Mo = mem_Mo + (numel(app.ProfilesData{k}.H) + numel(app.ProfilesData{k}.X)) * 8 / 1e6;
    end

    % Reset stats
    app.Label_2.Text = '—';
    app.Label_3.Text = '—';
    app.Label_4.Text = '—';
    app.Label_5.Text = '—';

    % ⭐ CORRECTION 1 : Remplir le dropdown Selected Profile (onglet 3) ⭐
    items = arrayfun(@(k) sprintf('P%d', k), 1:Nprof_loc, 'UniformOutput', false);
    itemsWithDefault = [{'— Select a profile —'}, items];
    
    % Désactiver temporairement le callback pour éviter les erreurs
    oldCallback = app.ProfileDropDown.ValueChangedFcn;
    app.ProfileDropDown.ValueChangedFcn = [];
    
    app.ProfileDropDown.Items = itemsWithDefault;
    app.ProfileDropDown.Value = '— Select a profile —';
    
    % Réactiver le callback
    app.ProfileDropDown.ValueChangedFcn = oldCallback;

    % Initialiser la plage par défaut 1-5
    iEnd = min(5, Nprof_loc);
    app.LastSurface.ProfileRange = [1, iEnd];
    app.fillRangeDropDowns(Nprof_loc, 1, iEnd);

    % Afficher les profils
    app.drawProfiles(-1);

    % Synchroniser les paramètres vers l'onglet 4
    app.Windspeedu10msEditField_3.Value = u10_loc;
    app.FetchmEditField_4.Value = fetch_loc;
    app.LengthmEditField_2.Value = l_s_loc;
    app.RadarfrequencyGHz130GHz2308cmEditField_3.Value = freq;
    app.SamplesperwavelengthEditField_3.Value = nlb;
    app.NumberofsamplesEditField.Value = n_s_loc;
    app.updateFreqLabelTab4();

    % ⭐ CORRECTION 2 : Remplir le dropdown de sélection de profil (onglet 4) ⭐
    items4 = arrayfun(@(k) sprintf('P%d', k), 1:Nprof_loc, 'UniformOutput', false);
    
    oldCallback4 = app.SelectprofileDropDown.ValueChangedFcn;
    app.SelectprofileDropDown.ValueChangedFcn = [];
    
    app.SelectprofileDropDown.Items = items4;
    if Nprof_loc >= 1
        app.SelectprofileDropDown.Value = items4{1};
    end
    
    app.SelectprofileDropDown.ValueChangedFcn = oldCallback4;
    app.onProfileSelectorChanged();

    % ── Récupérer la valeur du spectre isotrope depuis onglet 3 ──
    isoVal     = app.IsotropicspectrumDropDown_2.Value;
    precVal    = app.UsesingleprecisionCheckBox_2.Value;
    roundVal   = app.Roundtothenearestpowerof2CheckBox_2.Value;
    parallelVal = app.YesCheckBox.Value;

    % ── Synchroniser vers l'onglet 4 ──
    app.Windspeedu10msEditField_3.Value                    = u10_loc;
    app.FetchmEditField_4.Value                            = fetch_loc;
    app.LengthmEditField_2.Value                           = l_s_loc;
    app.RadarfrequencyGHz130GHz2308cmEditField_3.Value     = freq;
    app.SamplesperwavelengthEditField_3.Value              = nlb;
    app.NumberofsamplesEditField.Value                     = n_s_loc;
    app.NprofilesEditField_2.Value                         = Nprof_loc;

    % Isotropic spectrum onglet 4 (valeur de l'onglet 3)
    app.IsotropicspectrumDropDown_3.Items = {'Apel', 'Elfouhaily', 'Donelan'};
    app.IsotropicspectrumDropDown_3.Value = isoVal;

    % Checkboxes onglet 4 (valeurs de l'onglet 3)
    app.UsesingleprecisionCheckBox_3.Value        = precVal;
    app.Roundtothenearestpowerof2CheckBox_3.Value = roundVal;
    app.YesCheckBox_2.Value                       = parallelVal;

    app.updateFreqLabelTab4();

    % Remplir le dropdown de sélection de profil (onglet 4)
    items4 = arrayfun(@(k) sprintf('P%d', k), 1:Nprof_loc, 'UniformOutput', false);
    oldCallback4 = app.SelectprofileDropDown.ValueChangedFcn;
    app.SelectprofileDropDown.ValueChangedFcn = [];
    app.SelectprofileDropDown.Items = items4;
    if Nprof_loc >= 1
        app.SelectprofileDropDown.Value = items4{1};
    end
    app.SelectprofileDropDown.ValueChangedFcn = oldCallback4;
    app.onProfileSelectorChanged();

    % ── Synchroniser vers l'onglet 5 ──
    app.Windspeedu10msEditField_4.Value                    = u10_loc;
    app.FetchmEditField_5.Value                            = fetch_loc;
    app.LengthmEditField_3.Value                           = l_s_loc;
    app.RadarfrequencyGHz130GHz2308cmEditField_4.Value     = freq;
    app.updateFreqLabelTab5();
    app.SamplesperwavelengthEditField_4.Value              = nlb;
    app.NumberofsamplesEditField_4.Value                   = n_s_loc;
    app.NprofilesEditField_3.Value                         = Nprof_loc;

    % Isotropic spectrum onglet 5 (valeur de l'onglet 3)
    app.IsotropicspectrumDropDown_4.Items = {'Apel', 'Elfouhaily', 'Donelan'};
    app.IsotropicspectrumDropDown_4.Value = isoVal;

    % Checkboxes onglet 5 (valeurs de l'onglet 3)
    app.UsesingleprecisionCheckBox_4.Value        = precVal;
    app.Roundtothenearestpowerof2CheckBox_4.Value = roundVal;
    app.YesCheckBox_3.Value                       = parallelVal;

    % ── Remplir dropdown sélection profil onglet 5 ──
    oldCb5 = app.SelectprofileDropDown_2.ValueChangedFcn;
    app.SelectprofileDropDown_2.ValueChangedFcn = [];
    app.SelectprofileDropDown_2.Items = items4;
    if Nprof_loc >= 1
        app.SelectprofileDropDown_2.Value = items4{1};
    end
    app.SelectprofileDropDown_2.ValueChangedFcn = oldCb5;
    app.onProfileSelectorTab5Changed();

    % ⭐ Mode texte amélioré avec explication
    if useParallel
        modeText = sprintf('Parallel (parfor) - optimized (n_s=%d, Nprof=%d)', n_s_loc, Nprof_loc);
    elseif app.YesCheckBox.Value
        if Nprof_loc < 5
            modeText = sprintf('Sequential (Nprof=%d < 5, parfor disabled)', Nprof_loc);
        elseif n_s_loc < 2000
            modeText = sprintf('Sequential (n_s=%d < 2000, parfor disabled)', n_s_loc);
        else
            modeText = 'Sequential (user choice or pool not available)';
        end
    else
        modeText = 'Sequential (user choice)';
    end

    % ── Remplir les dropdowns de plage onglet 4 et 5 ──
    items_range = arrayfun(@(k) num2str(k), 1:Nprof_loc, 'UniformOutput', false);
    iEndDefault = num2str(min(5, Nprof_loc));

    app.BistaticRangeStart.ValueChangedFcn = [];
    app.BistaticRangeEnd.ValueChangedFcn   = [];
    app.BistaticRangeStart.Items = items_range;
    app.BistaticRangeEnd.Items   = items_range;
    app.BistaticRangeStart.Value = '1';
    app.BistaticRangeEnd.Value   = iEndDefault;
    app.BistaticRangeStart.ValueChangedFcn = @(~,~) app.onBistaticRangeChanged();
    app.BistaticRangeEnd.ValueChangedFcn   = @(~,~) app.onBistaticRangeChanged();
    app.onBistaticRangeChanged();  % Afficher les profils P1-P5 immédiatement

    app.MonoRangeStart.ValueChangedFcn = [];
    app.MonoRangeEnd.ValueChangedFcn   = [];
    app.MonoRangeStart.Items = items_range;
    app.MonoRangeEnd.Items   = items_range;
    app.MonoRangeStart.Value = '1';
    app.MonoRangeEnd.Value   = iEndDefault;
    app.MonoRangeStart.ValueChangedFcn = @(~,~) app.onMonoRangeChanged();
    app.MonoRangeEnd.ValueChangedFcn   = @(~,~) app.onMonoRangeChanged();
    app.onMonoRangeChanged();  % Afficher les profils P1-P5 immédiatement

    % Message avec performances
    uialert(app.UIFigure, ...
        sprintf(['%d profiles generated in %.2f s\n' ...
                 'Mode: %s\n' ...
                 'n_s = %d samples per profile\n' ...
                 'Size ≈ %.2f Mo\n' ...
                 'RAM used ≈ %.1f MB'], ...
                 Nprof_loc, temps_gen, modeText, n_s_loc, mem_Mo, delta_RAM_MB), ...
        'Generation completed', 'Icon', 'success');
        end

        % Button pushed function: ClearSurfaceButton_2
        function ClearSurfaceButton_2Pushed(app, event)

    if ~app.isUIInitialized; return; end

    app.ProfilesData = {};
    cla(app.UIAxes_3);
    title(app.UIAxes_3, '');
    try; legend(app.UIAxes_3, 'off'); catch; end

    if ~isempty(app.RangeStartDropDown) && isvalid(app.RangeStartDropDown)
        app.RangeStartDropDown.ValueChangedFcn = [];
        app.RangeEndDropDown.ValueChangedFcn = [];
        app.RangeStartDropDown.Items = {'—'};
        app.RangeEndDropDown.Items = {'—'};
        app.RangeStartDropDown.Value = '—';
        app.RangeEndDropDown.Value = '—';
        app.RangeStartDropDown.ValueChangedFcn = @(~,~) app.onRangeDropDownChanged();
        app.RangeEndDropDown.ValueChangedFcn = @(~,~) app.onRangeDropDownChanged();
    end

    if ~isempty(app.ProfileDropDown) && isvalid(app.ProfileDropDown)
        app.ProfileDropDown.Items = {'— Generate first —'};
        app.ProfileDropDown.Value = '— Generate first —';
    end

    app.Label_2.Text = '—';
    app.Label_3.Text = '—';
    app.Label_4.Text = '—';
    app.Label_5.Text = '—';
    app.SelectedProfileStatisticsPanel.Title = 'Selected Profile Statistics';
        end

        % Button pushed function: ExportSurfaceButton_2
        function ExportSurfaceButton_2Pushed(app, event)

    if isempty(app.ProfilesData)
        uialert(app.UIFigure, 'No profiles to export.', 'Export', 'Icon', 'warning');
        return;
    end
    [file, path] = uiputfile({ ...
        '*.png', 'PNG image (*.png)'; ...
        '*.csv', 'CSV data (*.csv)'; ...
        '*.mat', 'MATLAB data (*.mat)'}, 'Export Sea Profiles');
    if isequal(file, 0); return; end
    [~, ~, ext] = fileparts(file);
    fullpath = fullfile(path, file);
    Nprof = numel(app.ProfilesData);

    switch lower(ext)

        case '.png'
            exportgraphics(app.UIAxes_3, fullpath, 'Resolution', 150);
            uialert(app.UIFigure, 'Graph exported as PNG.', 'Export OK', 'Icon', 'success');

        case '.csv'
            fid = fopen(fullpath, 'w');
            % Métadonnées
            fprintf(fid, 'Sea Profiles Export\n');
            fprintf(fid, 'Wind speed u10 (m/s),%.4f\n', app.Windspeedu10msEditField_2.Value);
            fprintf(fid, 'Fetch (m),%.1f\n',            app.FetchmEditField_3.Value);
            fprintf(fid, 'Length (m),%.4f\n',           app.LengthmEditField.Value);
            fprintf(fid, 'Frequency (GHz),%.4f\n',      app.RadarfrequencyGHz130GHz2308cmEditField_2.Value);
            fprintf(fid, 'Number of profiles,%d\n',     Nprof);
            fprintf(fid, '\n');
            % Données : une section par profil
            for k = 1:Nprof
                p = app.ProfilesData{k};
                fprintf(fid, 'Profile P%d\n', k);
                fprintf(fid, 'N_samples,%d\n', numel(p.X));
                fprintf(fid, 'Zmin_m,%.6f\n', min(p.H));
                fprintf(fid, 'Zmax_m,%.6f\n', max(p.H));
                fprintf(fid, 'Zmean_m,%.6f\n', mean(p.H));
                fprintf(fid, 'Zstd_m,%.6f\n', std(p.H));
                fprintf(fid, '\n');
                fprintf(fid, 'X_m,Z_m\n');
                for i = 1:numel(p.X)
                    fprintf(fid, '%.6f,%.6f\n', p.X(i), p.H(i));
                end
                fprintf(fid, '\n');
            end
            fclose(fid);
            uialert(app.UIFigure, ...
                sprintf('%d profile(s) exported to CSV.', Nprof), ...
                'Export OK', 'Icon', 'success');

        case '.mat'
            % Structure par profil avec métadonnées
            Profiles = struct();
            for k = 1:Nprof
                p = app.ProfilesData{k};
                Profiles(k).X_m     = p.X(:);
                Profiles(k).Z_m     = p.H(:);
                Profiles(k).Zmin    = min(p.H);
                Profiles(k).Zmax    = max(p.H);
                Profiles(k).Zmean   = mean(p.H);
                Profiles(k).Zstd    = std(p.H);
            end
            Metadata.wind_speed_ms = app.Windspeedu10msEditField_2.Value;
            Metadata.fetch_m       = app.FetchmEditField_3.Value;
            Metadata.length_m      = app.LengthmEditField.Value;
            Metadata.frequency_GHz = app.RadarfrequencyGHz130GHz2308cmEditField_2.Value;
            Metadata.Nprofiles     = Nprof;
            save(fullpath, 'Profiles', 'Metadata');
            uialert(app.UIFigure, ...
                sprintf('%d profile(s) exported to .mat with metadata.', Nprof), ...
                'Export OK', 'Icon', 'success');
    end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
         
   % ── Validation : angle d'incidence non négatif ──
if ~app.validateField(app.IncidenceangledegEditField_2, 'Incidence angle')
    return;
end
            
            if isempty(app.ProfilesData)
        uialert(app.UIFigure, ...
            'No profiles loaded. Generate profiles in Tab 3 first.', ...
            'No profile', 'Icon', 'warning');
        return;
    end

    u10     = app.Windspeedu10msEditField_3.Value;
    freq    = app.RadarfrequencyGHz130GHz2308cmEditField_3.Value;
    fetch_m = app.FetchmEditField_4.Value;
    th_i_d  = app.IncidenceangledegEditField_2.Value;
    Polar   = app.PolarizationDropDown_2.Value;

    NprofTotal = numel(app.ProfilesData);
    n_s        = length(app.ProfilesData{1}.X);
    
    selectedProfile = app.SelectprofileDropDown.Value;
    selectedIdx = str2double(selectedProfile(2:end));
    if isnan(selectedIdx); selectedIdx = 1; 

    end
    
    mem_GB_one = (n_s^2 * 16) / 1e9;
    if mem_GB_one > 2.0
        uialert(app.UIFigure, sprintf([ ...
            'Profile too large!\nn_s = %d → %.1f GB/profile\n' ...
            'Reduce Length or Samples/wavelength'], n_s, mem_GB_one), ...
            'Profile too large', 'Icon', 'warning');
        return;
    end

    % % Popup compact
    % choiceList = { ...
    %     sprintf('All (%d)', NprofTotal), ...
    %     sprintf('Single (%s)', selectedProfile), ...
    %     'Range (max 5)', ...
    %     'Custom...' };
    % 
    % [sel, ok] = listdlg( ...
    %     'PromptString', sprintf('n_s=%d | %.2f GB/prof', n_s, mem_GB_one), ...
    %     'ListString', choiceList, ...
    %     'SelectionMode', 'single', ...
    %     'ListSize', [220 120], ...
    %     'Name', 'NRCS');
    % if ~ok; 
    %     return; 
    % end
    % 
    % profilesToCompute = [];
    % 
    % switch sel
    %     case 1  % All
    %         profilesToCompute = 1:NprofTotal;
    %     case 2  % Single
    %         profilesToCompute = selectedIdx;
    %     case 3  % Range
    %         prompt = {'Start profile:', 'End profile:'};
    %         dlgtitle = 'Profile Range (max 5)';
    %         dims = [1 35];
    %         def = {'1', num2str(min(5, NprofTotal))};
    %         answer = inputdlg(prompt, dlgtitle, dims, def);
    % 
    %         if isempty(answer);
    %             return; 
    %         end
    % 
    %         iStart = max(1, round(str2double(answer{1})));
    %         iEnd = min(NprofTotal, round(str2double(answer{2})));
    % 
    %         % Corriger si inversé
    %         if iStart > iEnd
    %             [iStart, iEnd] = deal(iEnd, iStart);
    %         end
    % 
    %         currentCount = iEnd - iStart + 1;
    % 
    %         % ⭐ AJUSTEMENT INTELLIGENT POUR AVOIR EXACTEMENT 5 PROFILS ⭐
    %         if currentCount > 5
    %             % Calculer le centre exact de la plage demandée
    %             center = (iStart + iEnd) / 2;
    % 
    %             % Plage centrée sur le milieu
    %             newStart = floor(center) - 2;
    %             newEnd = floor(center) + 2;
    % 
    %             % Si le nombre est impair, ajuster pour bien centrer
    %             if mod(floor(center), 2) == 0
    %                 newStart = floor(center) - 2;
    %                 newEnd = floor(center) + 3;
    %                 % Re-ajuster si nécessaire
    %                 if (newEnd - newStart + 1) > 5
    %                     newEnd = newEnd - 1;
    %                 end
    %             end
    % 
    %             % Ajuster les bornes si elles dépassent
    %             if newStart < 1
    %                 newStart = 1;
    %                 newEnd = 5;
    %             end
    %             if newEnd > NprofTotal
    %                 newEnd = NprofTotal;
    %                 newStart = max(1, NprofTotal - 4);
    %             end
    % 
    %             % Vérifier qu'on a bien 5 profils, sinon ajuster
    %             if (newEnd - newStart + 1) < 5 && NprofTotal >= 5
    %                 if newStart > 1 && newEnd < NprofTotal
    %                     newStart = max(1, newStart - (5 - (newEnd - newStart + 1)));
    %                 elseif newStart == 1
    %                     newEnd = min(NprofTotal, newEnd + (5 - (newEnd - newStart + 1)));
    %                 elseif newEnd == NprofTotal
    %                     newStart = max(1, newStart - (5 - (newEnd - newStart + 1)));
    %                 end
    %             end
    % 
    %             iStart = newStart;
    %             iEnd = newEnd;
    % 
    %             uialert(app.UIFigure, ...
    %                 sprintf('Adjusted to 5 profiles: P%d to P%d', iStart, iEnd), ...
    %                 'Range adjusted', 'Icon', 'info');
    %         end
    % 
    %         profilesToCompute = iStart:iEnd;
    %     case 4  % Custom
    %         answer = inputdlg('Number of profiles:', 'Custom', [1 30], {'5'});
    %         if isempty(answer); 
    %             return; 
    %         end
    %         N = min(NprofTotal, max(1, round(str2double(answer{1}))));
    %         profilesToCompute = 1:N;
    % end
    % 
    % NprofActual = length(profilesToCompute);
    % 
    % if NprofActual == 0; 
    %     return; 
    % end


    % ── Lire la plage depuis les dropdowns (sans popup) ──
vs = app.BistaticRangeStart.Value;
ve = app.BistaticRangeEnd.Value;
if contains(vs,'—') || contains(ve,'—')
    uialert(app.UIFigure, ...
        'Generate profiles first and set a range with the dropdowns above the graph.', ...
        'No range set', 'Icon', 'warning');
    return;
end
iS = max(1, str2double(vs));
iE = min(NprofTotal, str2double(ve));
if (iE - iS + 1) > 5; iE = iS + 4; end
profilesToCompute = iS:iE;
NprofActual = numel(profilesToCompute);
if NprofActual == 0; return; end

    % Constantes
    c_ms    = 3e8;
    lb0_m   = c_ms / (freq * 1e9);
    k0_EM   = 2*pi / lb0_m;
    eta_inc = 119.9169832 * pi;
    k0_sea  = 9.81 / u10^2;
    Th_s_r  = (-89:1:89) * pi/180;

    % Préparer les données
    ProfileX = cell(1, NprofActual);
    ProfileH = cell(1, NprofActual);
    ProfileIndices = profilesToCompute;
    for j = 1:NprofActual
        k = profilesToCompute(j);
        ProfileX{j} = app.ProfilesData{k}.X(:)';
        ProfileH{j} = app.ProfilesData{k}.H(:)';
    end

    d = uiprogressdlg(app.UIFigure, ...
        'Title', 'NRCS Calculation', ...
        'Message', sprintf('Computing %d profiles...', NprofActual), ...
        'Indeterminate', 'on');
    drawnow;

    NRCS_all = zeros(NprofActual, length(Th_s_r));
    calcOK   = false(1, NprofActual);

    try
        tic;
        for j = 1:NprofActual
            X_h = ProfileX{j};
            H   = ProfileH{j};
            k   = ProfileIndices(j);
            try
                g_thorsos = (X_h(end) - X_h(1)) / 6;
                Dx    = gradient(X_h);
                DerZ  = gradient(H) ./ Dx;
                Der2Z = gradient(DerZ) ./ Dx;
                V     = ones(size(X_h));

                [Psi_inc, ~] = F_IIncidenteWave_Thorsos(th_i_d, g_thorsos, k0_EM, X_h, H);
                p_inc_num = trapz(X_h, abs(Psi_inc).^2 / (2*eta_inc));

                ZZ = F_IImpedanceMatrices(X_h, H, Dx, DerZ, Der2Z, V, k0_EM, Polar);
                Current = linsolve(ZZ, Psi_inc.');
                Current = Current.';

                if strcmp(Polar, 'TE')
                    D_Psi = Current; Psi_s = zeros(size(X_h));
                else
                    Psi_s = Current; D_Psi = zeros(size(X_h));
                end

                Psi_sca = F_IFarField(X_h, H, Dx, DerZ, V, k0_EM, Psi_s, D_Psi, Th_s_r);
                coef = eta_inc / (p_inc_num * 16 * pi * k0_sea);
                Nrcs = coef * abs(Psi_sca).^2;
                NRCS_all(j, :) = 10 * log10(Nrcs + eps);
                calcOK(j) = true;

            catch ME_k
                NRCS_all(j, :) = NaN;
                fprintf('Error P%d: %s\n', k, ME_k.message);
            end
        end
        temps_calc = toc;
        close(d);
    catch ME
        close(d);
        uialert(app.UIFigure, ['Error: ' ME.message], 'Error', 'Icon', 'error');
        return;
    end

    % Affichage
    Th_s_d = Th_s_r * 180/pi;
    th_retro = -th_i_d;
    [~, i_r] = min(abs(Th_s_d - th_retro));
    if numel(i_r) > 1; i_r = i_r(1); end

    cla(app.NRCSAxes);
    hold(app.NRCSAxes, 'on');
    colors = lines(NprofActual);

    for j = 1:NprofActual
        if calcOK(j)
            plot(app.NRCSAxes, Th_s_d, NRCS_all(j,:), ...
                'LineWidth', 1.2, 'Color', colors(j,:), ...
                'DisplayName', sprintf('P%d', ProfileIndices(j)));
        end
    end

    nOK = sum(calcOK);
    if nOK > 1
        NRCS_mean = mean(NRCS_all(calcOK, :), 1);
        plot(app.NRCSAxes, Th_s_d, NRCS_mean, ...
            'LineWidth', 2.5, 'Color', 'k', 'LineStyle', '--', ...
            'DisplayName', sprintf('Mean (%d)', nOK));
        retro_mean = NRCS_mean(i_r);
    elseif nOK == 1
        retro_mean = NRCS_all(find(calcOK,1), i_r);
    else
        retro_mean = NaN;
    end

    if ~isnan(retro_mean)
        retro_str = sprintf('%.1f dB', retro_mean);
        plot(app.NRCSAxes, th_retro, retro_mean, 'kv', 'MarkerSize', 8, 'MarkerFaceColor', 'k');
        text(app.NRCSAxes, th_retro+2, retro_mean, retro_str, 'FontSize', 8);
    else
        retro_str = 'N/A';
    end

    hold(app.NRCSAxes, 'off');
    grid(app.NRCSAxes, 'on');
    xlabel(app.NRCSAxes, '\theta_{sca} (deg)');
    ylabel(app.NRCSAxes, 'NRCS (dB)');
    title(app.NRCSAxes, sprintf('NRCS — %s — θ_{inc}=%d° — %.2f GHz', Polar, th_i_d, freq));
    xlim(app.NRCSAxes, [-90 90]);
    legend(app.NRCSAxes, 'Location', 'northeast', 'FontSize', 8);

    uialert(app.UIFigure, sprintf('OK: %d/%d profiles\nθ=%d° backscatter: %s', ...
        nOK, NprofActual, th_i_d, retro_str), 'NRCS OK', 'Icon', 'success');

        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)

    cla(app.UIAxes_4);
    title(app.UIAxes_4, '');
    if ~isempty(app.NRCSAxes) && isvalid(app.NRCSAxes)
        cla(app.NRCSAxes);
        title(app.NRCSAxes, 'Bistatic NRCS — set range then click Calculate');
    end
    % Réinitialiser les dropdowns de plage onglet 4
    if ~isempty(app.BistaticRangeStart) && isvalid(app.BistaticRangeStart)
        app.BistaticRangeStart.ValueChangedFcn = [];
        app.BistaticRangeEnd.ValueChangedFcn   = [];
        app.BistaticRangeStart.Items = {'—'};
        app.BistaticRangeEnd.Items   = {'—'};
        app.BistaticRangeStart.Value = '—';
        app.BistaticRangeEnd.Value   = '—';
        app.BistaticRangeStart.ValueChangedFcn = @(~,~) app.onBistaticRangeChanged();
        app.BistaticRangeEnd.ValueChangedFcn   = @(~,~) app.onBistaticRangeChanged();
    end
    app.SelectprofileDropDown.Items = {'— Generate profiles first —'};
    app.SelectprofileDropDown.Value = '— Generate profiles first —';
    app.Windspeedu10msEditField_3.Value = 0;
    app.FetchmEditField_4.Value = 0;
    app.LengthmEditField_2.Value = 0;
    app.RadarfrequencyGHz130GHz2308cmEditField_3.Value = 0;
    app.SamplesperwavelengthEditField_3.Value = 0;
    app.NumberofsamplesEditField.Value = 0;
    app.NprofilesEditField_2.Value = 0;
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)

    if isempty(app.NRCSAxes) || isempty(app.NRCSAxes.Children)
        uialert(app.UIFigure, 'No NRCS to export.', 'Export', 'Icon', 'warning');
        return;
    end
    [file, path] = uiputfile({ ...
        '*.png', 'PNG image (*.png)'; ...
        '*.csv', 'CSV data (*.csv)'; ...
        '*.mat', 'MATLAB data (*.mat)'}, 'Export Bistatic NRCS');
    if isequal(file, 0); return; end
    [~, ~, ext] = fileparts(file);
    fullpath = fullfile(path, file);

    switch lower(ext)

        case '.png'
            exportgraphics(app.NRCSAxes, fullpath, 'Resolution', 150);
            uialert(app.UIFigure, 'NRCS graph exported as PNG.', 'Export OK', 'Icon', 'success');

        case '.csv'
            fid = fopen(fullpath, 'w');
            % Métadonnées
            fprintf(fid, 'Bistatic NRCS Export\n');
            fprintf(fid, 'Wind speed u10 (m/s),%.4f\n', app.Windspeedu10msEditField_3.Value);
            fprintf(fid, 'Fetch (m),%.1f\n',            app.FetchmEditField_4.Value);
            fprintf(fid, 'Frequency (GHz),%.4f\n',      app.RadarfrequencyGHz130GHz2308cmEditField_3.Value);
            fprintf(fid, 'Incidence angle (deg),%.4f\n', app.IncidenceangledegEditField_2.Value);
            fprintf(fid, 'Polarization,%s\n',            app.PolarizationDropDown_2.Value);
            fprintf(fid, '\n');
            % Courbes
            lns = app.NRCSAxes.Children;
            curveIdx = 0;
            for k = 1:length(lns)
                if isa(lns(k), 'matlab.graphics.chart.primitive.Line')
                    curveIdx = curveIdx + 1;
                    fprintf(fid, 'Curve %d: %s\n', curveIdx, lns(k).DisplayName);
                    fprintf(fid, 'Theta_sca_deg,NRCS_dB\n');
                    xd = lns(k).XData(:);
                    yd = lns(k).YData(:);
                    for i = 1:length(xd)
                        fprintf(fid, '%.4f,%.6f\n', xd(i), yd(i));
                    end
                    fprintf(fid, '\n');
                end
            end
            fclose(fid);
            uialert(app.UIFigure, 'Bistatic NRCS exported to CSV.', 'Export OK', 'Icon', 'success');

        case '.mat'
            lns = app.NRCSAxes.Children;
            BistaticNRCS = struct();
            BistaticNRCS.wind_speed_ms   = app.Windspeedu10msEditField_3.Value;
            BistaticNRCS.fetch_m         = app.FetchmEditField_4.Value;
            BistaticNRCS.frequency_GHz   = app.RadarfrequencyGHz130GHz2308cmEditField_3.Value;
            BistaticNRCS.incidence_angle = app.IncidenceangledegEditField_2.Value;
            BistaticNRCS.polarization    = app.PolarizationDropDown_2.Value;
            curveIdx = 0;
            for k = 1:length(lns)
                if isa(lns(k), 'matlab.graphics.chart.primitive.Line')
                    curveIdx = curveIdx + 1;
                    BistaticNRCS.curves(curveIdx).name          = lns(k).DisplayName;
                    BistaticNRCS.curves(curveIdx).theta_sca_deg = lns(k).XData(:);
                    BistaticNRCS.curves(curveIdx).NRCS_dB       = lns(k).YData(:);
                end
            end
            save(fullpath, 'BistaticNRCS');
            uialert(app.UIFigure, 'Bistatic NRCS exported to .mat.', 'Export OK', 'Icon', 'success');
    end
        end

        % Value changed function: FrequencybandDropDown
        function FrequencybandDropDownValueChanged(app, event)
            band = app.FrequencybandDropDown.Value;
    switch band
        case 'UHF: 0.3-1 GHz'
            app.EMmodelDropDown.Items = {'NRL','TSC','HYBRID','TSM','GO1sh','SPM1'};
            app.EMmodelDropDown.Value = 'TSM';
            app.FrequencyGHzEditField.Value = 0.65;
            app.Windspeedu10msEditField_5.Value = 8;
            app.FetchmEditField.Value = 500000;
            app.k0kcEditField.Value = 4;
            app.IncidenceangledegEditField.Value = 30;
            app.Anglerelativetowinddirection0360degEditField.Value = 0;
            
        case 'L-band: 1-2 GHz'
            app.EMmodelDropDown.Items = {'Isoguchi','Meissner','NRL','TSC','HYBRID','TSM','GO1sh','SPM1'};
            app.EMmodelDropDown.Value = 'Isoguchi';
            app.FrequencyGHzEditField.Value = 1.5;
            app.Windspeedu10msEditField_5.Value = 8;
            app.FetchmEditField.Value = 500000;
            app.k0kcEditField.Value = 4;
            app.IncidenceangledegEditField.Value = 30;
            app.Anglerelativetowinddirection0360degEditField.Value = 0;
            
        case 'S-band: 2-4 GHz'
            app.EMmodelDropDown.Items = {'NRL','TSC','HYBRID','TSM','GO1sh','SPM1'};
            app.EMmodelDropDown.Value = 'TSM';
            app.FrequencyGHzEditField.Value = 3.0;
            app.Windspeedu10msEditField_5.Value = 8;
            app.FetchmEditField.Value = 500000;
            app.k0kcEditField.Value = 4;
            app.IncidenceangledegEditField.Value = 30;
            app.Anglerelativetowinddirection0360degEditField.Value = 0;
            
        case 'C-band: 4-8 GHz'
            app.EMmodelDropDown.Items = {'Quilfen','CMOD2-I3','CMOD5','CMOD5N','NRL','TSC','HYBRID','TSM','GO1sh','SPM1'};
            app.EMmodelDropDown.Value = 'CMOD5N';
            app.FrequencyGHzEditField.Value = 6.0;
            app.Windspeedu10msEditField_5.Value = 8;
            app.FetchmEditField.Value = 500000;
            app.k0kcEditField.Value = 4;
            app.IncidenceangledegEditField.Value = 30;
            app.Anglerelativetowinddirection0360degEditField.Value = 0;
            
        case 'X-band: 8-12 GHz'
            app.EMmodelDropDown.Items = {'XMOD1R','XMOD2N','Masuko X','NRL','TSC','GIT','HYBRID','RRE','SITTROP','TSM','GO1sh','SPM1'};
            app.EMmodelDropDown.Value = 'XMOD1R';
            app.FrequencyGHzEditField.Value = 10.0;
            app.Windspeedu10msEditField_5.Value = 8;
            app.FetchmEditField.Value = 500000;
            app.k0kcEditField.Value = 4;
            app.IncidenceangledegEditField.Value = 30;
            app.Anglerelativetowinddirection0360degEditField.Value = 0;
            
        case 'Ku-band: 12-18 GHz'
            app.EMmodelDropDown.Items = {'Wentz84','Wentz99','NRL','TSC','HYBRID','TSM','GO1sh','SPM1'};
            app.EMmodelDropDown.Value = 'Wentz84';
            app.FrequencyGHzEditField.Value = 15.0;
            app.Windspeedu10msEditField_5.Value = 8;
            app.FetchmEditField.Value = 500000;
            app.k0kcEditField.Value = 4;
            app.IncidenceangledegEditField.Value = 30;
            app.Anglerelativetowinddirection0360degEditField.Value = 0;
            
        case 'K-band: 18-27 GHz'
            app.EMmodelDropDown.Items = {'NRL','TSC','HYBRID','TSM','GO1sh','SPM1'};
            app.EMmodelDropDown.Value = 'TSM';
            app.FrequencyGHzEditField.Value = 22.5;
            app.Windspeedu10msEditField_5.Value = 8;
            app.FetchmEditField.Value = 500000;
            app.k0kcEditField.Value = 4;
            app.IncidenceangledegEditField.Value = 30;
            app.Anglerelativetowinddirection0360degEditField.Value = 0;
            
        case 'Ka-band: 27-40 GHz'
            app.EMmodelDropDown.Items = {'Masuko Ka','NRL','TSC','HYBRID','TSM','GO1sh','SPM1'};
            app.EMmodelDropDown.Value = 'Masuko Ka';
            app.FrequencyGHzEditField.Value = 33.5;
            app.Windspeedu10msEditField_5.Value = 8;
            app.FetchmEditField.Value = 500000;
            app.k0kcEditField.Value = 4;
            app.IncidenceangledegEditField.Value = 30;
            app.Anglerelativetowinddirection0360degEditField.Value = 0;
            
        case 'V-band: 40-75 GHz'
            app.EMmodelDropDown.Items = {'TSM','GO1sh','SPM1'};
            app.EMmodelDropDown.Value = 'TSM';
            app.FrequencyGHzEditField.Value = 57.5;
            app.Windspeedu10msEditField_5.Value = 8;
            app.FetchmEditField.Value = 500000;
            app.k0kcEditField.Value = 4;
            app.IncidenceangledegEditField.Value = 30;
            app.Anglerelativetowinddirection0360degEditField.Value = 0;
            
        case 'W-band: 75-110 GHz'
            app.EMmodelDropDown.Items = {'TSM','GO1sh','SPM1'};
            app.EMmodelDropDown.Value = 'TSM';
            app.FrequencyGHzEditField.Value = 92.5;
            app.Windspeedu10msEditField_5.Value = 8;
            app.FetchmEditField.Value = 500000;
            app.k0kcEditField.Value = 4;
            app.IncidenceangledegEditField.Value = 30;
            app.Anglerelativetowinddirection0360degEditField.Value = 0;
    end
    EMmodelDropDownValueChanged(app, []);

     % Forcer la mise à jour de la note (car ModelDropDownValueChanged vient d'être appelé)
    app.updateValidRangesNote();

        end

        % Value changed function: EMmodelDropDown
        function EMmodelDropDownValueChanged(app, event)
           model = app.EMmodelDropDown.Value;
    switch model
        case 'Isoguchi'
            app.PolarizationDropDown.Items = {'HH'};
            app.PolarizationDropDown.Value = 'HH';
        case {'Meissner','TSM'}
            app.PolarizationDropDown.Items = {'VV','HH','VH'};
            app.PolarizationDropDown.Value = 'VV';
        case {'Quilfen','CMOD2-I3','CMOD5','CMOD5N','XMOD1R','XMOD2N'}
            app.PolarizationDropDown.Items = {'VV'};
            app.PolarizationDropDown.Value = 'VV';
        otherwise
            app.PolarizationDropDown.Items = {'VV','HH'};
            app.PolarizationDropDown.Value = 'VV';
    end
    
    % Gérer Fetch et kokc
    if ismember(model, {'TSM','GO1sh','SPM1'})
        app.FetchmEditField.Enable = 'on';
        app.k0kcEditField.Enable = 'on';
        if strcmp(model, 'TSM')
            app.k0kcEditField.Value = 4;
        elseif strcmp(model, 'GO1sh')
            app.k0kcEditField.Value = 3;
        end
    else
        app.FetchmEditField.Enable = 'off';
        app.k0kcEditField.Enable = 'off';
    end

    % Mettre à jour la note des plages valides
    app.updateValidRangesNote();
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
    if isempty(app.ProfilesData)
        uialert(app.UIFigure, ...
            'No profiles loaded. Generate profiles in Tab 3 first.', ...
            'No profile', 'Icon', 'warning');
        return;
    end

    % ── Paramètres hérités de l'onglet 3 ──
    u10     = app.Windspeedu10msEditField_2.Value;
    freq    = app.RadarfrequencyGHz130GHz2308cmEditField_2.Value;
    fetch_m = app.FetchmEditField_3.Value;
    NprofTotal = numel(app.ProfilesData);

    % ── Paramètres spécifiques onglet 5 ──
    N_th    = round(app.N_calcsNEditField.Value);
    maxTh_i = app.MaximumincidenceangledegEditField.Value;
    Polar   = app.PolarizationDropDown_3.Value;

    % ── Lire la plage depuis les dropdowns onglet 5 ──
    vs5 = app.MonoRangeStart.Value;
    ve5 = app.MonoRangeEnd.Value;
    if contains(vs5,'—') || contains(ve5,'—')
        uialert(app.UIFigure, ...
            'Generate profiles first and set a range with the dropdowns above the graph.', ...
            'No range set', 'Icon', 'warning');
        return;
    end
    iS5 = max(1, str2double(vs5));
    iE5 = min(NprofTotal, str2double(ve5));
    if (iE5 - iS5 + 1) > 5
        iE5 = iS5 + 4;
    end
    profilesToCompute5 = iS5:iE5;
    NprofActual = numel(profilesToCompute5);
    if NprofActual == 0
        return;
    end

    % Préparer les profils
    ProfileX5 = cell(1, NprofActual);
    ProfileH5 = cell(1, NprofActual);
    for j = 1:NprofActual
        ProfileX5{j} = app.ProfilesData{profilesToCompute5(j)}.X(:)';
        ProfileH5{j} = app.ProfilesData{profilesToCompute5(j)}.H(:)';
    end

    % ── Validation ──
    if maxTh_i <= 0 || maxTh_i > 89
        uialert(app.UIFigure, ...
            'Maximum incidence angle must be between 1° and 89°.', ...
            'Invalid parameter', 'Icon', 'warning');
        return;
    end
    if N_th < 1
        N_th = 10;
        app.N_calcsNEditField.Value = N_th;
    end

    % ── Vecteur d'angles d'incidence pour le monostatique ──
    th_i_vector = linspace(1, maxTh_i, N_th);

    % ── Constantes EM ──
    c_ms    = 3e8;
    lb0_m   = c_ms / (freq * 1e9);
    k0_EM   = 2*pi / lb0_m;
    eta_inc = 119.9169832 * pi;
    k0_sea  = 9.81 / u10^2;

    % ── Progress dialog ──
    d = uiprogressdlg(app.UIFigure, ...
        'Title',   'Monostatic NRCS Calculation', ...
        'Message', sprintf('%d profiles | %d angles | Polar: %s', NprofActual, N_th, Polar), ...
        'Indeterminate', 'on');
    drawnow;

    % Initialisation des résultats
    NRCS_mono_dB = zeros(NprofActual, N_th);
    calcOK = false(1, NprofActual);

    try
        tic;
        
        for j = 1:NprofActual
            X_h = ProfileX5{j};
            H   = ProfileH5{j};
            k   = profilesToCompute5(j);
            
            % Calculs préliminaires (indépendants de l'angle)
            Dx    = gradient(X_h);
            DerZ  = gradient(H) ./ Dx;
            Der2Z = gradient(DerZ) ./ Dx;
            V     = ones(size(X_h));
            g_thorsos = (X_h(end) - X_h(1)) / 6;
            
            try
                for i_angle = 1:N_th
                    th_i = th_i_vector(i_angle);
                    
                    % Onde incidente (enveloppe de Thorsos)
                    [Psi_inc, ~] = F_IIncidenteWave_Thorsos(th_i, g_thorsos, k0_EM, X_h, H);
                    p_inc_num = trapz(X_h, abs(Psi_inc).^2 / (2*eta_inc));
                    
                    % Matrice d'impédance
                    ZZ = F_IImpedanceMatrices(X_h, H, Dx, DerZ, Der2Z, V, k0_EM, Polar);
                    
                    % Résolution → courant de surface
                    Current = linsolve(ZZ, Psi_inc.');
                    Current = Current.';
                    
                    if strcmp(Polar, 'TE')
                        D_Psi = Current;
                        Psi_s = zeros(size(X_h));
                    else
                        Psi_s = Current;
                        D_Psi = zeros(size(X_h));
                    end
                    
                    % ⭐ MONOSTATIQUE : angle d'observation = -θᵢ (rétrodiffusion) ⭐
                    Th_s_r_mono = -th_i * pi/180;
                    Psi_sca = F_IFarField(X_h, H, Dx, DerZ, V, k0_EM, ...
                                          Psi_s, D_Psi, Th_s_r_mono);
                    
                    coef = eta_inc / (p_inc_num * 16 * pi * k0_sea);
                    Nrcs_lin = coef * abs(Psi_sca)^2;
                    NRCS_mono_dB(j, i_angle) = 10 * log10(Nrcs_lin + eps);
                end
                calcOK(j) = true;
                
            catch ME_angle
                fprintf('Profile P%d error: %s\n', k, ME_angle.message);
                NRCS_mono_dB(j, :) = NaN;
            end
        end
        temps_calc = toc;
        close(d);
        
    catch ME
        close(d);
        uialert(app.UIFigure, ['Error: ' ME.message], 'Error', 'Icon', 'error');
        return;
    end

    % ── Affichage dans MonoNRCSAxes ──
    cla(app.MonoNRCSAxes);
    hold(app.MonoNRCSAxes, 'on');
    colors = lines(NprofActual);
    
    for j = 1:NprofActual
        if calcOK(j)
            plot(app.MonoNRCSAxes, th_i_vector, NRCS_mono_dB(j, :), ...
                'LineWidth', 1.5, 'Color', colors(j, :), ...
                'DisplayName', sprintf('P%d', profilesToCompute5(j)));
        end
    end
    
    % Moyenne si plusieurs profils
    nOK = sum(calcOK);
    if nOK > 1
        NRCS_mean = mean(NRCS_mono_dB(calcOK, :), 1);
        plot(app.MonoNRCSAxes, th_i_vector, NRCS_mean, ...
            'LineWidth', 3, 'Color', 'k', 'LineStyle', '--', ...
            'DisplayName', sprintf('Mean (%d profiles)', nOK));
    end
    
    hold(app.MonoNRCSAxes, 'off');
    grid(app.MonoNRCSAxes, 'on');
    xlabel(app.MonoNRCSAxes, '\theta_{inc} (deg)');
    ylabel(app.MonoNRCSAxes, '\sigma^0_{mono} (dB)');
    title(app.MonoNRCSAxes, sprintf('Monostatic NRCS — %s — %.2f GHz', Polar, freq));
    legend(app.MonoNRCSAxes, 'Location', 'northeast', 'FontSize', 8);
    xlim(app.MonoNRCSAxes, [0, maxTh_i]);

    % ── Rapport final ──
    if nOK > 0
        % Pour afficher la valeur de rétrodiffusion à un angle représentatif
         % (par exemple l'angle maximum ou un angle par défaut comme 30°)
        angle_display = min(30, maxTh_i);  % angle par défaut 30° ou le max disponible
    
         % Trouver l'index de l'angle le plus proche
           [~, idx_angle] = min(abs(th_i_vector - angle_display));
    
         % Calculer la moyenne des rétrodiffusions à cet angle
         backscatter_value = mean(NRCS_mono_dB(calcOK, idx_angle));
    
          % Message simple
         msg = sprintf('OK: %d/%d profiles\nθ=%d° backscatter: %.1f dB', ...
                  nOK, NprofActual, round(th_i_vector(idx_angle)), backscatter_value);
    
         uialert(app.UIFigure, msg, 'Monostatic NRCS OK', 'Icon', 'success');
    else
        uialert(app.UIFigure, 'No valid NRCS computed.', 'Warning', 'Icon', 'warning');
    end
        end

        % Button pushed function: ClearButton_3
        function ClearButton_3Pushed(app, event)

    cla(app.UIAxes_5);
    title(app.UIAxes_5, '');
    if ~isempty(app.MonoNRCSAxes) && isvalid(app.MonoNRCSAxes)
        cla(app.MonoNRCSAxes);
        title(app.MonoNRCSAxes, 'Monostatic NRCS — set range then click Calculate');
    end
    % Réinitialiser les dropdowns de plage onglet 5
    if ~isempty(app.MonoRangeStart) && isvalid(app.MonoRangeStart)
        app.MonoRangeStart.ValueChangedFcn = [];
        app.MonoRangeEnd.ValueChangedFcn   = [];
        app.MonoRangeStart.Items = {'—'};
        app.MonoRangeEnd.Items   = {'—'};
        app.MonoRangeStart.Value = '—';
        app.MonoRangeEnd.Value   = '—';
        app.MonoRangeStart.ValueChangedFcn = @(~,~) app.onMonoRangeChanged();
        app.MonoRangeEnd.ValueChangedFcn   = @(~,~) app.onMonoRangeChanged();
    end
    app.SelectprofileDropDown_2.Items = {'— Generate profiles first —'};
    app.SelectprofileDropDown_2.Value = '— Generate profiles first —';
    app.Windspeedu10msEditField_4.Value = 0;
    app.FetchmEditField_5.Value = 0;
    app.LengthmEditField_3.Value = 0;
    app.RadarfrequencyGHz130GHz2308cmEditField_4.Value = 0;
    app.SamplesperwavelengthEditField_4.Value = 0;
    app.NumberofsamplesEditField_4.Value = 0;
    app.NprofilesEditField_3.Value = 0;

        end

        % Button pushed function: ExportButton_3
        function ExportButton_3Pushed(app, event)

    if isempty(app.MonoNRCSAxes) || isempty(app.MonoNRCSAxes.Children)
        uialert(app.UIFigure, 'No NRCS to export.', 'Export', 'Icon', 'warning');
        return;
    end
    [file, path] = uiputfile({ ...
        '*.png', 'PNG image (*.png)'; ...
        '*.csv', 'CSV data (*.csv)'; ...
        '*.mat', 'MATLAB data (*.mat)'}, 'Export Monostatic NRCS');
    if isequal(file, 0); return; end
    [~, ~, ext] = fileparts(file);
    fullpath = fullfile(path, file);

    switch lower(ext)

        case '.png'
            exportgraphics(app.MonoNRCSAxes, fullpath, 'Resolution', 150);
            uialert(app.UIFigure, 'NRCS graph exported as PNG.', 'Export OK', 'Icon', 'success');

        case '.csv'
            fid = fopen(fullpath, 'w');
            % Métadonnées
            fprintf(fid, 'Monostatic NRCS Export\n');
            fprintf(fid, 'Wind speed u10 (m/s),%.4f\n', app.Windspeedu10msEditField_2.Value);
            fprintf(fid, 'Fetch (m),%.1f\n',            app.FetchmEditField_3.Value);
            fprintf(fid, 'Frequency (GHz),%.4f\n',      app.RadarfrequencyGHz130GHz2308cmEditField_2.Value);
            fprintf(fid, 'Max incidence angle (deg),%.4f\n', app.MaximumincidenceangledegEditField.Value);
            fprintf(fid, 'N_calcs (Ntheta),%d\n',       round(app.N_calcsNEditField.Value));
            fprintf(fid, 'Polarization,%s\n',            app.PolarizationDropDown_3.Value);
            fprintf(fid, '\n');
            % Courbes
            lns = app.MonoNRCSAxes.Children;
            curveIdx = 0;
            for k = 1:length(lns)
                if isa(lns(k), 'matlab.graphics.chart.primitive.Line')
                    curveIdx = curveIdx + 1;
                    fprintf(fid, 'Curve %d: %s\n', curveIdx, lns(k).DisplayName);
                    fprintf(fid, 'Theta_inc_deg,NRCS_mono_dB\n');
                    xd = lns(k).XData(:);
                    yd = lns(k).YData(:);
                    for i = 1:length(xd)
                        fprintf(fid, '%.4f,%.6f\n', xd(i), yd(i));
                    end
                    fprintf(fid, '\n');
                end
            end
            fclose(fid);
            uialert(app.UIFigure, 'Monostatic NRCS exported to CSV.', 'Export OK', 'Icon', 'success');

        case '.mat'
            lns = app.MonoNRCSAxes.Children;
            MonostaticNRCS = struct();
            MonostaticNRCS.wind_speed_ms      = app.Windspeedu10msEditField_2.Value;
            MonostaticNRCS.fetch_m            = app.FetchmEditField_3.Value;
            MonostaticNRCS.frequency_GHz      = app.RadarfrequencyGHz130GHz2308cmEditField_2.Value;
            MonostaticNRCS.max_incidence_deg  = app.MaximumincidenceangledegEditField.Value;
            MonostaticNRCS.N_calcs            = round(app.N_calcsNEditField.Value);
            MonostaticNRCS.polarization       = app.PolarizationDropDown_3.Value;
            curveIdx = 0;
            for k = 1:length(lns)
                if isa(lns(k), 'matlab.graphics.chart.primitive.Line')
                    curveIdx = curveIdx + 1;
                    MonostaticNRCS.curves(curveIdx).name         = lns(k).DisplayName;
                    MonostaticNRCS.curves(curveIdx).theta_inc_deg = lns(k).XData(:);
                    MonostaticNRCS.curves(curveIdx).NRCS_dB      = lns(k).YData(:);
                end
            end
            save(fullpath, 'MonostaticNRCS');
            uialert(app.UIFigure, 'Monostatic NRCS exported to .mat.', 'Export OK', 'Icon', 'success');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NRCSSimulationTab = uitab(app.TabGroup);
            app.NRCSSimulationTab.Title = 'NRCS Simulation';
            app.GridLayout = uigridlayout(app.NRCSSimulationTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.NRCSSimulationLabel = uilabel(app.GridLayout2);
            app.NRCSSimulationLabel.Text = 'NRCS Simulation';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'logo_icam.png';
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.ImageSource = 'logo_ietr.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.InputparametersLabel = uilabel(app.GridLayout4);
            app.InputparametersLabel.Text = 'Input parameters';
            app.IncidenceangledegLabel_2 = uilabel(app.GridLayout4);
            app.IncidenceangledegLabel_2.Text = 'Incidence angle (deg) :';
            app.IncidenceangledegEditField = uieditfield(app.GridLayout4, 'numeric');
            app.FrequencybandLabel = uilabel(app.GridLayout4);
            app.FrequencybandLabel.Text = 'Frequency band :';
            app.FrequencybandDropDown = uidropdown(app.GridLayout4);
            app.FrequencybandDropDown.Items = {'UHF: 0.3-1 GHz', 'L-band: 1-2 GHz', 'S-band: 2-4 GHz', 'C-band: 4-8 GHz', 'X-band: 8-12 GHz', 'Ku-band: 12-18 GHz', 'K-band: 18-27 GHz', 'Ka-band: 27-40 GHz', 'V-band: 40-75 GHz', 'W-band: 75-110 GHz'};
            app.FrequencybandDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencybandDropDownValueChanged, true);
            app.FrequencybandDropDown.Value = 'UHF: 0.3-1 GHz';
            app.EMmodelDropDownLabel = uilabel(app.GridLayout4);
            app.EMmodelDropDownLabel.Text = 'EM model :';
            app.EMmodelDropDown = uidropdown(app.GridLayout4);
            app.EMmodelDropDown.Items = {'TSM', 'TSC', 'HYBRID', 'NRL', 'GO1sh', 'SPM1'};
            app.EMmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @EMmodelDropDownValueChanged, true);
            app.EMmodelDropDown.Value = 'TSM';
            app.PolarizationLabel_2 = uilabel(app.GridLayout4);
            app.PolarizationLabel_2.Text = 'Polarization :';
            app.PolarizationDropDown = uidropdown(app.GridLayout4);
            app.PolarizationDropDown.Items = {'VV', 'HH', 'VH'};
            app.PolarizationDropDown.Value = 'VV';
            app.Windspeedu10msLabel_4 = uilabel(app.GridLayout4);
            app.Windspeedu10msLabel_4.Text = 'Wind speed u10 (m/s) :';
            app.Windspeedu10msEditField_5 = uieditfield(app.GridLayout4, 'numeric');
            app.Anglerelativetowinddirection0360degLabel = uilabel(app.GridLayout4);
            app.Anglerelativetowinddirection0360degLabel.Text = 'Angle relative to wind direction [0 ; 360[ (deg) :';
            app.Anglerelativetowinddirection0360degEditField = uieditfield(app.GridLayout4, 'numeric');
            app.Anglerelativetowinddirection0360degEditField.AllowEmpty = 'on';
            app.FetchmLabel_4 = uilabel(app.GridLayout4);
            app.FetchmLabel_4.Text = 'Fetch (m) :';
            app.FetchmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.k0kcLabel = uilabel(app.GridLayout4);
            app.k0kcLabel.Text = 'k0/kc :';
            app.k0kcEditField = uieditfield(app.GridLayout4, 'numeric');
            app.FrequencyGHzLabel = uilabel(app.GridLayout4);
            app.FrequencyGHzLabel.Text = 'Frequency (GHz) :';
            app.FrequencyGHzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ValidRangesNoteLabel = uilabel(app.GridLayout4);
            app.ValidRangesNoteLabel.Text = 'Valid ranges: Incidence Angle 30°-89.9°, Frequency 0.5-35 GHz, Wind Speed u10 0-13.2 m/s''';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.UIAxes = uiaxes(app.GridLayout5);
            title(app.UIAxes, 'NRCS curves')
            xlabel(app.UIAxes, 'Incidence angle (deg)')
            ylabel(app.UIAxes, 'NRCS (dB)')
            zlabel(app.UIAxes, 'Z')
            app.ResultsPanel = uipanel(app.GridLayout5);
            app.ResultsPanel.Title = 'Results';
            app.Label_9 = uilabel(app.ResultsPanel);
            app.Label_9.Text = '';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.CalculateButton = uibutton(app.GridLayout6, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OverlayButton = uibutton(app.GridLayout6, 'push');
            app.OverlayButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayButtonPushed, true);
            app.OverlayButton.Text = 'Overlay';
            app.ClearButton = uibutton(app.GridLayout6, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ExportButton = uibutton(app.GridLayout6, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.GenerationofSeaSurfaceTab = uitab(app.TabGroup);
            app.GenerationofSeaSurfaceTab.Title = 'Generation of Sea Surface';
            app.GridLayout_2 = uigridlayout(app.GenerationofSeaSurfaceTab);
            app.GridLayout2_2 = uigridlayout(app.GridLayout_2);
            app.GenerationofSeaSurfaceLabel = uilabel(app.GridLayout2_2);
            app.GenerationofSeaSurfaceLabel.Text = 'Generation of Sea Surface';
            app.Image_2 = uiimage(app.GridLayout2_2);
            app.Image_2.ImageSource = 'logo_icam.png';
            app.Image2_2 = uiimage(app.GridLayout2_2);
            app.Image2_2.ImageSource = 'logo_ietr.png';
            app.GridLayout3_2 = uigridlayout(app.GridLayout_2);
            app.GridLayout5_2 = uigridlayout(app.GridLayout3_2);
            app.UIAxes_2 = uiaxes(app.GridLayout5_2);
            xlabel(app.UIAxes_2, 'x(m)')
            ylabel(app.UIAxes_2, 'y(m)')
            zlabel(app.UIAxes_2, 'Z')
            app.SurfacestatisticsPanel = uipanel(app.GridLayout5_2);
            app.SurfacestatisticsPanel.Title = 'Surface statistics';
            app.GridLayout10 = uigridlayout(app.SurfacestatisticsPanel);
            app.ZminLabel = uilabel(app.GridLayout10);
            app.ZminLabel.Text = 'Zmin';
            app.ZmeanLabel = uilabel(app.GridLayout10);
            app.ZmeanLabel.Text = 'Zmean';
            app.ZmaxLabel = uilabel(app.GridLayout10);
            app.ZmaxLabel.Text = 'Zmax';
            app.ZstdLabel = uilabel(app.GridLayout10);
            app.ZstdLabel.Text = 'Zstd';
            app.Label = uilabel(app.GridLayout10);
            app.Label.Text = '';
            app.Label_7 = uilabel(app.GridLayout10);
            app.Label_7.Text = '';
            app.Label_6 = uilabel(app.GridLayout10);
            app.Label_6.Text = '';
            app.Label_8 = uilabel(app.GridLayout10);
            app.Label_8.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout3_2);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.SeasurfaceparametersLabel = uilabel(app.GridLayout8);
            app.SeasurfaceparametersLabel.Text = 'Sea surface parameters';
            app.Windspeedu10msLabel = uilabel(app.GridLayout8);
            app.Windspeedu10msLabel.Text = 'Wind speed u10 (m/s) :';
            app.Windspeedu10msEditField = uieditfield(app.GridLayout8, 'numeric');
            app.Windspeedu10msEditField.Value = 8;
            app.WinddirectiondegLabel = uilabel(app.GridLayout8);
            app.WinddirectiondegLabel.Text = 'Wind direction (deg) :';
            app.WinddirectiondegEditField = uieditfield(app.GridLayout8, 'numeric');
            app.FetchmLabel = uilabel(app.GridLayout8);
            app.FetchmLabel.Text = 'Fetch (m) :';
            app.FetchmEditField_2 = uieditfield(app.GridLayout8, 'numeric');
            app.FetchmEditField_2.Value = 500000;
            app.LengthXmLabel = uilabel(app.GridLayout8);
            app.LengthXmLabel.Text = 'Length X (m) :';
            app.LengthXmEditField = uieditfield(app.GridLayout8, 'numeric');
            app.LengthXmEditField.Value = 40;
            app.LengthYmLabel = uilabel(app.GridLayout8);
            app.LengthYmLabel.Text = 'Length Y (m) :';
            app.LengthYmEditField = uieditfield(app.GridLayout8, 'numeric');
            app.LengthYmEditField.Value = 30;
            app.RadarfrequencyGHz130GHz2308cmLabel = uilabel(app.GridLayout8);
            app.RadarfrequencyGHz130GHz2308cmLabel.Text = 'Radar frequency (GHz) :               1.30 GHz → λ = 23.08 cm';
            app.RadarfrequencyGHz130GHz2308cmEditField = uieditfield(app.GridLayout8, 'numeric');
            app.RadarfrequencyGHz130GHz2308cmEditField.Value = 1.3;
            app.SamplesperwavelengthEditFieldLabel = uilabel(app.GridLayout8);
            app.SamplesperwavelengthEditFieldLabel.Text = 'Samples per wavelength (-):';
            app.SamplesperwavelengthEditField = uieditfield(app.GridLayout8, 'numeric');
            app.NumberofsamplesLabel = uilabel(app.GridLayout8);
            app.NumberofsamplesLabel.Text = 'Number of samples :';
            app.NumberofsamplesEditField_2 = uieditfield(app.GridLayout8, 'numeric');
            app.PrecisionLabel = uilabel(app.GridLayout8);
            app.PrecisionLabel.Text = 'Precision :';
            app.RoundlengthLabel = uilabel(app.GridLayout8);
            app.RoundlengthLabel.Text = 'Round length :';
            app.IsotropicspectrumLabel = uilabel(app.GridLayout8);
            app.IsotropicspectrumLabel.Text = 'Isotropic spectrum :';
            app.IsotropicspectrumDropDown = uidropdown(app.GridLayout8);
            app.IsotropicspectrumDropDown.Items = {'Apel', 'Elfouhaily', 'Donelan'};
            app.IsotropicspectrumDropDown.Value = 'Apel';
            app.AngularspectrumLabel = uilabel(app.GridLayout8);
            app.AngularspectrumLabel.Text = 'Angular spectrum :';
            app.AngularspectrumDropDown = uidropdown(app.GridLayout8);
            app.AngularspectrumDropDown.Items = {'Elfouhaily', 'Du', 'Donelan', 'Banner', 'McDaniel'};
            app.AngularspectrumDropDown.Value = 'Elfouhaily';
            app.UsesingleprecisionCheckBox = uicheckbox(app.GridLayout8);
            app.UsesingleprecisionCheckBox.Text = 'Use single precision';
            app.Roundtothenearestpowerof2CheckBox = uicheckbox(app.GridLayout8);
            app.Roundtothenearestpowerof2CheckBox.Text = 'Round to the nearest power of 2';
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.GenerateSurfaceButton = uibutton(app.GridLayout9, 'push');
            app.GenerateSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSurfaceButtonPushed, true);
            app.GenerateSurfaceButton.Text = 'Generate Surface';
            app.ClearSurfaceButton = uibutton(app.GridLayout9, 'push');
            app.ClearSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSurfaceButtonPushed, true);
            app.ClearSurfaceButton.Text = 'Clear Surface';
            app.ExportSurfaceButton = uibutton(app.GridLayout9, 'push');
            app.ExportSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSurfaceButtonPushed, true);
            app.ExportSurfaceButton.Text = 'Export Surface';
            app.GenerationofSeaProfilesTab = uitab(app.TabGroup);
            app.GenerationofSeaProfilesTab.Title = 'Generation of Sea Profiles';
            app.GridLayout_3 = uigridlayout(app.GenerationofSeaProfilesTab);
            app.GridLayout2_3 = uigridlayout(app.GridLayout_3);
            app.GenerationofSeaProfilesLabel = uilabel(app.GridLayout2_3);
            app.GenerationofSeaProfilesLabel.Text = 'Generation of Sea Profiles';
            app.Image_3 = uiimage(app.GridLayout2_3);
            app.Image_3.ImageSource = 'logo_icam.png';
            app.Image2_3 = uiimage(app.GridLayout2_3);
            app.Image2_3.ImageSource = 'logo_ietr.png';
            app.GridLayout3_3 = uigridlayout(app.GridLayout_3);
            app.GridLayout5_3 = uigridlayout(app.GridLayout3_3);
            app.UIAxes_3 = uiaxes(app.GridLayout5_3);
            xlabel(app.UIAxes_3, 'X(m)')
            ylabel(app.UIAxes_3, 'Y(m)')
            zlabel(app.UIAxes_3, 'Z')
            app.SelectedProfileStatisticsPanel = uipanel(app.GridLayout5_3);
            app.SelectedProfileStatisticsPanel.Title = 'Selected Profile Statistics';
            app.GridLayout10_2 = uigridlayout(app.SelectedProfileStatisticsPanel);
            app.ZminLabel_2 = uilabel(app.GridLayout10_2);
            app.ZminLabel_2.Text = 'Zmin';
            app.ZmeanLabel_2 = uilabel(app.GridLayout10_2);
            app.ZmeanLabel_2.Text = 'Zmean';
            app.ZmaxLabel_2 = uilabel(app.GridLayout10_2);
            app.ZmaxLabel_2.Text = 'Zmax';
            app.ZstdLabel_2 = uilabel(app.GridLayout10_2);
            app.ZstdLabel_2.Text = 'Zstd';
            app.Label_2 = uilabel(app.GridLayout10_2);
            app.Label_2.Text = '';
            app.Label_4 = uilabel(app.GridLayout10_2);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.GridLayout10_2);
            app.Label_3.Text = '';
            app.Label_5 = uilabel(app.GridLayout10_2);
            app.Label_5.Text = '';
            app.GridLayout7_2 = uigridlayout(app.GridLayout3_3);
            app.GridLayout8_2 = uigridlayout(app.GridLayout7_2);
            app.SeaProfilesparametersLabel = uilabel(app.GridLayout8_2);
            app.SeaProfilesparametersLabel.Text = 'Sea Profiles parameters';
            app.Windspeedu10msLabel_2 = uilabel(app.GridLayout8_2);
            app.Windspeedu10msLabel_2.Text = 'Wind speed u10 (m/s) :';
            app.Windspeedu10msEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.Windspeedu10msEditField_2.Value = 10;
            app.FetchmLabel_2 = uilabel(app.GridLayout8_2);
            app.FetchmLabel_2.Text = 'Fetch (m) :';
            app.FetchmEditField_3 = uieditfield(app.GridLayout8_2, 'numeric');
            app.FetchmEditField_3.Value = 500000;
            app.LengthmLabel = uilabel(app.GridLayout8_2);
            app.LengthmLabel.Text = 'Length (m) :';
            app.LengthmEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.LengthmEditField.Value = 500;
            app.NprofilesLabel = uilabel(app.GridLayout8_2);
            app.NprofilesLabel.Text = 'Nprofiles :';
            app.NprofilesEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.NprofilesEditField.Value = 5;
            app.RadarfrequencyGHz130GHz2308cmLabel_2 = uilabel(app.GridLayout8_2);
            app.RadarfrequencyGHz130GHz2308cmLabel_2.Text = 'Radar frequency (GHz) :               1.30 GHz → λ = 23.08 cm';
            app.RadarfrequencyGHz130GHz2308cmEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.RadarfrequencyGHz130GHz2308cmEditField_2.Value = 1.3;
            app.SamplesperwavelengthLabel = uilabel(app.GridLayout8_2);
            app.SamplesperwavelengthLabel.Text = 'Samples per wavelength (-) :';
            app.SamplesperwavelengthEditField_2 = uieditfield(app.GridLayout8_2, 'numeric');
            app.SamplesperwavelengthEditField_2.Value = 8;
            app.NumberofsamplesLabel_2 = uilabel(app.GridLayout8_2);
            app.NumberofsamplesLabel_2.Text = 'Number of samples :';
            app.NumberofsamplesEditField_3 = uieditfield(app.GridLayout8_2, 'numeric');
            app.PrecisionLabel_2 = uilabel(app.GridLayout8_2);
            app.PrecisionLabel_2.Text = 'Precision :';
            app.RoundlengthLabel_2 = uilabel(app.GridLayout8_2);
            app.RoundlengthLabel_2.Text = 'Round length :';
            app.IsotropicspectrumLabel_2 = uilabel(app.GridLayout8_2);
            app.IsotropicspectrumLabel_2.Text = 'Isotropic spectrum :';
            app.IsotropicspectrumDropDown_2 = uidropdown(app.GridLayout8_2);
            app.IsotropicspectrumDropDown_2.Items = {'Apel', 'Elfouhaily', 'Donelan'};
            app.IsotropicspectrumDropDown_2.Value = 'Apel';
            app.UsesingleprecisionCheckBox_2 = uicheckbox(app.GridLayout8_2);
            app.UsesingleprecisionCheckBox_2.Text = 'Use single precision';
            app.Roundtothenearestpowerof2CheckBox_2 = uicheckbox(app.GridLayout8_2);
            app.Roundtothenearestpowerof2CheckBox_2.Text = 'Round to the nearest power of 2';
            app.ParallelisationLabel = uilabel(app.GridLayout8_2);
            app.ParallelisationLabel.Text = 'Parallelisation :';
            app.YesCheckBox = uicheckbox(app.GridLayout8_2);
            app.YesCheckBox.Text = 'Yes';
            app.GridLayout9_2 = uigridlayout(app.GridLayout7_2);
            app.GenerateProfilesButton = uibutton(app.GridLayout9_2, 'push');
            app.GenerateProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateProfilesButtonPushed, true);
            app.GenerateProfilesButton.Text = 'Generate Profiles';
            app.ClearSurfaceButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.ClearSurfaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearSurfaceButton_2Pushed, true);
            app.ClearSurfaceButton_2.Text = 'Clear Surface';
            app.ExportSurfaceButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.ExportSurfaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportSurfaceButton_2Pushed, true);
            app.ExportSurfaceButton_2.Text = 'Export Surface';
            app.BistaticNRCSTab = uitab(app.TabGroup);
            app.BistaticNRCSTab.Title = 'Bistatic NRCS';
            app.GridLayout_4 = uigridlayout(app.BistaticNRCSTab);
            app.GridLayout2_4 = uigridlayout(app.GridLayout_4);
            app.BistaticNRCSofSpecificSeaProfilesMOMLabel = uilabel(app.GridLayout2_4);
            app.BistaticNRCSofSpecificSeaProfilesMOMLabel.Text = 'Bistatic NRCS of  Specific Sea Profiles (MOM)';
            app.Image_4 = uiimage(app.GridLayout2_4);
            app.Image_4.ImageSource = 'logo_icam.png';
            app.Image2_4 = uiimage(app.GridLayout2_4);
            app.Image2_4.ImageSource = 'logo_ietr.png';
            app.GridLayout3_4 = uigridlayout(app.GridLayout_4);
            app.GridLayout5_4 = uigridlayout(app.GridLayout3_4);
            app.UIAxes_4 = uiaxes(app.GridLayout5_4);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.GridLayout7_3 = uigridlayout(app.GridLayout3_4);
            app.GridLayout8_3 = uigridlayout(app.GridLayout7_3);
            app.InputparametersLabel_2 = uilabel(app.GridLayout8_3);
            app.InputparametersLabel_2.Text = 'Input parameters';
            app.Windspeedu10msLabel_3 = uilabel(app.GridLayout8_3);
            app.Windspeedu10msLabel_3.Text = 'Wind speed u10 (m/s) :';
            app.Windspeedu10msEditField_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.FetchmLabel_3 = uilabel(app.GridLayout8_3);
            app.FetchmLabel_3.Text = 'Fetch (m) :';
            app.FetchmEditField_4 = uieditfield(app.GridLayout8_3, 'numeric');
            app.LengthmLabel_2 = uilabel(app.GridLayout8_3);
            app.LengthmLabel_2.Text = 'Length (m) :';
            app.LengthmEditField_2 = uieditfield(app.GridLayout8_3, 'numeric');
            app.IncidenceangledegLabel = uilabel(app.GridLayout8_3);
            app.IncidenceangledegLabel.Text = 'Incidence angle (deg) :';
            app.IncidenceangledegEditField_2 = uieditfield(app.GridLayout8_3, 'numeric');
            app.IncidenceangledegEditField_2.Value = 30;
            app.RadarfrequencyGHz130GHz2308cmLabel_3 = uilabel(app.GridLayout8_3);
            app.RadarfrequencyGHz130GHz2308cmLabel_3.Text = 'Radar frequency (GHz) :               1.30 GHz → λ = 23.08 cm';
            app.RadarfrequencyGHz130GHz2308cmEditField_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.SamplesperwavelengthLabel_2 = uilabel(app.GridLayout8_3);
            app.SamplesperwavelengthLabel_2.Text = 'Samples per wavelength (-) :';
            app.SamplesperwavelengthEditField_3 = uieditfield(app.GridLayout8_3, 'numeric');
            app.NumberofsamplesLabel_3 = uilabel(app.GridLayout8_3);
            app.NumberofsamplesLabel_3.Text = 'Number of samples :';
            app.NumberofsamplesEditField = uieditfield(app.GridLayout8_3, 'numeric');
            app.SelectprofileLabel = uilabel(app.GridLayout8_3);
            app.SelectprofileLabel.Text = 'Select profile :';
            app.SelectprofileDropDown = uidropdown(app.GridLayout8_3);
            app.SelectprofileDropDown.Items = {'__Generate profiles first__'};
            app.SelectprofileDropDown.Value = '__Generate profiles first__';
            app.PolarizationLabel = uilabel(app.GridLayout8_3);
            app.PolarizationLabel.Text = 'Polarization :';
            app.PolarizationDropDown_2 = uidropdown(app.GridLayout8_3);
            app.PolarizationDropDown_2.Items = {'TE', 'TM'};
            app.PolarizationDropDown_2.Value = 'TE';
            app.YesCheckBox_2 = uicheckbox(app.GridLayout8_3);
            app.YesCheckBox_2.Text = 'Yes';
            app.NprofilesLabel_2 = uilabel(app.GridLayout8_3);
            app.NprofilesLabel_2.Text = 'Nprofiles :';
            app.NprofilesEditField_2 = uieditfield(app.GridLayout8_3, 'numeric');
            app.Roundtothenearestpowerof2CheckBox_3 = uicheckbox(app.GridLayout8_3);
            app.Roundtothenearestpowerof2CheckBox_3.Text = 'Round to the nearest power of 2';
            app.UsesingleprecisionCheckBox_3 = uicheckbox(app.GridLayout8_3);
            app.UsesingleprecisionCheckBox_3.Text = 'Use single precision';
            app.IsotropicspectrumLabel_3 = uilabel(app.GridLayout8_3);
            app.IsotropicspectrumLabel_3.Text = 'Isotropic spectrum :';
            app.IsotropicspectrumDropDown_3 = uidropdown(app.GridLayout8_3);
            app.IsotropicspectrumDropDown_3.Items = {'Apel', 'Elfouhaily', 'Donelan'};
            app.IsotropicspectrumDropDown_3.Value = 'Apel';
            app.PrecisionLabel_3 = uilabel(app.GridLayout8_3);
            app.PrecisionLabel_3.Text = 'Precision :';
            app.RoundlengthLabel_3 = uilabel(app.GridLayout8_3);
            app.RoundlengthLabel_3.Text = 'Round length :';
            app.ParallelisationLabel_2 = uilabel(app.GridLayout8_3);
            app.ParallelisationLabel_2.Text = 'Parallelisation :';
            app.GridLayout9_3 = uigridlayout(app.GridLayout7_3);
            app.CalculateButton_2 = uibutton(app.GridLayout9_3, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ClearButton_2 = uibutton(app.GridLayout9_3, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.ExportButton_2 = uibutton(app.GridLayout9_3, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.MonostaticNRCSTab = uitab(app.TabGroup);
            app.MonostaticNRCSTab.Title = 'Monostatic NRCS';
            app.GridLayout_5 = uigridlayout(app.MonostaticNRCSTab);
            app.GridLayout2_5 = uigridlayout(app.GridLayout_5);
            app.MonostaticNRCSofSpecificSeaProfilesMOMLabel = uilabel(app.GridLayout2_5);
            app.MonostaticNRCSofSpecificSeaProfilesMOMLabel.Text = 'Monostatic NRCS of  Specific Sea Profiles (MOM)';
            app.Image_5 = uiimage(app.GridLayout2_5);
            app.Image_5.ImageSource = 'logo_icam.png';
            app.Image2_5 = uiimage(app.GridLayout2_5);
            app.Image2_5.ImageSource = 'logo_ietr.png';
            app.GridLayout3_5 = uigridlayout(app.GridLayout_5);
            app.GridLayout5_5 = uigridlayout(app.GridLayout3_5);
            app.UIAxes_5 = uiaxes(app.GridLayout5_5);
            xlabel(app.UIAxes_5, ' θ_inc (deg)')
            ylabel(app.UIAxes_5, 'NRCS_mono (dB)')
            zlabel(app.UIAxes_5, 'Z')
            app.GridLayout7_4 = uigridlayout(app.GridLayout3_5);
            app.GridLayout8_4 = uigridlayout(app.GridLayout7_4);
            app.InputparametersLabel_3 = uilabel(app.GridLayout8_4);
            app.InputparametersLabel_3.Text = 'Input parameters';
            app.Windspeedu10msLabel_5 = uilabel(app.GridLayout8_4);
            app.Windspeedu10msLabel_5.Text = 'Wind speed u10 (m/s) :';
            app.Windspeedu10msEditField_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.Windspeedu10msEditField_4.Value = 80;
            app.FetchmLabel_5 = uilabel(app.GridLayout8_4);
            app.FetchmLabel_5.Text = 'Fetch (m) :';
            app.FetchmEditField_5 = uieditfield(app.GridLayout8_4, 'numeric');
            app.FetchmEditField_5.Value = 100;
            app.RadarfrequencyGHz130GHz2308cmLabel_4 = uilabel(app.GridLayout8_4);
            app.RadarfrequencyGHz130GHz2308cmLabel_4.Text = 'Radar frequency (GHz) :               1.30 GHz → λ = 23.08 cm';
            app.RadarfrequencyGHz130GHz2308cmEditField_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.LengthmLabel_3 = uilabel(app.GridLayout8_4);
            app.LengthmLabel_3.Text = 'Length (m) :';
            app.LengthmEditField_3 = uieditfield(app.GridLayout8_4, 'numeric');
            app.SamplesperwavelengthLabel_3 = uilabel(app.GridLayout8_4);
            app.SamplesperwavelengthLabel_3.Text = 'Samples per wavelength (-) :';
            app.SamplesperwavelengthEditField_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.MaximumincidenceangledegLabel = uilabel(app.GridLayout8_4);
            app.MaximumincidenceangledegLabel.Text = 'Maximum incidence angle (deg) :';
            app.MaximumincidenceangledegEditField = uieditfield(app.GridLayout8_4, 'numeric');
            app.NumberofcalculationsNLabel = uilabel(app.GridLayout8_4);
            app.NumberofcalculationsNLabel.Text = 'N_calcs (Nθ) :';
            app.N_calcsNEditField = uieditfield(app.GridLayout8_4, 'numeric');
            app.IsotropicspectrumLabel_4 = uilabel(app.GridLayout8_4);
            app.IsotropicspectrumLabel_4.Text = 'Isotropic spectrum :';
            app.IsotropicspectrumDropDown_4 = uidropdown(app.GridLayout8_4);
            app.IsotropicspectrumDropDown_4.Items = {'Apel', 'Elfouhaily', 'Donelan'};
            app.IsotropicspectrumDropDown_4.Value = 'Apel';
            app.NumberofsamplesLabel_4 = uilabel(app.GridLayout8_4);
            app.NumberofsamplesLabel_4.Text = 'Number of samples :';
            app.NumberofsamplesEditField_4 = uieditfield(app.GridLayout8_4, 'numeric');
            app.NprofilesLabel_3 = uilabel(app.GridLayout8_4);
            app.NprofilesLabel_3.Text = 'Nprofiles :';
            app.NprofilesEditField_3 = uieditfield(app.GridLayout8_4, 'numeric');
            app.YesCheckBox_3 = uicheckbox(app.GridLayout8_4);
            app.YesCheckBox_3.Text = 'Yes';
            app.Roundtothenearestpowerof2CheckBox_4 = uicheckbox(app.GridLayout8_4);
            app.Roundtothenearestpowerof2CheckBox_4.Text = 'Round to the nearest power of 2';
            app.UsesingleprecisionCheckBox_4 = uicheckbox(app.GridLayout8_4);
            app.UsesingleprecisionCheckBox_4.Text = 'Use single precision';
            app.ParallelisationLabel_3 = uilabel(app.GridLayout8_4);
            app.ParallelisationLabel_3.Text = 'Parallelisation :';
            app.RoundlengthLabel_4 = uilabel(app.GridLayout8_4);
            app.RoundlengthLabel_4.Text = 'Round length :';
            app.PrecisionLabel_4 = uilabel(app.GridLayout8_4);
            app.PrecisionLabel_4.Text = 'Precision :';
            app.PolarizationDropDown_3Label = uilabel(app.GridLayout8_4);
            app.PolarizationDropDown_3Label.Text = 'Polarization :';
            app.PolarizationDropDown_3 = uidropdown(app.GridLayout8_4);
            app.PolarizationDropDown_3.Items = {'TE', 'TM'};
            app.PolarizationDropDown_3.Value = 'TE';
            app.SelectprofileDropDown_2Label = uilabel(app.GridLayout8_4);
            app.SelectprofileDropDown_2Label.Text = 'Select profile :';
            app.SelectprofileDropDown_2 = uidropdown(app.GridLayout8_4);
            app.SelectprofileDropDown_2.Items = {'__Generate profiles first__'};
            app.SelectprofileDropDown_2.Value = '__Generate profiles first__';
            app.GridLayout9_4 = uigridlayout(app.GridLayout7_4);
            app.CalculateButton_3 = uibutton(app.GridLayout9_4, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.ClearButton_3 = uibutton(app.GridLayout9_4, 'push');
            app.ClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_3Pushed, true);
            app.ClearButton_3.Text = 'Clear';
            app.ExportButton_3 = uibutton(app.GridLayout9_4, 'push');
            app.ExportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_3Pushed, true);
            app.ExportButton_3.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1firstversion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end